classdef App_automatische_Analyse_eines_Regelkreises_Kopie < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Menu                          matlab.ui.container.Menu
        TabGroup_2                    matlab.ui.container.TabGroup
        ZeroPoleTab                   matlab.ui.container.Tab
        HTMLPZ                        matlab.ui.control.HTML
        PZButton                      matlab.ui.container.ButtonGroup
        SsButton                      matlab.ui.control.RadioButton
        TsButton                      matlab.ui.control.RadioButton
        KSsButton                     matlab.ui.control.RadioButton
        GSsButton                     matlab.ui.control.RadioButton
        LsButton                      matlab.ui.control.RadioButton
        KsButton                      matlab.ui.control.RadioButton
        GsButton                      matlab.ui.control.RadioButton
        pzplotUIAxes                  matlab.ui.control.UIAxes
        NyquistTab                    matlab.ui.container.Tab
        MoveslidertovisualizeNyquistdiagramatdifferentfrequenciesLabel  matlab.ui.control.Label
        HTMLNyquistInfo               matlab.ui.control.HTML
        nyquist_UIAxes2               matlab.ui.control.UIAxes
        BodeTab                       matlab.ui.container.Tab
        Bode_UIAxes                   matlab.ui.control.UIAxes
        BandwidthTab                  matlab.ui.container.Tab
        Bandwidth_UIAxes              matlab.ui.control.UIAxes
        RobustnessTab                 matlab.ui.container.Tab
        RobustnessTabGroup            matlab.ui.container.TabGroup
        SensitivityTab                matlab.ui.container.Tab
        SensitivityPointsEdit         matlab.ui.control.NumericEditField
        NumberofPointsLabel           matlab.ui.control.Label
        SensitivityHTML               matlab.ui.control.HTML
        RunSensitivityButton          matlab.ui.control.Button
        SensitivityMetricDropdown     matlab.ui.control.DropDown
        PerformanceMetricLabel        matlab.ui.control.Label
        SensitivityRangeEdit          matlab.ui.control.NumericEditField
        VariationRangeLabel           matlab.ui.control.Label
        SensitivityParamDropdown      matlab.ui.control.DropDown
        SelectParameterLabel          matlab.ui.control.Label
        SensitivityUIAxes             matlab.ui.control.UIAxes
        MonteCarloTab                 matlab.ui.container.Tab
        MonteCarloHTML                matlab.ui.control.HTML
        RunMonteCarloButton           matlab.ui.control.Button
        MonteCarloMetricDropdown      matlab.ui.control.DropDown
        PerformanceMetricLabel_2      matlab.ui.control.Label
        NumSimulationsEdit            matlab.ui.control.NumericEditField
        NumberofSimulationsLabel      matlab.ui.control.Label
        KdParam2Edit                  matlab.ui.control.NumericEditField
        StdMaxLabel_3                 matlab.ui.control.Label
        KdParam1Edit                  matlab.ui.control.NumericEditField
        KdDistDropdown                matlab.ui.control.DropDown
        MeanMinLabel_3                matlab.ui.control.Label
        DistributionLabel_3           matlab.ui.control.Label
        DerivativeGainKdLabel         matlab.ui.control.Label
        KiParam2Edit                  matlab.ui.control.NumericEditField
        StdMaxLabel_2                 matlab.ui.control.Label
        KiParam1Edit                  matlab.ui.control.NumericEditField
        KiDistDropdown                matlab.ui.control.DropDown
        MeanMinLabel_2                matlab.ui.control.Label
        DistributionLabel_2           matlab.ui.control.Label
        IntegralGainKiLabel           matlab.ui.control.Label
        KpParam2Edit                  matlab.ui.control.NumericEditField
        StdMaxLabel                   matlab.ui.control.Label
        KpParam1Edit                  matlab.ui.control.NumericEditField
        MeanMinLabel                  matlab.ui.control.Label
        KpDistDropdown                matlab.ui.control.DropDown
        DistributionLabel             matlab.ui.control.Label
        ControllerGainKpLabel         matlab.ui.control.Label
        MonteCarloResponseUIAxes      matlab.ui.control.UIAxes
        MonteCarloHistUIAxes          matlab.ui.control.UIAxes
        MuAnalysisTab                 matlab.ui.container.Tab
        MuAnalysisHTML                matlab.ui.control.HTML
        RunMuAnalysisButton           matlab.ui.control.Button
        FreqMaxEdit                   matlab.ui.control.NumericEditField
        toLabel                       matlab.ui.control.Label
        FreqMinEdit                   matlab.ui.control.NumericEditField
        FrequencyRangeLabel           matlab.ui.control.Label
        DelayUncertaintyEdit          matlab.ui.control.NumericEditField
        DelayUncertaintyLabel         matlab.ui.control.Label
        TimeConstUncertaintyEdit      matlab.ui.control.NumericEditField
        TimeConstantUncLabel          matlab.ui.control.Label
        GainUncertaintyEdit           matlab.ui.control.NumericEditField
        GainUncertaintyLabel          matlab.ui.control.Label
        MuAnalysisUIAxes              matlab.ui.control.UIAxes
        TabGroup                      matlab.ui.container.TabGroup
        ResponseTab                   matlab.ui.container.Tab
        HTMLkeyparamTable             matlab.ui.control.HTML
        ButtonGroup                   matlab.ui.container.ButtonGroup
        r_d12Button                   matlab.ui.control.RadioButton
        SetpointButton                matlab.ui.control.RadioButton
        StepButton                    matlab.ui.control.RadioButton
        Response_UIAxes               matlab.ui.control.UIAxes
        StationaryValuesTab           matlab.ui.container.Tab
        HTMLkeyparamTable_2           matlab.ui.control.HTML
        JumpLabel                     matlab.ui.control.Label
        ButtonGroup_2                 matlab.ui.container.ButtonGroup
        SetpointandDisturbanceButton  matlab.ui.control.RadioButton
        OutputdisturbanceButton       matlab.ui.control.RadioButton
        SetButton                     matlab.ui.control.RadioButton
        Controllanderrorplo           matlab.ui.control.UIAxes
        StateSpaceTab                 matlab.ui.container.Tab
        StateSpaceVisualizationPanel  matlab.ui.container.Panel
        StateSpaceAxes                matlab.ui.control.UIAxes
        StateSpaceControlPanel        matlab.ui.container.Panel
        ClearStateSpaceButton         matlab.ui.control.Button
        LoadStateSpaceButton          matlab.ui.control.Button
        SaveStateSpaceButton          matlab.ui.control.Button
        ConvertToTransferFunctionButton  matlab.ui.control.Button
        OutputIndexInput              matlab.ui.control.NumericEditField
        OutputIndexLabel              matlab.ui.control.Label
        InputIndexLabel               matlab.ui.control.Label
        InputIndexInput               matlab.ui.control.NumericEditField
        InputOutputLabel              matlab.ui.control.Label
        ConversionHeaderLabel         matlab.ui.control.Label
        RunAnalysisButton             matlab.ui.control.Button
        AnalysisParamInput            matlab.ui.control.EditField
        AnalysisParamLabel            matlab.ui.control.Label
        AnalysisTypeDropdown          matlab.ui.control.DropDown
        AnalysisTypeLabel             matlab.ui.control.Label
        StateSpaceInfoPanel           matlab.ui.container.Panel
        StateSpaceInfoHTML            matlab.ui.control.HTML
        StateSpaceTypeTabGroup        matlab.ui.container.TabGroup
        NonlinearModelTab             matlab.ui.container.Tab
        NonlinearHelpButton           matlab.ui.control.Button
        CreateNonlinearStateSpaceButton  matlab.ui.control.Button
        LinearizationPointInput       matlab.ui.control.EditField
        LinearizationPointLabel       matlab.ui.control.Label
        NonlinearStateEqInput         matlab.ui.control.EditField
        NonlinearOutputEqLabel_2      matlab.ui.control.Label
        NonlinearOutputEqInput        matlab.ui.control.EditField
        NonlinearOutputEqLabel        matlab.ui.control.Label
        NonlinearInputVarsInput       matlab.ui.control.EditField
        NonlinearInputVarsLabel       matlab.ui.control.Label
        NonlinearStateVarsInput       matlab.ui.control.EditField
        NonlinearStateVarsLabel       matlab.ui.control.Label
        LinearModelTab                matlab.ui.container.Tab
        LinearHelpButton              matlab.ui.control.Button
        CreateLinearStateSpaceButton  matlab.ui.control.Button
        LinearDInput                  matlab.ui.control.TextArea
        LinearDLabel                  matlab.ui.control.Label
        LinearCInput                  matlab.ui.control.TextArea
        LinearCLabel                  matlab.ui.control.Label
        LinearBInput                  matlab.ui.control.TextArea
        LinearBLabel                  matlab.ui.control.Label
        LinearAInput                  matlab.ui.control.TextArea
        LinearALabel                  matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        HtmlTable                     matlab.ui.control.HTML
        LoadButton                    matlab.ui.control.Button
        SaveButton                    matlab.ui.control.Button
        controlloopstabilityLabel     matlab.ui.control.Label
        AnalyzeButton                 matlab.ui.control.Button
        Updated2Button                matlab.ui.control.Button
        Updated1Button                matlab.ui.control.Button
        UpdaterButton                 matlab.ui.control.Button
        UpdateKButton                 matlab.ui.control.Button
        UpdateGButton                 matlab.ui.control.Button
        Image                         matlab.ui.control.Image
        r_UIAxes                      matlab.ui.control.UIAxes
        K_UIAxes                      matlab.ui.control.UIAxes
        G_UIAxes                      matlab.ui.control.UIAxes
        d_2_UIAxes                    matlab.ui.control.UIAxes
        d_1_UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        StateSpaceModel % Linear state space model
        LinearizedModel % Linearized model for nonlinear systems
        IsNonlinearModel % Flag indicating if current model is nonlinear
        NonlinearStateVars % State variables for nonlinear model
        NonlinearInputVars % Input variables for nonlinear model
        NonlinearStateEqs % State equations for nonlinear model
        NonlinearOutputEqs % Output equations for nonlinear model
        LinearizationPoint % Linearization point for nonlinear systems
        nyquist_lReal      % Real part of Nyquist curve points
        nyquist_lImag      % Imaginary part of Nyquist curve points
        nyquist_omega      % Frequency values for Nyquist curve
        nyquist_angles     % Calculated angles from critical point
        required_delta_phi % Required delta phi for stability
        nyquistSlider      % Slider UI component
        nyquistSliderPanel % Panel containing slider
        r_latex_string
        d1_latex_string
        d2_latex_string
        G
        K
        r
        r_s
        d_1
        d_1_s
        d_2
        d_2_s
        T
        t
        r_num_sections
        r_start_time
        r_end_time
        d1_num_sections
        d2_num_sections
        d1_start_time
        d1_end_time
        d1_time_steps
        d1_time_vector
        d2_start_time
        d2_end_time
        d2_time_steps
        d2_time_vector
        timeDescription
        cansysjump
        S
        L
        GS
        KS
        char_eq
        N_G
        Z_G
        N_K
        Z_K
        G_poles
        G_zeroes
        K_poles
        K_zeroes
        L_poles
        L_zeroes
        T_poles
        T_zeroes
        S_poles
        S_zeroes
        GS_poles
        GS_zeroes
        KS_poles
        KS_zeroes
    end
    
    methods (Access = private)


        function displayTransferFunctionInApp(app, G, label)
    % DISPLAYTRANSFUNCTIONINAPP
    % ------------------------
    % This enhanced function displays the transfer function G as a LaTeX-formatted expression
    % in the UIAxes component. It uses improved formatting and error handling.
    %
    % Inputs:
    %   app   - The app object containing the UI components
    %   G     - The transfer function
    %   label - A string specifying the label (e.g., 'G', 'K', etc.)
    
    try
        % Extract numerator and denominator coefficients
        [num, den] = tfdata(G, 'v');
        
        % Remove leading zeros to get correct polynomial order
        num = removeLeadingZeros(num);
        den = removeLeadingZeros(den);
        
        % Convert the coefficients to LaTeX string representations
        num_str = poly2str(num, 's');
        den_str = poly2str(den, 's');
        
        % Replace scientific notation for LaTeX
        num_str = regexprep(num_str, 'e([-+]?\d+)', '\\cdot10^{\$1}');
        den_str = regexprep(den_str, 'e([-+]?\d+)', '\\cdot10^{\$1}');
        
        % Create the LaTeX fraction with improved formatting
        formulaLaTeX = ['$$' label '(s) = \frac{' num_str '}{' den_str '}$$'];
        
        % Determine the target UIAxes component (e.g., app.G_UIAxes)
        uiAxesName = [label '_UIAxes'];
        uiAxesHandle = app.(uiAxesName);
        cla(uiAxesHandle);
        
        % Set axis properties for better display
        axis(uiAxesHandle, 'off');
        
        % Display the formula using the LaTeX interpreter with improved font size
        text(uiAxesHandle, 0.5, 0.5, formulaLaTeX, ...
            'Interpreter', 'latex', 'FontSize', 11, ...
            'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
    catch ME
        % Handle errors gracefully
        disp(['Error displaying transfer function: ' ME.message]);
        
        % Determine the target UIAxes component
        uiAxesName = [label '_UIAxes'];
        uiAxesHandle = app.(uiAxesName);
        cla(uiAxesHandle);
        
        % Display error message
        text(uiAxesHandle, 0.5, 0.5, ['Error displaying ' label '(s)'], ...
            'FontSize', 12, 'HorizontalAlignment', 'center', 'Color', 'red');
    end
end

function displayPlaceholder(app, label, variable)
    % DISPLAYPLACEHOLDER
    % ----------------
    % This function displays a placeholder formula when no actual function is defined.
    % The UI is improved with better text styling and centering.
    %
    % Inputs:
    %   app      - The app object containing the UI components
    %   label    - Function label (e.g., 'G', 'K', 'r', etc.)
    %   variable - Variable symbol (e.g., 's' or 't')

    % Create a visually appealing placeholder formula
    placeholderFormula = ['$$' label '(' variable ') = \textrm{not defined}$$'];
    
    % Get the target UIAxes handle
    uiAxesName = [label '_UIAxes'];
    uiAxesHandle = app.(uiAxesName);
    
    % Clear the axis and turn off axis lines
    cla(uiAxesHandle);
    axis(uiAxesHandle, 'off');
    
    % Display the placeholder formula with improved styling
    text(uiAxesHandle, 0.5, 0.5, placeholderFormula, ...
        'Interpreter', 'latex', 'FontSize', 14, ...
        'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle', ...
        'Color', [0.6 0.6 0.6]);  % Gray color to indicate placeholder status
end

function displayTimeFunction(app, target_value, num_sections, label, start_time, end_time)
    % DISPLAYTIMEFUNCTION
    % -----------------
    % Enhanced function to display time-domain functions with better formatting
    % and error handling. Supports continuous and piecewise functions.
    %
    % Inputs:
    %   app          - The app object containing UI components
    %   target_value - Function handle or cell array of function handles
    %   num_sections - Number of sections (1 for continuous)
    %   label        - Function label (e.g., 'r', 'd_1', etc.)
    %   start_time   - Vector of section start times
    %   end_time     - Vector of section end times
    
    try
        % Define symbolic variable t for LaTeX representation
        syms t;
        
        % Create the appropriate LaTeX property name based on the label
        latex_prop_name = '';
        switch label
            case 'r'
                latex_prop_name = 'r_latex_string';
            case 'd_1'
                latex_prop_name = 'd1_latex_string';
            case 'd_2'
                latex_prop_name = 'd2_latex_string';
        end
        
        % Handle continuous vs. piecewise cases
        if num_sections == 1
            % Continuous case
            if isa(target_value, 'function_handle')
                % Try to convert function to symbolic for better display
                try
                    % Try to extract the function definition from string
                    funcStr = func2str(target_value);
                    exprStr = regexp(funcStr, '@\(t\)(.*)', 'tokens');
                    
                    if ~isempty(exprStr) && ~isempty(exprStr{1})
                        % Convert to symbolic expression
                        timeFormula = str2sym(exprStr{1}{1});
                        latexTimeStr = latex(timeFormula);
                    else
                        % Fallback: simple string representation
                        latexTimeStr = 'f(t)';
                    end
                catch
                    % If conversion fails, use a generic representation
                    latexTimeStr = 'f(t)';
                end
            else
                % Constant value case
                latexTimeStr = num2str(target_value);
            end
            
            % Create LaTeX formula
            combinedFormula = ['$$' label '(t) = ' latexTimeStr '$$'];
        else
            % Piecewise case: use \left\{ and array environment
            latexTimeStr = ['\left\{ \begin{array}{ll}'];
            
            % Add each piecewise segment
            for i = 1:num_sections
                if isa(target_value{i}, 'function_handle')
                    % Try to convert function to symbolic for better display
                    try
                        % Try to extract the function definition from string
                        funcStr = func2str(target_value{i});
                        exprStr = regexp(funcStr, '@\(t\)(.*)', 'tokens');
                        
                        if ~isempty(exprStr) && ~isempty(exprStr{1})
                            % Convert to symbolic expression
                            timePart = str2sym(exprStr{1}{1});
                            latexTimePart = latex(timePart);
                        else
                            % Fallback: simple string representation
                            latexTimePart = 'f(t)';
                        end
                    catch
                        % If conversion fails, use a generic representation
                        latexTimePart = 'f(t)';
                    end
                else
                    % Constant value case (though this shouldn't happen in cell mode)
                    latexTimePart = num2str(target_value{i});
                end
                
                % Add time range condition and formatting
                latexTimeStr = [latexTimeStr, ' ', latexTimePart, ' & ', ...
                    num2str(start_time(i)), ' \leq t \leq ', num2str(end_time(i)), ' \\[5pt] '];
            end
            
            % Complete the piecewise function LaTeX
            latexTimeStr = [latexTimeStr, '\end{array}\right.'];
            combinedFormula = ['$$' label '(t) = ' latexTimeStr '$$'];
        end
        
        % Store the LaTeX string representation in the appropriate property
        if ~isempty(latex_prop_name)
            app.(latex_prop_name) = combinedFormula;
        end
        
        % Get the target UIAxes component
        uiAxesName = [label, '_UIAxes'];
        uiAxesHandle = app.(uiAxesName);
        cla(uiAxesHandle);
        axis(uiAxesHandle, 'off');
        
        % Display the formula with improved styling
        text(uiAxesHandle, 0.5, 0.5, combinedFormula, ...
            'Interpreter', 'latex', 'FontSize', 11, ...
            'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
    catch ME
        % Handle errors gracefully
        disp(['Error displaying time function: ' ME.message]);
        
        % Get the target UIAxes
        uiAxesName = [label, '_UIAxes'];
        uiAxesHandle = app.(uiAxesName);
        cla(uiAxesHandle);
        
        % Display error message
        text(uiAxesHandle, 0.5, 0.5, ['Error displaying ' label '(t)'], ...
            'FontSize', 12, 'HorizontalAlignment', 'center', 'Color', 'red');
    end
end


function char_eq = get_characteristic_equation(app, G, K)
    % Extract numerator and denominator polynomials
    [num_G, den_G] = tfdata(G, 'v');
    [num_K, den_K] = tfdata(K, 'v');

    app.N_G = num_G;
    app.Z_G = den_G;
    app.N_K = num_K;
    app.Z_K = den_K;

    % Create symbolic variables
    syms s;
    N_g = poly2sym(den_G, s);
    Z_g = poly2sym(num_G, s);
    N_k = poly2sym(den_K, s);
    Z_k = poly2sym(num_K, s);

    % Calculate the characteristic equation
    char_eq = expand(N_g * N_k + Z_g * Z_k);


    % Convert to LaTeX string
    latex_str = ['$', latex(char_eq), ' = 0$'];


    % Display in the specified axes
    %text(app.CharForm_UIAxes, 0.5, 0.5, latex_str, 'Interpreter', 'latex', 'FontSize', 14, 'HorizontalAlignment','center');
end


function latex_string = generate_latex_string(app, system, system_name)
    % Calculate poles and zeroes
    poles = pole(system);
    zeroes = zero(system);
    
    % Generate LaTeX string
    latex_string = ['$' system_name '(s) \Rightarrow \, '];
    
    % Append zeroes to the string
    for i = 1:length(zeroes)
        latex_string = [latex_string, ' x_{z', num2str(i), '} = ', num2str(zeroes(i)), ', \, \ \ '];
    end
    
    % Append poles to the string
    for i = 1:length(poles)
        latex_string = [latex_string, ' x_{p', num2str(i), '} = ', num2str(poles(i)), ', \, \ \ '];
    end
    
    % Remove last comma and space, and close the LaTeX string
    latex_string = [latex_string(1:end-8), '$'];
    
    % Display LaTeX string in UIAxes
    uiAxesName = ['poles_and_zeroes_' system_name '_UIAxes'];
    uiAxesHandle = app.(uiAxesName); 
    cla(uiAxesHandle); % Clear existing content
    text(uiAxesHandle, 0.5, 0.5, latex_string, 'Interpreter', 'latex', 'FontSize', 14, 'HorizontalAlignment', 'center');
end


function checkAndDisplayUnstableCancellation(app)

    
    % Check for unstable pole-zero cancellation
    isUnstableCancellation1 = checkUnstablePoleZeroCancellation(app.Z_G, app.N_K);
    isUnstableCancellation2 = checkUnstablePoleZeroCancellation(app.Z_K, app.N_G);
    
    % Determine the text message
    if isUnstableCancellation1 && isUnstableCancellation2
        message = 'Both systems have unstable pole-zero cancellations!';
    elseif isUnstableCancellation1 || isUnstableCancellation2
        message = 'One of the systems has an unstable pole-zero cancellation!';
    else
        message = 'No unstable pole-zero cancellations detected.';
    end
    
    % Display the message in the label
    app.pazcancel_label.Text = message;
end


function displayTransferFunctionInfo(app, tf_sys, label)
    % Set up the axis for displaying the results
    uiAxesName = [label '_InfoUIAxes'];
    uiAxesHandle = app.(uiAxesName);
    cla(uiAxesHandle); % Clear existing plots
    
    % Determine stability and type
    stability = routh_hurwitz_tf(tf_sys);
    if sternlyproper_tf(tf_sys)
        type = 'Sternly proper';
    elseif proper_tf(tf_sys)
        type = 'Proper';
    else
        type = 'Improper';
    end
    
    % Create LaTeX string
    latex_str = createLatexString(tf_sys, label, stability, type);
    
    % Display the LaTeX string in the axis
    text(uiAxesHandle, 0.5, 0.5, latex_str, 'Interpreter', 'latex', 'FontSize', 14, 'HorizontalAlignment', 'center');
end
        
function controlloopstable(app)
    % CONTROLLOOPSTABLE
    % ---------------
    % Enhanced stability check with more visual feedback
    
    try
        % Check stability
        if control_loop_stability(app.G, app.K) == true  
            message = 'The control loop is stable';
            color = [0.2 0.7 0.3]; % Green for stable
        else
            message = 'The control loop is unstable';
            color = [0.7 0.3 0.2]; % Red for unstable
        end
        
        % Update the label with improved styling
        app.controlloopstabilityLabel.Text = message;
        app.controlloopstabilityLabel.FontColor = color;
        app.controlloopstabilityLabel.FontWeight = 'bold';
        app.controlloopstabilityLabel.FontSize = 16;
    catch ME
        % Handle errors gracefully
        disp(['Error checking control loop stability: ' ME.message]);
        app.controlloopstabilityLabel.Text = 'Could not determine control loop stability';
        app.controlloopstabilityLabel.FontColor = [0.7 0.7 0.2]; % Yellow for warning
    end
end
        

function plot_TS_bandwidth(app, T, S)
    % Clear existing plots and disable legend
    cla(app.Bandwidth_UIAxes);
    legend(app.Bandwidth_UIAxes, 'off');
    
    % Calculate bandwidth of T
    try
        bandwidth_T = bandwidth(T);
        if isnan(bandwidth_T)
            disp('Warning: Standard bandwidth calculation failed, searching for -3dB bandwidth.');
            [mag, ~, freq] = bode(T);
            mag = squeeze(mag);
            bandwidth_idx = find(20*log10(mag) <= -3, 1, 'first');
            if isempty(bandwidth_idx)
                disp('No frequency crosses -3 dB. Using alternative approach.');
                % Alternative: search frequency range
                [mag, ~, freq] = bode(T, {0.01, 1000});
                mag = squeeze(mag);
                mag_db = 20*log10(mag);
                [peak_mag_db, peak_idx] = max(mag_db);
                cutoff_db = peak_mag_db - 3;
                bandwidth_idx = find(mag_db <= cutoff_db & freq > freq(peak_idx), 1, 'first');
                if ~isempty(bandwidth_idx)
                    bandwidth_T = freq(bandwidth_idx);
                else
                    bandwidth_T = NaN;
                    disp('Bandwidth could not be determined.');
                end
            else
                bandwidth_T = freq(bandwidth_idx);
            end
        end
    catch ME
        disp(['Error calculating bandwidth: ', ME.message]);
        bandwidth_T = NaN;
    end
    
    % Calculate frequency responses with better resolution
    try
        omega = logspace(-2, 2, 1000);
        
        [magT, ~, ~] = bode(T, omega);
        magT = squeeze(magT);
        magT_dB = 20 * log10(magT);
        
        [magS, ~, ~] = bode(S, omega);
        magS = squeeze(magS);
        magS_dB = 20 * log10(magS);
        
        % Plot T(s) (blue, solid)
        h1 = semilogx(app.Bandwidth_UIAxes, omega, magT_dB, 'b-', 'LineWidth', 2);
        hold(app.Bandwidth_UIAxes, 'on');
        
        % Plot S(s) (red, dashed)
        h2 = semilogx(app.Bandwidth_UIAxes, omega, magS_dB, 'r--', 'LineWidth', 1.5);
        
        % Add -3dB line (helpful for bandwidth determination)
        h3 = yline(app.Bandwidth_UIAxes, -3, 'k:', 'LineWidth', 1, 'Alpha', 0.7);
        
        % If bandwidth was calculated, mark it
        if ~isnan(bandwidth_T)
            % Get magnitude at bandwidth frequency
            [magAtBW, ~, ~] = bode(T, bandwidth_T);
            magAtBW_dB = 20 * log10(squeeze(magAtBW));
            
            % Draw vertical line at bandwidth frequency
            h4 = xline(app.Bandwidth_UIAxes, bandwidth_T, 'g-', 'LineWidth', 1.5);
            
            % Mark the bandwidth point on the T(s) curve
            h5 = plot(app.Bandwidth_UIAxes, bandwidth_T, magAtBW_dB, 'go', ...
                'MarkerSize', 10, 'MarkerFaceColor', 'g', 'LineWidth', 1.5);
            
            % Legend labels with bandwidth info
            legendLabels = {'T(s)', 'S(s)', '-3dB Line', ...
                ['Bandwidth: ', num2str(bandwidth_T, '%.3f'), ' rad/s'], ...
                ['Magnitude at BW: ', num2str(magAtBW_dB, '%.2f'), ' dB']};
            
            % Create legend
            legend(app.Bandwidth_UIAxes, [h1, h2, h3, h4, h5], legendLabels, 'Location', 'best');
        else
            % Legend without bandwidth info
            legend(app.Bandwidth_UIAxes, [h1, h2, h3], {'T(s)', 'S(s)', '-3dB Line'}, 'Location', 'best');
        end
        
        % Enhance the chart
        grid(app.Bandwidth_UIAxes, 'on');
        title(app.Bandwidth_UIAxes, 'Bandwidth Analysis');
        xlabel(app.Bandwidth_UIAxes, 'Frequency (rad/s)');
        ylabel(app.Bandwidth_UIAxes, 'Magnitude (dB)');
        
        % Set chart limits for better visibility
        ylim(app.Bandwidth_UIAxes, [-40, 20]);
        
        hold(app.Bandwidth_UIAxes, 'off');
    catch ME
        disp(['Error plotting bandwidth: ', ME.message]);
        % Minimal error display in the chart
        text(app.Bandwidth_UIAxes, 0.5, 0.5, ['Error: ', ME.message], ...
            'HorizontalAlignment', 'center', 'Units', 'normalized');
    end
end

        
function plot_bode_margins(app, L)
    % Check if the input is a valid transfer function
    if ~isa(L, 'tf')
        error('Input must be a transfer function object');
    end
    
    % Calculate margins
    [Gm, Pm, Wcg, Wcp] = margin(L);
    
    % Clear the existing plots
    cla(app.Bode_UIAxes);
    
    % Plot the Bode diagram using bodeplot
    bode(app.Bode_UIAxes, L);
    
    % Add annotations within the axis for margins
    annotationStr = {
        ['Gain Margin: ', num2str(20*log10(Gm), '%.2f'), ' dB'], ...
        ['Phase Margin: ', num2str(Pm, '%.2f'), '°'], ...
        ['Gain Crossover Frequency: ', num2str(Wcg, '%.2f'), ' rad/s'], ...
        ['Phase Crossover Frequency: ', num2str(Wcp, '%.2f'), ' rad/s']
    };
    
    % Display the annotation within the axis
    text(app.Bode_UIAxes, 'Units', 'normalized', 'Position', [0.1, 0.1], ...
        'String', annotationStr, 'FontSize', 12, 'BackgroundColor', 'white', ...
        'EdgeColor', 'black', 'Margin', 5);
end

function render_html_table(app)
    % RENDER_HTML_TABLE
    % ---------------
    % Enhanced HTML table rendering with improved styling and better organization
    
    % Generate string representations of transfer functions
    G_table = createHtmlStringfortransfer(app.G, 'G'); 
    K_table = createHtmlStringfortransfer(app.K, 'K'); 
    L_table = createHtmlStringfortransfer(app.L, 'L'); 
    T_table = createHtmlStringfortransfer(app.T, 'T'); 
    S_table = createHtmlStringfortransfer(app.S, 'S'); 
    GS_table = createHtmlStringfortransfer(app.GS, 'GS'); 
    KS_table = createHtmlStringfortransfer(app.KS, 'KS'); 
    
    % Check conditions for each transfer function
    [G_stable, G_proper, G_sternly] = check_tf_conditions(app.G);
    [K_stable, K_proper, K_sternly] = check_tf_conditions(app.K);
    [L_stable, L_proper, L_sternly] = check_tf_conditions(app.L);
    [T_stable, T_proper, T_sternly] = check_tf_conditions(app.T);
    [S_stable, S_proper, S_sternly] = check_tf_conditions(app.S);
    [GS_stable, GS_proper, GS_sternly] = check_tf_conditions(app.GS);
    [KS_stable, KS_proper, KS_sternly] = check_tf_conditions(app.KS);
    
    % Create an enhanced HTML table with improved styling
    htmlContent = sprintf(['<html>'...
        '<head>'...
        '<style>'...
        'table { border-collapse: collapse; width: 100%%; font-family: Arial, sans-serif; }'...
        'th { background-color: #4472C4; color: white; padding: 8px; text-align: center; font-size: 14px; }'...
        'td { border: 1px solid #DDDDDD; padding: 8px; text-align: center; font-size: 13px; }'...
        'tr:nth-child(odd) { background-color: #F9F9F9; }'...
        'tr:hover { background-color: #E8F4F8; }'...
        '.check { color: #2ECC71; font-size: 18px; }'... % Green checkmark
        '.cross { color: #E74C3C; font-size: 18px; }'... % Red cross
        '.tf-cell { text-align: left; padding-left: 15px; }'...
        '</style>'...
        '</head>'...
        '<body>'...
        '<table>'...
        '<tr><th>Transfer Function</th><th>Stable</th><th>Proper</th><th>Strictly Proper</th></tr>'...
        '<tr><td class="tf-cell">%s</td><td>%s</td><td>%s</td><td>%s</td></tr>'...
        '<tr><td class="tf-cell">%s</td><td>%s</td><td>%s</td><td>%s</td></tr>'...
        '<tr><td class="tf-cell">%s</td><td>%s</td><td>%s</td><td>%s</td></tr>'...
        '<tr><td class="tf-cell">%s</td><td>%s</td><td>%s</td><td>%s</td></tr>'...
        '<tr><td class="tf-cell">%s</td><td>%s</td><td>%s</td><td>%s</td></tr>'...
        '<tr><td class="tf-cell">%s</td><td>%s</td><td>%s</td><td>%s</td></tr>'...
        '<tr><td class="tf-cell">%s</td><td>%s</td><td>%s</td><td>%s</td></tr>'...
        '</table>'...
        '</body>'...
        '</html>'], ...
        G_table,  boolToSymbol(G_stable),  boolToSymbol(G_proper),  boolToSymbol(G_sternly), ...
        K_table,  boolToSymbol(K_stable),  boolToSymbol(K_proper),  boolToSymbol(K_sternly), ...
        L_table,  boolToSymbol(L_stable),  boolToSymbol(L_proper),  boolToSymbol(L_sternly), ...
        T_table,  boolToSymbol(T_stable),  boolToSymbol(T_proper),  boolToSymbol(T_sternly), ...
        S_table,  boolToSymbol(S_stable),  boolToSymbol(S_proper),  boolToSymbol(S_sternly), ...
        GS_table, boolToSymbol(GS_stable), boolToSymbol(GS_proper), boolToSymbol(GS_sternly), ...
        KS_table, boolToSymbol(KS_stable), boolToSymbol(KS_proper), boolToSymbol(KS_sternly));
    
    % Update the HTML component
    try
        app.HtmlTable.HTMLSource = htmlContent;
    catch
        % Create component if it doesn't exist
        app.HtmlTable = uihtml(app.Panel, 'HTMLSource', htmlContent);
        app.HtmlTable.Position = [10 10 app.Panel.Position(3)-20 app.Panel.Position(4)-20];
    end
end

        
function plotnyquist(app)
    % PLOTNYQUIST - Enhanced and optimized Nyquist diagram visualization
    % with improved visual elements, performance optimizations, and proper legend
    
    try
        % Get the Nyquist diagram data
        [lReal, lImag, omega] = nyquistSiso(app.L);
        
        % Store data in App properties
        app.nyquist_lReal = lReal;
        app.nyquist_lImag = lImag;
        app.nyquist_omega = omega;
        
        % Calculate angle changes
        app.nyquist_angles = calculateAngles(lReal, lImag);
        
        % Calculate poles, zeros and DeltaPhi
        [m_0, n_0, deltaphi] = countPolesforNyquist(app.L);
        app.required_delta_phi = deltaphi; % Store required DeltaPhi
        
        % Check pole-zero cancellation
        if checkUnstablePoleZeroCancellation(app.G, app.K)
            pzcancel = 'with';
            pzColor = '#E74C3C'; % Red for warning
        else
            pzcancel = 'without';
            pzColor = '#2ECC71'; % Green for good
        end
        
        % Check if L(s) is proper
        if ~proper_tf(app.L)
            lprop = 'not';
            lpropColor = '#E74C3C'; % Red for warning
        else
            lprop = '';
            lpropColor = '#2ECC71'; % Green for good
        end
        
        % Prepare the axis
        cla(app.nyquist_UIAxes2);
        
        % 1. NEW FEATURE: Background with colored regions for stability analysis
        % Draw a circle around the critical point with radius based on number of RHP poles
        criticalPoint = [-1, 0];
        hold(app.nyquist_UIAxes2, 'on');
        
        % Add unit point line for better orientation
        theta = linspace(0, 2*pi, 100);
        xunit = cos(theta);
        yunit = sin(theta);
        unitCircle = plot(app.nyquist_UIAxes2, xunit-1, yunit, 'k:', 'LineWidth', 1);
        
        % 2. OPTIMIZATION: Plot with custom line properties for better visibility
        mainCurve = plot(app.nyquist_UIAxes2, lReal, lImag, 'LineWidth', 2.5, 'Color', [0, 0.447, 0.741]);
        
        % 3. BETTER MARKERS: Make special points more distinct
        zeroPoint = plot(app.nyquist_UIAxes2, lReal(1), lImag(1), 'o', 'MarkerSize', 10, 'MarkerEdgeColor', [0.85, 0.325, 0.098], ...
            'MarkerFaceColor', [0.929, 0.694, 0.125], 'LineWidth', 2);
        
        infPoint = plot(app.nyquist_UIAxes2, lReal(end), lImag(end), 'p', 'MarkerSize', 10, 'MarkerEdgeColor', [0.494, 0.184, 0.556], ...
            'MarkerFaceColor', [0.494, 0.184, 0.556], 'LineWidth', 2);
        
        critPoint = plot(app.nyquist_UIAxes2, criticalPoint(1), criticalPoint(2), '+', 'MarkerSize', 15, 'MarkerEdgeColor', 'r', ...
            'LineWidth', 3);
        
        % 4. IMPROVED AXIS LABELS
        xlabel(app.nyquist_UIAxes2, 'Real Axis', 'FontSize', 12, 'FontWeight', 'bold');
        ylabel(app.nyquist_UIAxes2, 'Imaginary Axis', 'FontSize', 12, 'FontWeight', 'bold');
        title(app.nyquist_UIAxes2, 'Enhanced Nyquist Diagram', 'FontSize', 14, 'FontWeight', 'bold');
        
        % 5. IMPROVED AXIS PROPERTIES: Finer grid and better visibility
        grid(app.nyquist_UIAxes2, 'on');
        app.nyquist_UIAxes2.GridAlpha = 0.3;
        app.nyquist_UIAxes2.MinorGridAlpha = 0.2;
        app.nyquist_UIAxes2.XMinorGrid = 'on';
        app.nyquist_UIAxes2.YMinorGrid = 'on';
        
        % 6. FEATURE: Highlight the critical point with concentric circles
        radius = 0.1;
        critCircle = plot(app.nyquist_UIAxes2, criticalPoint(1) + radius*cos(theta), criticalPoint(2) + radius*sin(theta), ...
            'r--', 'LineWidth', 1.5);
        
        % KEY FIX: Set the DisplayName property for each plot element to create proper legend
        unitCircle.DisplayName = 'Unit Circle at (-1,0)';
        mainCurve.DisplayName = 'Nyquist Curve L(jω)';
        zeroPoint.DisplayName = 'ω = 0';
        infPoint.DisplayName = 'ω = ∞';
        critPoint.DisplayName = 'Critical Point (-1,0)';
        critCircle.DisplayName = 'Critical Region';
        
        % 7. BETTER LEGEND with proper labels
        legend(app.nyquist_UIAxes2, 'Location', 'northwest', 'FontSize', 10, 'Box', 'on', 'EdgeColor', [0.7 0.7 0.7]);
        
        % Axis limits automatically adjust or fix for better display
        xlim_vals = [min(lReal)-0.5, max(lReal)+0.5];
        ylim_vals = [min(lImag)-0.5, max(lImag)+0.5];
        xlim(app.nyquist_UIAxes2, xlim_vals);
        ylim(app.nyquist_UIAxes2, ylim_vals);
        
        % Clearly mark origin and coordinate axes
        plot(app.nyquist_UIAxes2, [xlim_vals(1), xlim_vals(2)], [0, 0], 'k-', 'LineWidth', 1, 'HandleVisibility', 'off');
        plot(app.nyquist_UIAxes2, [0, 0], [ylim_vals(1), ylim_vals(2)], 'k-', 'LineWidth', 1, 'HandleVisibility', 'off');
        
        % HTML table with improved design
        htmlTableStr = [...
            '<html><head><style>', ...
                'table { border-collapse: collapse; width: 100%; font-family: Arial, sans-serif; margin-bottom: 15px; }', ...
                'th { background-color: #4472C4; color: white; padding: 8px; text-align: center; font-size: 14px; }', ...
                'td { border: 1px solid #DDDDDD; padding: 8px; font-size: 13px; }', ...
                'td:first-child { width: 60%; }', ...
                'tr:nth-child(even) { background-color: #F2F2F2; }', ...
                'tr:hover { background-color: #E8F4F8; }', ...
                '.warning { color: ', pzColor, '; font-weight: bold; }', ...
                '.proper { color: ', lpropColor, '; font-weight: bold; }', ...
                '.stable { color: #2ECC71; font-weight: bold; }', ...
                '.unstable { color: #E74C3C; font-weight: bold; }', ...
                '.angle-value { font-weight: bold; }', ...
                '.info-label { font-style: italic; color: #7F8C8D; font-size: 12px; }', ...
            '</style></head><body>', ...
                '<table>', ...
                    '<tr><th colspan="1">Nyquist Stability Analysis</th></tr>', ...
                    '<tr><td class="warning">G(s) and K(s) ', pzcancel, ' pole/zero cancellation</td></tr>', ...
                    '<tr><td class="proper">L(s) is ', lprop, ' proper</td></tr>', ...
                    '<tr><td>m = 180°; <span class="angle-value">m₀ = ', num2str(m_0), '</span></td></tr>', ...
                    '<tr><td>n = 90°; <span class="angle-value">n₀ = ', num2str(n_0), '</span></td></tr>', ...
                    '<tr><td>Required encirclements: <span class="angle-value">Δφ = ', num2str(deltaphi), '°</span></td></tr>', ...
                    '<tr><td id="currentFreq"><span class="info-label">Move slider to analyze →</span></td></tr>', ...
                    '<tr><td id="currentAngle">Current angle from critical point: <span class="angle-value">0°</span></td></tr>', ...
                    '<tr><td id="deltaPhi" class="unstable">Current Δφ: <span class="angle-value">0°</span> (required: ', num2str(deltaphi), '°)</td></tr>', ...
                    '<tr><td id="stabilityStatus" class="unstable">Control loop stability: <span class="angle-value">Analyzing...</span></td></tr>', ...
                '</table>', ...
            '</body></html>'];
        
        % Update the HTML component
        app.HTMLNyquistInfo.HTMLSource = htmlTableStr;

       % Create slider panel if it doesn't exist
    if ~isfield(app, 'nyquistSlider') || ~isvalid(app.nyquistSlider)
        
         % Improved slider labels
         frequencyLabels = {'ω=0', 'Low ω', 'Mid ω', 'High ω', 'ω=∞'};

        % Add slider
        app.nyquistSlider = uislider(app.NyquistTab, ...
            'Position', [674,60,469,3], ...
            'Limits', [1, length(omega)], ...
            'Value', 1, ...
            'MajorTicks', linspace(1, length(omega), 5), ...
            'MajorTickLabels', frequencyLabels, ...
            'FontSize', 10);
        
        % CRITICAL CHANGE - Add callback for continuous slider movement
        app.nyquistSlider.ValueChangingFcn = @(slider, event) updateNyquistVisualization(app, event.Value);
    end
        
     % Initialize visualization with the first point (ω=0)
        updateNyquistVisualization(app, 1);
        
        hold(app.nyquist_UIAxes2, 'off');
    catch ME
        % Improved error handling with more details
        disp(['Error plotting Nyquist diagram: ' ME.message]);
        disp(getReport(ME, 'extended'));
        
        % Show error message in plot
        cla(app.nyquist_UIAxes2);
        text(app.nyquist_UIAxes2, 0.5, 0.5, {'Error plotting Nyquist diagram', ME.message}, ...
            'HorizontalAlignment', 'center', 'Units', 'normalized', ...
            'FontSize', 12, 'Color', 'red', 'FontWeight', 'bold');
        
        % HTML error output
        app.HTMLNyquistInfo.HTMLSource = ['<html><body><div style="color:red; padding:20px; ', ...
            'text-align:center; font-family:Arial; font-weight:bold; border:1px solid red; ', ...
            'border-radius:5px; margin:10px;">', ...
            'Error generating Nyquist analysis:<br>', ME.message, ...
            '</div></body></html>'];
    end
end


function updateNyquistVisualization(app, sliderValue)
    % UPDATENYQUISTVISUALIZATION - Optimized version with performance improvements
    % and proper legend maintenance
    
    try
        % PERFORMANCE: Store precalculations instead of recalculating each time
        persistent lastPlotObjects;
        
        % Calculate index only once
        currentIdx = max(1, min(round(sliderValue), length(app.nyquist_omega)));
        
        % Critical point
        criticalPoint = [-1, 0];
        
        % Current point
        currentPoint = [app.nyquist_lReal(currentIdx), app.nyquist_lImag(currentIdx)];
        
        % PERFORMANCE OPTIMIZATION: Check if plot already exists
        if isempty(lastPlotObjects) || ~isfield(lastPlotObjects, 'mainCurve') || ~isvalid(lastPlotObjects.mainCurve)
            % Complete redraw if plot doesn't exist yet
            cla(app.nyquist_UIAxes2);
            hold(app.nyquist_UIAxes2, 'on');
            
            % Unit circle around -1
            theta = linspace(0, 2*pi, 100);
            xunit = cos(theta);
            yunit = sin(theta);
            unitCircle = plot(app.nyquist_UIAxes2, xunit-1, yunit, 'k:', 'LineWidth', 1);
            
            % Full curve with proper DisplayName
            mainCurve = plot(app.nyquist_UIAxes2, app.nyquist_lReal, app.nyquist_lImag, ...
                'Color', [0.8, 0.8, 0.8], 'LineWidth', 2);
            
            % Special points with proper DisplayName
            zeroPoint = plot(app.nyquist_UIAxes2, app.nyquist_lReal(1), app.nyquist_lImag(1), 'o', ...
                'MarkerSize', 10, 'MarkerEdgeColor', [0.85, 0.325, 0.098], ...
                'MarkerFaceColor', [0.929, 0.694, 0.125], 'LineWidth', 2);
            
            infPoint = plot(app.nyquist_UIAxes2, app.nyquist_lReal(end), app.nyquist_lImag(end), 'p', ...
                'MarkerSize', 10, 'MarkerEdgeColor', [0.494, 0.184, 0.556], ...
                'MarkerFaceColor', [0.494, 0.184, 0.556], 'LineWidth', 2);
            
            critPoint = plot(app.nyquist_UIAxes2, criticalPoint(1), criticalPoint(2), '+', ...
                'MarkerSize', 15, 'MarkerEdgeColor', 'r', 'LineWidth', 3);
            
            % KEY FIX: Set DisplayName properties for legend 
            unitCircle.DisplayName = 'Unit Circle at (-1,0)';
            mainCurve.DisplayName = 'Full Nyquist Curve';
            zeroPoint.DisplayName = 'ω = 0';
            infPoint.DisplayName = 'ω = ∞';
            critPoint.DisplayName = 'Critical Point (-1,0)';
            
            % Save objects for later
            lastPlotObjects = struct('mainCurve', mainCurve, 'zeroPoint', zeroPoint, ...
                'infPoint', infPoint, 'critPoint', critPoint, 'unitCircle', unitCircle);
            lastPlotObjects.axesConfigured = false;
        end
        
        % OPTIMIZATION: Only update elements that change
        % This significantly improves performance
        
        % 1. Active curve up to current point (with proper DisplayName)
        activePart = plot(app.nyquist_UIAxes2, app.nyquist_lReal(1:currentIdx), app.nyquist_lImag(1:currentIdx), ...
            'Color', [0, 0.447, 0.741], 'LineWidth', 3);
        activePart.DisplayName = 'Active Portion L(jω)';
        
        % 2. Highlight current point (with proper DisplayName)
        currentDot = plot(app.nyquist_UIAxes2, currentPoint(1), currentPoint(2), 'o', ...
            'MarkerSize', 10, 'LineWidth', 2, 'MarkerFaceColor', 'r', 'MarkerEdgeColor', 'k');
        currentDot.DisplayName = sprintf('L(jω) at ω = %.3g rad/s', app.nyquist_omega(currentIdx));
        
        % 3. Arrow from critical point to current point (with proper DisplayName)
        arrow = quiver(app.nyquist_UIAxes2, criticalPoint(1), criticalPoint(2), ...
            currentPoint(1) - criticalPoint(1), currentPoint(2) - criticalPoint(2), ...
            0, 'r', 'LineWidth', 2, 'MaxHeadSize', 0.4);
        arrow.DisplayName = 'Vector from (-1,0)';
        
        % OPTIMIZATION: Don't reconfigure axes on every update
        % Only update axes properties when necessary
        if ~isfield(lastPlotObjects, 'axesConfigured') || ~lastPlotObjects.axesConfigured
            grid(app.nyquist_UIAxes2, 'on');
            app.nyquist_UIAxes2.GridAlpha = 0.3;
            app.nyquist_UIAxes2.MinorGridAlpha = 0.2;
            app.nyquist_UIAxes2.XMinorGrid = 'on';
            app.nyquist_UIAxes2.YMinorGrid = 'on';
            xlabel(app.nyquist_UIAxes2, 'Real Axis', 'FontSize', 12, 'FontWeight', 'bold');
            ylabel(app.nyquist_UIAxes2, 'Imaginary Axis', 'FontSize', 12, 'FontWeight', 'bold');
            title(app.nyquist_UIAxes2, 'Nyquist Diagram Analysis', 'FontSize', 14, 'FontWeight', 'bold');
            
            % Only set axis limits the first time
            xlim_vals = [min(app.nyquist_lReal)-0.5, max(app.nyquist_lReal)+0.5];
            ylim_vals = [min(app.nyquist_lImag)-0.5, max(app.nyquist_lImag)+0.5];
            xlim(app.nyquist_UIAxes2, xlim_vals);
            ylim(app.nyquist_UIAxes2, ylim_vals);
            
            % Draw coordinate axes
            xline(app.nyquist_UIAxes2, 0, 'k-', 'LineWidth', 1, 'Alpha', 0.5, 'HandleVisibility', 'off');
            yline(app.nyquist_UIAxes2, 0, 'k-', 'LineWidth', 1, 'Alpha', 0.5, 'HandleVisibility', 'off');
            
            lastPlotObjects.axesConfigured = true;
        end
        
        % Update legend to show newest elements
        legend(app.nyquist_UIAxes2, 'Location', 'northwest');
        
        % Calculate current angles (in degrees)
        currentAngle = atan2(currentPoint(2) - criticalPoint(2), currentPoint(1) - criticalPoint(1)) * 180/pi;
        
        % Cumulative DeltaPhi based on precalculation
        deltaPhi = app.nyquist_angles(currentIdx) * 180/pi;
        
        % Round to multiples of 90° for stability display
        deltaPhi_display = round(deltaPhi / 90) * 90;
        
        % Determine stability for HTML display
        if abs(deltaPhi_display) >= abs(app.required_delta_phi)
            colorClass = 'stable';
            stabilityText = 'STABLE';
        else
            colorClass = 'unstable';
            stabilityText = 'UNSTABLE';
        end
        
        % Current frequency for display
        currentFreq = app.nyquist_omega(currentIdx);
        
        % Update HTML table with improved formatting
        htmlContent = app.HTMLNyquistInfo.HTMLSource;
        
        % OPTIMIZATION: More efficient replacement of HTML elements
        % Fix the parse error by using proper regex patterns
        
        % 1. Update frequency display
        htmlContent = regexprep(htmlContent, '<td id="currentFreq"[^>]*>.*?</td>', ...
            ['<td id="currentFreq">Current frequency: <span style="font-weight:bold;">\omega = ' num2str(currentFreq, '%.3g') ' rad/s</span></td>']);
        
        % 2. Update angle display
        htmlContent = regexprep(htmlContent, '<td id="currentAngle"[^>]*>.*?</td>', ...
            ['<td id="currentAngle">Angle from critical point: <span style="font-weight:bold;">' num2str(currentAngle, '%.1f') '°</span></td>']);
        
        % 3. Update DeltaPhi with color coding
        htmlContent = regexprep(htmlContent, '<td id="deltaPhi"[^>]*>.*?</td>', ...
            ['<td id="deltaPhi" class="' colorClass '">Encirclement angle: <span style="font-weight:bold;">' num2str(deltaPhi_display) '°</span> (required: ' num2str(app.required_delta_phi) '°)</td>']);
        
        % 4. Add stability status if it doesn't exist yet, otherwise update it
        if ~contains(htmlContent, 'stabilityStatus')
            % Find the table end tag
            tableEndPos = strfind(htmlContent, '</table>');
            if ~isempty(tableEndPos)
                % Insert stability status row before table end
                insertPos = tableEndPos(1);
                beforeInsert = htmlContent(1:insertPos-1);
                afterInsert = htmlContent(insertPos:end);
                
                htmlContent = [beforeInsert, ...
                    '<tr><td id="stabilityStatus" class="' colorClass '">Control loop stability: <span style="font-weight:bold;">' stabilityText '</span></td></tr>', ...
                    afterInsert];
            end
        else
            % Update existing stability status
            htmlContent = regexprep(htmlContent, '<td id="stabilityStatus"[^>]*>.*?</td>', ...
                ['<td id="stabilityStatus" class="' colorClass '">Control loop stability: <span style="font-weight:bold;">' stabilityText '</span></td>']);
        end
        
        % Update HTML content
        app.HTMLNyquistInfo.HTMLSource = htmlContent;
        
        % Cleanup for next iteration - Performance optimization
        if isfield(lastPlotObjects, 'activePart') && isvalid(lastPlotObjects.activePart)
            delete(lastPlotObjects.activePart);
        end
        if isfield(lastPlotObjects, 'currentDot') && isvalid(lastPlotObjects.currentDot)
            delete(lastPlotObjects.currentDot);
        end
        if isfield(lastPlotObjects, 'arrow') && isvalid(lastPlotObjects.arrow)
            delete(lastPlotObjects.arrow);
        end
        
        % Save current temporary objects for next iterations
        lastPlotObjects.activePart = activePart;
        lastPlotObjects.currentDot = currentDot;
        lastPlotObjects.arrow = arrow;
        
        drawnow limitrate; % Smooth animation with optimized performance
    catch ME
        % Improved error handling
        disp(['Error updating Nyquist visualization: ' ME.message]);
        disp(getReport(ME, 'extended'));
    end
end

        
function calculateAndDisplayResponse(app, selectedButton)
    app.Response_UIAxes.Visible = 'on';
    
    % Set standard title
    responseTitle = 'System Response';
    
    % Check if time vector for setpoint is set
    if isempty(app.t)
        app.t = create_linear_time_vector(app.r_num_sections);
        if isempty(app.t)
            disp('Failed to create time vector');
            return;
        end
    end
    
    try
        % Prepare input values for simulation
        [input_r, input_d1, input_d2] = prepareInputValues(app);
        
        % IMPORTANT: FIRST clear all existing plots
        cla(app.Response_UIAxes);
        
        % Different cases based on the selected button
        if selectedButton == app.StepButton
            % Step response
            try
                % Create unit step for the time vector
                u = ones(size(app.t));
                
                % Calculate system response
                [y, t_out] = lsim(app.T, u, app.t);
                
                % Plot system response
                plot(app.Response_UIAxes, t_out, y, 'DisplayName', 'System Response');
                
                % Set title
                responseTitle = 'System Step Response';
                
                % Always show legend
                legend(app.Response_UIAxes, 'show', 'Location', 'best');
                
                % Display key parameters
                displayKeyParameters(app, y, t_out);
            catch ME
                disp(['Simulation error: ', ME.message]);
                return;
            end
        elseif selectedButton == app.SetpointButton
            % Response to the user-defined setpoint
            try
                % Calculate system response
                [y, t_out] = lsim(app.T, input_r, app.t);
                
                % Plot system response and reference input
                hold(app.Response_UIAxes, 'on');
                sysRes = plot(app.Response_UIAxes, t_out, y, 'DisplayName', 'System Response');
                refInp = plot(app.Response_UIAxes, app.t, input_r, '--', 'DisplayName', 'Reference Input');
                hold(app.Response_UIAxes, 'off');
                
                % Always show legend
                legend([sysRes refInp] , app.Response_UIAxes, 'show', 'Location', 'best');
                
                % Set title
                responseTitle = 'System Response to Setpoint r';
                
                % Display key parameters
                displayKeyParameters(app, y, t_out);
            catch ME
                disp(['Simulation error: ', ME.message]);
                return;
            end
        elseif selectedButton == app.r_d12Button
            % Response to setpoint and disturbances
            try
                % Define closed-loop system
                closed_loop_system = feedback(app.L, 1);
                
                % Calculate response to setpoint
                [y_r, t_out] = lsim(closed_loop_system, input_r, app.t);
                
                % Transfer functions for disturbances
                H_d1 = feedback(1, app.L); % Transfer function for disturbance D1
                H_d2 = feedback(app.G, app.K * app.G); % Transfer function for disturbance D2
                
                % Calculate responses to disturbances
                [y_d1, ~] = lsim(H_d1, input_d1, app.t);
                [y_d2, ~] = lsim(H_d2, input_d2, app.t);
                
                % Total response
                y_total = y_r + y_d1 + y_d2;
                
                % Calculate control error
                error = input_r - y_total;
                
                % Plot with full legend visibility
                hold(app.Response_UIAxes, 'on');
                sysRep = plot(app.Response_UIAxes, t_out, y_total, 'DisplayName', 'System Response');
                refInp = plot(app.Response_UIAxes, app.t, input_r, '--', 'DisplayName', 'Reference Input');
                DisOne = plot(app.Response_UIAxes, app.t, input_d1, ':', 'DisplayName', 'Disturbance 1');
                DisTwo = plot(app.Response_UIAxes, app.t, input_d2, ':', 'DisplayName', 'Disturbance 2');
                ContErr = plot(app.Response_UIAxes, t_out, error, '-.', 'DisplayName', 'Control Error');
                hold(app.Response_UIAxes, 'off');
               
                
                % Always show legend
                legend([sysRep refInp DisOne DisTwo ContErr], 'Location', 'best');
                
                % Set title
                responseTitle = 'System Response to Setpoint r and Disturbances';
                
                % Display key parameters
                displayKeyParameters(app, y_total, t_out);
            catch ME
                disp(['Simulation error: ', ME.message]);
                return;
            end
        end
    catch ME
        disp(['Error generating input values: ', ME.message]);
        return;
    end
    
    % Set title and labels
    title(app.Response_UIAxes, responseTitle);
    xlabel(app.Response_UIAxes, 'Time (s)');
    ylabel(app.Response_UIAxes, 'Amplitude');
    
    % Enable grid
    grid(app.Response_UIAxes, 'on');
end


function displayKeyParameters(app, y, t_vector)
    % DISPLAYKEYPARAMETERS
    % ------------------
    % Enhanced function to display key response parameters with improved
    % formatting and error handling.
    
    try
        % Ensure y and t_vector have matching lengths
        if length(y) ~= length(t_vector)
            disp('Warning: Sizes of y and t_vector mismatch in displayKeyParameters');
            if length(y) > length(t_vector)
                y = y(1:length(t_vector));
            elseif length(t_vector) > length(y)
                t_vector = t_vector(1:length(y));
            end
        end
        
        % Generate system response information using stepinfo
        info = stepinfo(y, t_vector);
        
        % Extract data from the stepinfo structure
        RiseTime = info.RiseTime;
        SettlingTime = info.SettlingTime;
        SettlingMin = info.SettlingMin;
        SettlingMax = info.SettlingMax;
        Overshoot = info.Overshoot;
        Undershoot = info.Undershoot;
        Peak = info.Peak;
        PeakTime = info.PeakTime;
        
        % Check for TransientTime field
        if isfield(info, 'TransientTime')
            TransientTime = info.TransientTime;
        else
            TransientTime = NaN;
        end
        
        % Format values with proper precision
        RiseTime_str = formatNumber(RiseTime);
        SettlingTime_str = formatNumber(SettlingTime);
        SettlingMin_str = formatNumber(SettlingMin);
        SettlingMax_str = formatNumber(SettlingMax);
        Overshoot_str = [formatNumber(Overshoot), ' %']; % Add percentage symbol
        Undershoot_str = formatNumber(Undershoot);
        Peak_str = formatNumber(Peak);
        PeakTime_str = formatNumber(PeakTime);
        TransientTime_str = formatNumber(TransientTime);
        
        % Create enhanced HTML table with better styling
        htmlTableStr = [...
            '<html><head><style>', ...
                'table { border-collapse: collapse; width: 100%; font-family: Arial, sans-serif; }', ...
                'th { background-color: #4472C4; color: white; padding: 10px; text-align: center; font-size: 14px; }', ...
                'td { border: 1px solid #DDDDDD; padding: 8px; font-size: 13px; }', ...
                'td:first-child { text-align: left; font-weight: bold; width: 60%; }', ...
                'td:last-child { text-align: right; width: 40%; }', ...
                'tr:nth-child(even) { background-color: #F2F2F2; }', ...
                'tr:hover { background-color: #E8F4F8; }', ...
            '</style></head><body>', ...
                '<table>', ...
                    '<tr><th colspan="2">Key Parameters of a Response</th></tr>', ...
                    '<tr><td>Rise time t<sub>r</sub></td><td>', RiseTime_str, '</td></tr>', ...
                    '<tr><td>Settling time (2% band) t<sub>s</sub></td><td>', SettlingTime_str, '</td></tr>', ...
                    '<tr><td>Settling Minimum</td><td>', SettlingMin_str, '</td></tr>', ...
                    '<tr><td>Settling Maximum</td><td>', SettlingMax_str, '</td></tr>', ...
                    '<tr><td>Overshoot</td><td>', Overshoot_str, '</td></tr>', ...
                    '<tr><td>Undershoot</td><td>', Undershoot_str, '</td></tr>', ...
                    '<tr><td>Peak</td><td>', Peak_str, '</td></tr>', ...
                    '<tr><td>Peak Time</td><td>', PeakTime_str, '</td></tr>', ...
                    '<tr><td>Transient Time</td><td>', TransientTime_str, '</td></tr>', ...
                '</table>', ...
            '</body></html>'];
        
        % Update the HTML component
        app.HTMLkeyparamTable.HTMLSource = htmlTableStr;
    catch ME
        % Handle errors in a more user-friendly way
        disp(['Error in displayKeyParameters: ', ME.message]);
        
        % Create error table
        errorTableStr = [...
            '<html><head><style>', ...
                'table { border-collapse: collapse; width: 100%; font-family: Arial, sans-serif; }', ...
                'th { background-color: #E74C3C; color: white; padding: 10px; text-align: center; font-size: 14px; }', ...
                'td { border: 1px solid #DDDDDD; padding: 12px; text-align: center; font-size: 14px; }', ...
                '.error { color: #E74C3C; font-weight: bold; }', ...
            '</style></head><body>', ...
                '<table>', ...
                    '<tr><th>Response Analysis Error</th></tr>', ...
                    '<tr><td class="error">Could not calculate key parameters</td></tr>', ...
                    '<tr><td>Possible issues: non-standard response shape, insufficient data points, or negative time values</td></tr>', ...
                '</table>', ...
            '</body></html>'];
        
        app.HTMLkeyparamTable.HTMLSource = errorTableStr;
    end
    
    % Helper function to format numbers with appropriate precision
    function str = formatNumber(num)
        if isnan(num)
            str = 'N/A';
        elseif abs(num) > 1000 || abs(num) < 0.01
            % Use scientific notation for very large/small numbers
            str = sprintf('%.4e', num);
        else
            % Use fixed-point notation with appropriate precision
            str = sprintf('%.4f', num);
        end
    end
end

        
function calculateAndDisplayErrorandOutput(app, selectedButton)
    % CALCULATEANDDISPLAYERRORANDOUTPUT - Berechnet und zeigt stationäre Werte an
    % Diese Funktion wurde überarbeitet, um die gleiche Eingabevorbereitung wie
    % calculateAndDisplayResponse zu verwenden und somit Dimensionsprobleme zu vermeiden.
    
    % Sichtbarkeit der Antwort-UIAxes aktivieren
    app.Controllanderrorplo.Visible = 'on';
    
    % Prüfen, ob gültige Übertragungsfunktionen vorhanden sind
    if ~isa(app.T, 'tf') || ~isa(app.S, 'tf') || ~isa(app.G, 'tf')
        uialert(app.UIFigure, 'Please define valid transfer functions G(s) and K(s) first.', 'Invalid System');
        return;
    end
    
    % Standardwerte für Sollwert und Störgrößen
    if isempty(app.r_s)
        app.r_s = 1;
    end
    
    if isempty(app.d_1_s)
        app.d_1_s = 0;
    end
    
    if isempty(app.d_2_s)
        app.d_2_s = 0;
    end
    
    disp(['Reference Input (r_s): ', num2str(app.r_s)]);
    disp(['Output Disturbance (d_1_s): ', num2str(app.d_1_s)]);
    disp(['Input Disturbance (d_2_s): ', num2str(app.d_2_s)]);
    
    % Zeitvektor prüfen
    if isempty(app.t)
        app.t = create_linear_time_vector(app.r_num_sections);
        if isempty(app.t)
            uialert(app.UIFigure, 'Unable to create time vector. Please set time parameters.', 'Error');
            return;
        end
    end
    
    % Eingabewerte vorbereiten mit der gleichen Funktion wie in calculateAndDisplayResponse
    try
        [input_r, input_d1, input_d2] = prepareInputValues(app);
    catch ME
        uialert(app.UIFigure, ['Error preparing inputs: ', ME.message], 'Error');
        disp(['Error details: ', getReport(ME)]);
        return;
    end
    
    % Struktur für die Simulation vorbereiten
    inputs_constant = struct('reference', true, 'disturbance1', true, 'disturbance2', true);
    
    % Prüfen, ob Eingaben konstant sind
    if iscell(app.r)
        inputs_constant.reference = false;
    end
    
    if iscell(app.d_1)
        inputs_constant.disturbance1 = false;
    end
    
    if iscell(app.d_2)
        inputs_constant.disturbance2 = false;
    end
    
    % Basierend auf dem ausgewählten Button unterschiedliche Simulationen durchführen
    if selectedButton == app.SetButton
        % Nur Sollwert: r konstant und Störgrößen = 0
        try
            % Simulation ausführen
            [y_TR, t_out] = lsim(app.T, input_r, app.t);
            [e_SR, ~] = lsim(app.S, input_r, app.t);
            
            % Ergebnisse anzeigen
            responseTitle = 'Function if Setpoint = const and disturbances = 0';
            cla(app.Controllanderrorplo);
            plot(app.Controllanderrorplo, t_out, y_TR, 'DisplayName', 'Output');
            hold(app.Controllanderrorplo, 'on');
            plot(app.Controllanderrorplo, t_out, e_SR, '--', 'DisplayName', 'Error');
            plot(app.Controllanderrorplo, app.t, input_r, ':', 'DisplayName', 'Reference');
            hold(app.Controllanderrorplo, 'off');
            
            % Letzte 10% der Simulationsdaten für eine zuverlässigere Schätzung verwenden
            if ~isempty(y_TR) && length(y_TR) > 10
                endpart = max(1, round(0.9*length(y_TR)));
                y_stat_TR = mean(y_TR(endpart:end));
                e_stat_SR = mean(e_SR(endpart:end));
            else
                y_stat_TR = y_TR(end);
                e_stat_SR = e_SR(end);
            end
            
            % Berechnung, ob das System springen kann
            app.cansysjump = (abs(e_stat_SR) < 1e-3);
            
            % Stationäre Werte anzeigen
            displayStationaryValues(app, y_stat_TR, e_stat_SR, inputs_constant);
        catch ME
            uialert(app.UIFigure, ['Simulation error: ', ME.message], 'Error');
            disp(['Error during simulation: ', ME.message]);
            disp(['Error details: ', getReport(ME)]);
            return;
        end
    elseif selectedButton == app.OutputdisturbanceButton
        % Nur Ausgangsstörung: r = 0, d_1 konstant
        try
            % Simulation ausführen
            [y_SD1, t_out] = lsim(-app.S, input_d1, app.t);
            [e_SD1, ~] = lsim(app.S, input_d1, app.t);
            
            % Ergebnisse anzeigen
            responseTitle = 'Function if Output disturbance = const and r = 0';
            cla(app.Controllanderrorplo);
            plot(app.Controllanderrorplo, t_out, y_SD1, 'DisplayName', 'Output');
            hold(app.Controllanderrorplo, 'on');
            plot(app.Controllanderrorplo, t_out, e_SD1, '--', 'DisplayName', 'Error');
            plot(app.Controllanderrorplo, app.t, input_d1, ':', 'DisplayName', 'Disturbance');
            hold(app.Controllanderrorplo, 'off');
            
            % Letzte 10% der Simulationsdaten für eine zuverlässigere Schätzung verwenden
            if ~isempty(y_SD1) && length(y_SD1) > 10
                endpart = max(1, round(0.9*length(y_SD1)));
                y_stat_SD1 = mean(y_SD1(endpart:end));
                e_stat_SD1 = mean(e_SD1(endpart:end));
            else
                y_stat_SD1 = y_SD1(end);
                e_stat_SD1 = e_SD1(end);
            end
            
            % Berechnung, ob das System springen kann
            app.cansysjump = (abs(e_stat_SD1) < 1e-3);
            
            % Stationäre Werte anzeigen
            displayStationaryValues(app, y_stat_SD1, e_stat_SD1, inputs_constant);
        catch ME
            uialert(app.UIFigure, ['Simulation error: ', ME.message], 'Error');
            disp(['Error during simulation: ', ME.message]);
            disp(['Error details: ', getReport(ME)]);
            return;
        end
    elseif selectedButton == app.SetpointandDisturbanceButton
        % Kombinierter Fall: r und Ausgangsstörung
        try
            % Simulation für die kombinierte Antwort
            [y_r, t_out] = lsim(app.T, input_r, app.t);
            [y_d, ~] = lsim(-app.S, input_d1, app.t);
            y_total = y_r + y_d;  % Kombinierte Antwort
            
            [e_r, ~] = lsim(app.S, input_r, app.t);
            [e_d, ~] = lsim(app.S, input_d1, app.t);
            e_total = e_r + e_d;  % Kombinierter Fehler
            
            % Ergebnisse anzeigen
            responseTitle = 'Function if Setpoint and Output disturbance = const';
            cla(app.Controllanderrorplo);
            plot(app.Controllanderrorplo, t_out, y_total, 'DisplayName', 'Output');
            hold(app.Controllanderrorplo, 'on');
            plot(app.Controllanderrorplo, t_out, e_total, '--', 'DisplayName', 'Error');
            plot(app.Controllanderrorplo, app.t, input_r, ':', 'DisplayName', 'Reference');
            plot(app.Controllanderrorplo, app.t, input_d1, '-.', 'DisplayName', 'Disturbance');
            hold(app.Controllanderrorplo, 'off');
            
            % Letzte 10% der Simulationsdaten für eine zuverlässigere Schätzung verwenden
            if ~isempty(y_total) && length(y_total) > 10
                endpart = max(1, round(0.9*length(y_total)));
                y_stat_combined = mean(y_total(endpart:end));
                e_stat_combined = mean(e_total(endpart:end));
            else
                y_stat_combined = y_total(end);
                e_stat_combined = e_total(end);
            end
            
            % Berechnung, ob das System springen kann
            app.cansysjump = (abs(e_stat_combined) < 1e-3);
            
            % Stationäre Werte anzeigen
            displayStationaryValues(app, y_stat_combined, e_stat_combined, inputs_constant);
        catch ME
            uialert(app.UIFigure, ['Simulation error: ', ME.message], 'Error');
            disp(['Error during simulation: ', ME.message]);
            disp(['Error details: ', getReport(ME)]);
            return;
        end
    end
    
    % Titel und Labels setzen
    title(app.Controllanderrorplo, responseTitle);
    xlabel(app.Controllanderrorplo, 'Time (s)');
    ylabel(app.Controllanderrorplo, 'Amplitude');
    grid(app.Controllanderrorplo, 'on');
    legend(app.Controllanderrorplo, 'Location', 'best');
end


function displayStationaryValues(app, y, e, inputs_constant)
    % DISPLAYSTATIONARYVALUES
    % ---------------------
    % Enhanced display of steady-state values with better formatting and more information
    % Now includes jumpability information in the HTML table
    
    % Define threshold for considering values as effectively zero
    threshold = 1e-6;
    
    % Add warnings for non-constant inputs
    warning_msg = '';
    if nargin >= 4 && isstruct(inputs_constant)
        if ~inputs_constant.reference
            warning_msg = [warning_msg, '<tr><td class="warning">Warning: Reference input is not constant.</td></tr>'];
        end
        if ~inputs_constant.disturbance1
            warning_msg = [warning_msg, '<tr><td class="warning">Warning: Output disturbance is not constant.</td></tr>'];
        end
        if ~inputs_constant.disturbance2
            warning_msg = [warning_msg, '<tr><td class="warning">Warning: Input disturbance is not constant.</td></tr>'];
        end
        
        if ~isempty(warning_msg)
            warning_msg = [warning_msg, '<tr><td class="warning">Steady-state values are based on simulation end points.</td></tr>'];
        end
    end
    
    % Format output value string with improved handling of special cases
    if isnan(y)
        disp('Warning: Output value is NaN, using 0 instead for display');
        y = 0;
        y_str = '<span class="estimate">y<sub>∞</sub> = 0 (estimate)</span>';
    elseif abs(y) < threshold
        y_str = 'y<sub>∞</sub> = 0';
    else
        % Format y with 4 significant digits and scientific notation for very large/small values
        if abs(y) > 10000 || abs(y) < 0.01
            y_str = ['y<sub>∞</sub> = ', sprintf('%.4e', y)];
        else
            y_str = ['y<sub>∞</sub> = ', sprintf('%.4g', y)];
        end
    end
    
    % Format error value string
    if isnan(e)
        disp('Warning: Error value is NaN, using 0 instead for display');
        e = 0;
        e_str = '<span class="estimate">e<sub>∞</sub> = 0 (estimate)</span>';
    elseif abs(e) < threshold
        e_str = 'e<sub>∞</sub> = 0';
    else
        % Format e with scientific notation if needed
        if abs(e) > 10000 || abs(e) < 0.01
            e_str = ['e<sub>∞</sub> = ', sprintf('%.4e', e)];
        else
            e_str = ['e<sub>∞</sub> = ', sprintf('%.4g', e)];
        end
    end
    
    % Set jumpability message with improved styling
    if app.cansysjump == 1
        jumpMessage = 'The control loop is jumpable (Zero steady-state error for step input)';
        jumpColor = '#2ECC71'; % Green color for positive result
    else
        jumpMessage = 'The control loop is not jumpable (Has steady-state error for step input)';
        jumpColor = '#E74C3C'; % Red color for negative result
    end
    
    % Still update the JumpLabel for backward compatibility
    app.JumpLabel.Text = jumpMessage;
    app.JumpLabel.FontColor = jumpColor;
    
    % Create enhanced HTML table - now including jumpability information
    htmlTableStr = [...
        '<html><head><style>', ...
            'table { border-collapse: collapse; width: 100%; font-family: Arial, sans-serif; }', ...
            'th { background-color: #4472C4; color: white; padding: 10px; text-align: center; font-size: 14px; }', ...
            'td { border: 1px solid #DDDDDD; padding: 8px; text-align: center; font-size: 13px; }', ...
            'tr:nth-child(even) { background-color: #F2F2F2; }', ...
            'tr:hover { background-color: #E8F4F8; }', ...
            '.warning { color: #E74C3C; font-weight: bold; }', ...
            '.info { color: #3498DB; font-style: italic; }', ...
            '.estimate { color: #E67E22; font-style: italic; }', ...
            '.jumpable { color: ', jumpColor, '; font-weight: bold; }', ...
        '</style></head><body>', ...
            '<table>', ...
                '<tr><th>Stationary Values</th></tr>', ...
                '<tr><td>', y_str, '</td></tr>', ...
                '<tr><td>', e_str, '</td></tr>', ...
                '<tr><td class="jumpable">', jumpMessage, '</td></tr>', ...
                '<tr><td class="info">Based on simulation results</td></tr>', ...
                warning_msg, ...
            '</table>', ...
        '</body></html>'];
    
    % Update the HTML component
    app.HTMLkeyparamTable_2.HTMLSource = htmlTableStr;
    
    % Display additional diagnostic info in console
    disp(['Steady-state output: ', strrep(y_str, '<sub>∞</sub>', '(∞)')]);
    disp(['Steady-state error: ', strrep(e_str, '<sub>∞</sub>', '(∞)')]);
    disp(['System jumpability: ', jumpMessage]);
end


function PZchange(app, selectedButton)
    % PZCHANGE
    % -------
    % Creates an enhanced pole-zero plot with colored stable/unstable regions
    % and displays pole/zero information in HTML format
    
    try
        % Select the appropriate transfer function based on button selection
        if selectedButton == app.GsButton
            sys = app.G;
            varName = 'G';
        elseif selectedButton == app.TsButton
            sys = app.T;
            varName = 'T';
        elseif selectedButton == app.KsButton
            sys = app.K;
            varName = 'K';
        elseif selectedButton == app.KSsButton
            sys = app.KS;
            varName = 'KS';
        elseif selectedButton == app.GSsButton
            sys = app.GS;
            varName = 'GS';
        elseif selectedButton == app.LsButton
            sys = app.L;
            varName = 'L';
        elseif selectedButton == app.SsButton
            sys = app.S;
            varName = 'S';
        else
            error('Unknown transfer function button selected.');
        end
        
        % Debug output
        disp(['Selected Button: ', char(selectedButton.Tag)]);
        disp(['VarName: ', varName]);
        
        % Calculate poles and zeros
        poles = pole(sys);
        zeroes = zero(sys);
        
        % Create HTML strings for poles and zeros with improved formatting
        polesStr = formatPZListInline(poles, 'p');
        zeroesStr = formatPZListInline(zeroes, 'z');
        
        % Create enhanced HTML table
        htmlTableStr = [...
            '<html><head><style>', ...
                'table { border-collapse: collapse; width: 100%; font-family: Arial, sans-serif; }', ...
                'th { background-color: #4472C4; color: white; padding: 10px; text-align: center; font-size: 14px; }', ...
                'td { border: 1px solid #DDDDDD; padding: 8px; font-size: 13px; }', ...
                'td.pz-list { padding: 12px; text-align: left; }', ...
                'tr:hover { background-color: #E8F4F8; }', ...
            '</style></head><body>', ...
                '<table>', ...
                    '<tr><th>Poles and Zeros of ', varName, '(s)</th></tr>', ...
                    '<tr><td class="pz-list">', polesStr, '</td></tr>', ...
                    '<tr><td class="pz-list">', zeroesStr, '</td></tr>', ...
                '</table>', ...
            '</body></html>'];
        
        % Update the HTML component
        app.HTMLPZ.HTMLSource = htmlTableStr;
        
        % Store poles and zeros in app
        app.([varName, '_poles']) = poles;
        app.([varName, '_zeroes']) = zeroes;
        
        % Make the UIAxes visible if it wasn't already
        app.pzplotUIAxes.Visible = 'on';
        
        % Clear the plot area
        cla(app.pzplotUIAxes);
        
        % Check if the system is discrete or continuous
        isDiscrete = false;
        if isa(sys, 'tf') || isa(sys, 'ss')
            try
                isDiscrete = sys.Ts > 0;
            catch
                % If Ts property doesn't exist or can't be accessed
                isDiscrete = false;
            end
        end
        
        % Create enhanced pole-zero plot with colored regions
        createEnhancedPZMapInline(app.pzplotUIAxes, poles, zeroes, isDiscrete, varName);
        
    catch ME
        % Handle errors gracefully
        disp(['Error in PZchange: ' ME.message]);
        
        % Display error message in HTML
        app.HTMLPZ.HTMLSource = [...
            '<html><head><style>', ...
                'div.error { color: red; font-weight: bold; text-align: center; padding: 20px; }', ...
            '</style></head><body>', ...
                '<div class="error">Error displaying poles and zeros:<br>', ME.message, '</div>', ...
            '</body></html>'];
        
        % Display error in plot
        cla(app.pzplotUIAxes);
        text(app.pzplotUIAxes, 0.5, 0.5, 'Error displaying pole-zero plot', ...
            'HorizontalAlignment', 'center', 'Units', 'normalized', ...
            'FontSize', 12, 'Color', 'red');
    end
    
    % ====================================================================
    % INLINE HELPER FUNCTIONS
    % ====================================================================
    
    % Helper function to format poles/zeros list - defined inline to avoid scope issues
    function str = formatPZListInline(values, type)
        if isempty(values)
            if strcmp(type, 'p')
                str = 'No poles';
            else
                str = 'No zeros';
            end
            return;
        end
        
        str = '';
        for i = 1:length(values)
            % Format complex numbers nicely
            if imag(values(i)) == 0
                % Real value
                val_str = sprintf('%.4f', real(values(i)));
            elseif real(values(i)) == 0
                % Pure imaginary
                val_str = sprintf('%.4fi', imag(values(i)));
            elseif imag(values(i)) > 0
                % Complex with positive imaginary
                val_str = sprintf('%.4f + %.4fi', real(values(i)), imag(values(i)));
            else
                % Complex with negative imaginary
                val_str = sprintf('%.4f - %.4fi', real(values(i)), abs(imag(values(i))));
            end
            
            % Add to list with proper formatting
            if strcmp(type, 'p')
                str = [str, 'x<sub>p', num2str(i), '</sub> = ', val_str, '<br>'];
            else
                str = [str, 'x<sub>z', num2str(i), '</sub> = ', val_str, '<br>'];
            end
        end
    end
    
    % Enhanced pole-zero map creation function - defined inline to avoid scope issues
    function createEnhancedPZMapInline(axesHandle, poles, zeroes, isDiscrete, sysName)
        % Clear any previous content
        cla(axesHandle);
        
        % Enable hold for multiple elements
        hold(axesHandle, 'on');
        
        % Determine axis limits based on poles and zeros
        allPoints = [poles; zeroes];
        if ~isempty(allPoints)
            maxMag = max(abs(allPoints)) * 1.2;  % Add 20% margin
            maxMag = max(maxMag, 1.5);  % Ensure minimum view range
        else
            maxMag = 1.5;  % Default view range
        end
        
        % Define colors
        stableColor = [0.3, 0.6, 0.9];  % Blue
        unstableColor = [0.9, 0.6, 0.3]; % Orange
        
        % Create colored regions based on system type
        if isDiscrete
            % For discrete-time systems, stable region is inside the unit circle
            
            % Create a circle with many points for the stable region
            theta = linspace(0, 2*pi, 100);
            x_circle = cos(theta);
            y_circle = sin(theta);
            
            % Draw the unstable region (outside the unit circle)
            % Create a rectangular boundary for the outer region
            x_rect = [-maxMag, maxMag, maxMag, -maxMag];
            y_rect = [-maxMag, -maxMag, maxMag, maxMag];
            
            % Create patch objects for regions
            patch(axesHandle, x_rect, y_rect, unstableColor, 'EdgeColor', 'none');
            patch(axesHandle, x_circle, y_circle, stableColor, 'EdgeColor', 'k');
            
            % Draw the unit circle boundary
            plot(axesHandle, x_circle, y_circle, 'k-', 'LineWidth', 2);
            
            % Removed region labels as requested
        else
            % For continuous-time systems, stable region is the left half-plane
            
            % Draw the stable region (left half-plane)
            x_stable = [-maxMag, 0, 0, -maxMag];
            y_stable = [-maxMag, -maxMag, maxMag, maxMag];
            patch(axesHandle, x_stable, y_stable, stableColor, 'EdgeColor', 'none');
            
            % Draw the unstable region (right half-plane)
            x_unstable = [0, maxMag, maxMag, 0];
            y_unstable = [-maxMag, -maxMag, maxMag, maxMag];
            patch(axesHandle, x_unstable, y_unstable, unstableColor, 'EdgeColor', 'none');
            
            % Draw the imaginary axis boundary
            plot(axesHandle, [0, 0], [-maxMag, maxMag], 'k-', 'LineWidth', 2);
            
            % Removed region labels as requested
        end
        
        % Plot poles (x) and zeros (o)
        if ~isempty(poles)
            h_poles = plot(axesHandle, real(poles), imag(poles), 'kx', 'MarkerSize', 10, 'LineWidth', 2);
        else
            % Create dummy poles for legend
            h_poles = plot(axesHandle, NaN, NaN, 'kx', 'MarkerSize', 10, 'LineWidth', 2);
        end
        
        if ~isempty(zeroes)
            h_zeros = plot(axesHandle, real(zeroes), imag(zeroes), 'ko', 'MarkerSize', 10, 'LineWidth', 2);
        else
            % Create dummy zeros for legend
            h_zeros = plot(axesHandle, NaN, NaN, 'ko', 'MarkerSize', 10, 'LineWidth', 2);
        end
        
        % Set axis properties
        axis(axesHandle, 'equal');
        grid(axesHandle, 'on');
        axesHandle.XGrid = 'on';
        axesHandle.YGrid = 'on';
        axesHandle.Box = 'on';
        xlim(axesHandle, [-maxMag, maxMag]);
        ylim(axesHandle, [-maxMag, maxMag]);
        
        % Add title and axis labels
        if isDiscrete
            title(axesHandle, ['Pole-Zero Map of Discrete-Time System ', sysName, '(z)']);
            xlabel(axesHandle, 'Real Axis');
            ylabel(axesHandle, 'Imaginary Axis');
        else
            title(axesHandle, ['Pole-Zero Map of Continuous-Time System ', sysName, '(s)']);
            xlabel(axesHandle, 'Real Axis (seconds^{-1})');
            ylabel(axesHandle, 'Imaginary Axis (seconds^{-1})');
        end
        
        % Create dummy patches for legend
        h_stable = patch(axesHandle, 'XData', NaN, 'YData', NaN, 'FaceColor', stableColor, 'EdgeColor', 'none');
        h_unstable = patch(axesHandle, 'XData', NaN, 'YData', NaN, 'FaceColor', unstableColor, 'EdgeColor', 'none');
        
        % Add legend
        legend(axesHandle, [h_stable, h_unstable, h_poles, h_zeros], ...
               {'Stable Region', 'Unstable Region', 'Poles', 'Zeros'}, ...
               'Location', 'best');
        
        % Turn off hold
        hold(axesHandle, 'off');
    end
end


    function build_G(app)
  % Get the button text as the function label
    label = 'G';

    % Store a temporary copy of the current G
    tempG = app.G;
    
    % Get the transfer function from the user
    newG = get_user_transfer_function();
    
    % If user didn't cancel, update app.G and display it
    if ~isempty(newG)
        app.G = newG;
        displayTransferFunctionInApp(app, app.G, label);
    else
        % User canceled, ensure app.G retains its original value
        app.G = tempG;
        % No need to update display as nothing changed
    end
    end

  function build_K(app)
    % Get the button text as the function label
    label = 'K';

    % Store the existing controller before calling get_user_controller
    existing_K = app.K;

    % Call User-Controller function and pass G directly
    if ~isempty(app.G)
        app.K = get_user_controller(app.G);
    else
        % If no plant defined, show warning
        app.K = get_user_controller(); % Call without G (Auto-Tuning will be deactivated)
    end
    
    % If user canceled (returned empty controller), keep the existing one
    if isempty(app.K) && ~isempty(existing_K)
        disp('Controller selection cancelled - keeping existing controller.');
        app.K = existing_K;
    end
    
    % If user didn't cancel, display the transfer function
    if ~isempty(app.K)
        displayTransferFunctionInApp(app, app.K, label);
    end
end


  function build_r(app)
    % Get the button text as the function label
    label = 'r';

    % Call the function without nested functions
    [app.r, app.r_num_sections, app.r_start_time, app.r_end_time] = get_target_value_version2();
    
    % In case r was defined already and is changed
    % the time vector changes as well
    % hence t needs to be defined new:
    app.t = create_linear_time_vector(app.r_num_sections);
    
    if ~isempty(app.r)
        displayTimeFunction(app, app.r, app.r_num_sections, label, app.r_start_time, app.r_end_time);
    end
  end


function saveAppVariables(app)
    % SAVEAPPVARIABLES - Save app variables to a file
    %
    % This improved function ensures that all necessary variables are saved,
    % including LaTeX string representations of time functions and their
    % time parameters.
    
    % List of variables to save, grouped by category
    % Basic transfer function variables
    basicVars = {'G', 'K', 'T', 'S', 'L', 'GS', 'KS'};
    
    % Reference signal variables
    refVars = {'r', 'r_num_sections', 'r_start_time', 'r_end_time', 'r_s', 'r_latex_string'};
    
    % Disturbance 1 variables
    d1Vars = {'d_1', 'd1_num_sections', 'd1_start_time', 'd1_end_time', 'd1_time_steps', 'd1_time_vector', 'd_1_s', 'd1_latex_string'};
    
    % Disturbance 2 variables
    d2Vars = {'d_2', 'd2_num_sections', 'd2_start_time', 'd2_end_time', 'd2_time_steps', 'd2_time_vector', 'd_2_s', 'd2_latex_string'};
    
    % Time and system state variables
    stateVars = {'t', 'timeDescription', 'cansysjump', 'char_eq'};
    
    % Combine all variable groups
    allVars = [basicVars, refVars, d1Vars, d2Vars, stateVars];
    
    % Initialize structure to save variables
    varsStruct = struct();

    % Assign values from app to the structure, with error handling for each variable
    for i = 1:length(allVars)
        varName = allVars{i};
        try
            if isprop(app, varName) && ~isempty(app.(varName))
                varsStruct.(varName) = app.(varName);
            end
        catch ME
            warning(['Error saving variable ' varName ': ' ME.message]);
        end
    end
    
    % Check if LaTeX string representations exist, and if not, try to generate them
    if ~isfield(varsStruct, 'r_latex_string') && isfield(varsStruct, 'r') && ~isempty(varsStruct.r)
        % Generate LaTeX string for r
        try
            syms t;
            if ~iscell(varsStruct.r)
                if isa(varsStruct.r, 'function_handle')
                    funcStr = func2str(varsStruct.r);
                    exprStr = regexp(funcStr, '@\(t\)(.*)', 'tokens');
                    if ~isempty(exprStr) && ~isempty(exprStr{1})
                        timeFormula = str2sym(exprStr{1}{1});
                        latexTimeStr = latex(timeFormula);
                        varsStruct.r_latex_string = ['$$r(t) = ' latexTimeStr '$$'];
                    end
                end
            else
                % For piecewise function, construct a basic representation
                latexTimeStr = ['\left\{ \begin{array}{ll}'];
                for j = 1:varsStruct.r_num_sections
                    latexTimeStr = [latexTimeStr, ' f_', num2str(j), '(t) & ', ...
                        num2str(varsStruct.r_start_time(j)), ' \leq t \leq ', num2str(varsStruct.r_end_time(j)), ' \\[5pt] '];
                end
                latexTimeStr = [latexTimeStr, '\end{array}\right.'];
                varsStruct.r_latex_string = ['$$r(t) = ' latexTimeStr '$$'];
            end
        catch
            warning('Could not generate LaTeX string for r(t)');
        end
    end
    
    % Similar checks for d1 and d2 LaTeX strings
    % [Similar code for d1_latex_string and d2_latex_string would go here]
    
    % Prompt user for filename
    filename = inputdlg('Enter filename (without extension):', 'Save Variables');
    if isempty(filename)
        msgbox('No filename entered. Operation cancelled.', 'Error', 'error');
        return;
    end
    filename = filename{1};
    
    % Remove invalid characters from filename
    invalidChars = {'<', '>', ':', '"', '/', '\', '|', '?', '*'};
    for i = 1:length(invalidChars)
        filename = strrep(filename, invalidChars{i}, '');
    end
    
    % Save the structure to a .mat file
    try
        save([filename, '.mat'], '-struct', 'varsStruct');
        
        % Display confirmation message 
        msgbox('All done! Your variables are now saved!', 'Save Successful', 'help');
    catch ME
        msgbox(['Error saving file: ' ME.message], 'Save Error', 'error');
    end
end
        
function success = loadAppVariables(app)
    % LOADAPPVARIABLES loads the required variables from a .mat file.
    % Returns false if no file is selected.
    
    [file, path] = uigetfile('*.mat', 'Select a file');
    if isequal(file, 0)
        % No file selected - abort function
        success = false;
        return;
    end
    
    % Create the full file path and load the data
    fullFileName = fullfile(path, file);
    data = load(fullFileName);
    
    % Keep track of variables loaded successfully for debugging
    loadedVariables = {};
    
    % Transfer function variables
    if isfield(data, 'G')
        app.G = data.G;
        loadedVariables{end+1} = 'G';
    end
    
    if isfield(data, 'K')
        app.K = data.K;
        loadedVariables{end+1} = 'K';
    end
    
    % Reference signal and time parameters
    if isfield(data, 'r')
        app.r = data.r;
        loadedVariables{end+1} = 'r';
    end
    
    if isfield(data, 'r_num_sections')
        app.r_num_sections = data.r_num_sections;
        loadedVariables{end+1} = 'r_num_sections';
    end
    
    if isfield(data, 'r_start_time')
        app.r_start_time = data.r_start_time;
        loadedVariables{end+1} = 'r_start_time';
    end
    
    if isfield(data, 'r_end_time')
        app.r_end_time = data.r_end_time;
        loadedVariables{end+1} = 'r_end_time';
    end
    
    % Time vector
    if isfield(data, 't')
        app.t = data.t;
        loadedVariables{end+1} = 't';
    end
    
    % Disturbance 1 variables
    if isfield(data, 'd_1')
        app.d_1 = data.d_1;
        loadedVariables{end+1} = 'd_1';
    end
    
    if isfield(data, 'd1_num_sections')
        app.d1_num_sections = data.d1_num_sections;
        loadedVariables{end+1} = 'd1_num_sections';
    end
    
    if isfield(data, 'd1_start_time')
        app.d1_start_time = data.d1_start_time;
        loadedVariables{end+1} = 'd1_start_time';
    end
    
    if isfield(data, 'd1_end_time')
        app.d1_end_time = data.d1_end_time;
        loadedVariables{end+1} = 'd1_end_time';
    end
    
    if isfield(data, 'd1_time_steps')
        app.d1_time_steps = data.d1_time_steps;
        loadedVariables{end+1} = 'd1_time_steps';
    end
    
    if isfield(data, 'd1_time_vector')
        app.d1_time_vector = data.d1_time_vector;
        loadedVariables{end+1} = 'd1_time_vector';
    end
    
    % Disturbance 2 variables
    if isfield(data, 'd_2')
        app.d_2 = data.d_2;
        loadedVariables{end+1} = 'd_2';
    end
    
    if isfield(data, 'd2_num_sections')
        app.d2_num_sections = data.d2_num_sections;
        loadedVariables{end+1} = 'd2_num_sections';
    end
    
    if isfield(data, 'd2_start_time')
        app.d2_start_time = data.d2_start_time;
        loadedVariables{end+1} = 'd2_start_time';
    end
    
    if isfield(data, 'd2_end_time')
        app.d2_end_time = data.d2_end_time;
        loadedVariables{end+1} = 'd2_end_time';
    end
    
    if isfield(data, 'd2_time_steps')
        app.d2_time_steps = data.d2_time_steps;
        loadedVariables{end+1} = 'd2_time_steps';
    end
    
    if isfield(data, 'd2_time_vector')
        app.d2_time_vector = data.d2_time_vector;
        loadedVariables{end+1} = 'd2_time_vector';
    end
    
    % Load LaTeX string representations if available
    if isfield(data, 'r_latex_string')
        app.r_latex_string = data.r_latex_string;
        loadedVariables{end+1} = 'r_latex_string';
    end
    
    if isfield(data, 'd1_latex_string')
        app.d1_latex_string = data.d1_latex_string;
        loadedVariables{end+1} = 'd1_latex_string';
    end
    
    if isfield(data, 'd2_latex_string')
        app.d2_latex_string = data.d2_latex_string;
        loadedVariables{end+1} = 'd2_latex_string';
    end
    
    % Additional parameters that might be saved
    if isfield(data, 'r_s')
        app.r_s = data.r_s;
        loadedVariables{end+1} = 'r_s';
    end
    
    if isfield(data, 'd_1_s')
        app.d_1_s = data.d_1_s;
        loadedVariables{end+1} = 'd_1_s';
    end
    
    if isfield(data, 'd_2_s')
        app.d_2_s = data.d_2_s;
        loadedVariables{end+1} = 'd_2_s';
    end
    
    % Display debug info
    disp('Successfully loaded variables:');
    disp(loadedVariables);
    
    % Verify time vector is properly loaded or create it if necessary
    % (this is the key part for fixing the issue)
    if isempty(app.t) && ~isempty(app.r) && ~isempty(app.r_num_sections) && ...
       ~isempty(app.r_start_time) && ~isempty(app.r_end_time)
        disp('Time vector not found in saved data. Creating from parameters...');
        % Store current time parameters for get_time_vector
        [~, ~, ~] = get_time_vector(app.r_num_sections, app.r_start_time, app.r_end_time, ones(size(app.r_start_time))*0.1);
        % Create time vector using stored parameters
        app.t = create_linear_time_vector(app.r_num_sections);
    end
    
    % Do the same for disturbance time vectors if needed
    if isempty(app.d1_time_vector) && ~isempty(app.d_1) && ~isempty(app.d1_num_sections) && ...
       ~isempty(app.d1_start_time) && ~isempty(app.d1_end_time) && ~isempty(app.d1_time_steps)
        disp('d1_time_vector not found. Creating from parameters...');
        [~, ~, ~] = get_time_vector_disturbance(app.d1_num_sections, app.d1_start_time, app.d1_end_time, app.d1_time_steps, 'd_1');
        app.d1_time_vector = create_linear_time_vector_disturbance(app.d1_num_sections, 'd_1');
    end
    
    if isempty(app.d2_time_vector) && ~isempty(app.d_2) && ~isempty(app.d2_num_sections) && ...
       ~isempty(app.d2_start_time) && ~isempty(app.d2_end_time) && ~isempty(app.d2_time_steps)
        disp('d2_time_vector not found. Creating from parameters...');
        [~, ~, ~] = get_time_vector_disturbance(app.d2_num_sections, app.d2_start_time, app.d2_end_time, app.d2_time_steps, 'd_2');
        app.d2_time_vector = create_linear_time_vector_disturbance(app.d2_num_sections, 'd_2');
    end
    
    % Successfully loaded variables
    success = true;
end

function [input_r, input_d1, input_d2] = prepareInputValues(app)
    % PREPAREINPUTVALUES - Bereitet die Eingabewerte für die Simulation vor
    % Diese Funktion wertet die Funktionen r, d_1 und d_2 für den gegebenen Zeitvektor aus,
    % unter Berücksichtigung, ob sie kontinuierlich oder stückweise definiert sind.
    
    % Initialisieren der Ausgabevektoren
    input_r = zeros(size(app.t));
    input_d1 = zeros(size(app.t));
    input_d2 = zeros(size(app.t));
    
    % Auswerten des Sollwerts
    if ~isempty(app.r)
        % Prüfen, ob es sich um eine kontinuierliche oder stückweise Funktion handelt
        if ~iscell(app.r)
            % Kontinuierlicher Fall
            if isa(app.r, 'function_handle')
                input_r = arrayfun(app.r, app.t);
            else
                % Konstanter Wert
                input_r = app.r * ones(size(app.t));
            end
        else
            % Stückweise Funktion
            for i = 1:length(app.t)
                current_time = app.t(i);
                
                % Den aktiven Abschnitt für diesen Zeitpunkt finden
                active_section = 0;
                
                % Überprüfen, ob alle notwendigen Daten vorhanden sind
                if ~isempty(app.r_start_time) && ~isempty(app.r_end_time) && length(app.r) <= length(app.r_start_time)
                    for j = 1:length(app.r)
                        if current_time >= app.r_start_time(j) && current_time <= app.r_end_time(j)
                            active_section = j;
                            break;
                        end
                    end
                end
                
                % Wenn kein aktiver Abschnitt gefunden wurde, den nächstgelegenen verwenden
                if active_section == 0 && ~isempty(app.r_start_time) && ~isempty(app.r_end_time)
                    if current_time < app.r_start_time(1)
                        active_section = 1; % Zeit liegt vor dem ersten Abschnitt
                    elseif current_time > app.r_end_time(end)
                        active_section = length(app.r); % Zeit liegt nach dem letzten Abschnitt
                    end
                elseif active_section == 0 && length(app.r) > 0
                    active_section = 1; % Fallback: ersten Abschnitt verwenden
                end
                
                % Funktion auswerten, wenn ein aktiver Abschnitt gefunden wurde
                if active_section > 0 && active_section <= length(app.r)
                    if isa(app.r{active_section}, 'function_handle')
                        input_r(i) = app.r{active_section}(current_time);
                    else
                        input_r(i) = app.r{active_section};
                    end
                end
            end
        end
    end
    
    % Auswerten der ersten Störgröße
    if ~isempty(app.d_1)
        % Prüfen, ob es sich um eine kontinuierliche oder stückweise Funktion handelt
        if ~iscell(app.d_1)
            % Kontinuierlicher Fall
            if isa(app.d_1, 'function_handle')
                input_d1 = arrayfun(app.d_1, app.t);
            else
                % Konstanter Wert
                input_d1 = app.d_1 * ones(size(app.t));
            end
        else
            % Stückweise Funktion - gleicher Ansatz wie bei r
            % Da die Zeitbereiche für d_1 nicht explizit gespeichert werden, verwenden wir
            % die Zeitbereiche von r oder machen eine konservative Schätzung
            for i = 1:length(app.t)
                current_time = app.t(i);
                
                % Standardmäßig den ersten Abschnitt verwenden
                active_section = 1;
                
                % Wenn wir genügend Informationen haben, den richtigen Abschnitt finden
                if app.d1_num_sections > 0 && app.d1_num_sections <= length(app.d_1)
                    % Zeitbereiche aus r übernehmen, sofern vorhanden und passend
                    if ~isempty(app.r_start_time) && ~isempty(app.r_end_time) && app.d1_num_sections <= length(app.r_start_time)
                        for j = 1:app.d1_num_sections
                            if current_time >= app.r_start_time(j) && current_time <= app.r_end_time(j)
                                active_section = j;
                                break;
                            end
                        end
                    else
                        % Einfache Schätzung: Gesamtzeitbereich gleichmäßig aufteilen
                        if ~isempty(app.t)
                            t_min = app.t(1);
                            t_max = app.t(end);
                            section_width = (t_max - t_min) / app.d1_num_sections;
                            for j = 1:app.d1_num_sections
                                section_start = t_min + (j-1) * section_width;
                                section_end = t_min + j * section_width;
                                if current_time >= section_start && current_time <= section_end
                                    active_section = j;
                                    break;
                                end
                            end
                        end
                    end
                end
                
                % Sicherstellen, dass active_section im gültigen Bereich liegt
                active_section = max(1, min(active_section, length(app.d_1)));
                
                % Funktion auswerten
                if isa(app.d_1{active_section}, 'function_handle')
                    input_d1(i) = app.d_1{active_section}(current_time);
                else
                    input_d1(i) = app.d_1{active_section};
                end
            end
        end
    end
    
    % Auswerten der zweiten Störgröße (gleicher Ansatz wie bei d_1)
    if ~isempty(app.d_2)
        % Prüfen, ob es sich um eine kontinuierliche oder stückweise Funktion handelt
        if ~iscell(app.d_2)
            % Kontinuierlicher Fall
            if isa(app.d_2, 'function_handle')
                input_d2 = arrayfun(app.d_2, app.t);
            else
                % Konstanter Wert
                input_d2 = app.d_2 * ones(size(app.t));
            end
        else
            % Stückweise Funktion - gleicher Ansatz wie bei d_1
            for i = 1:length(app.t)
                current_time = app.t(i);
                
                % Standardmäßig den ersten Abschnitt verwenden
                active_section = 1;
                
                % Wenn wir genügend Informationen haben, den richtigen Abschnitt finden
                if app.d2_num_sections > 0 && app.d2_num_sections <= length(app.d_2)
                    % Zeitbereiche aus r übernehmen, sofern vorhanden und passend
                    if ~isempty(app.r_start_time) && ~isempty(app.r_end_time) && app.d2_num_sections <= length(app.r_start_time)
                        for j = 1:app.d2_num_sections
                            if current_time >= app.r_start_time(j) && current_time <= app.r_end_time(j)
                                active_section = j;
                                break;
                            end
                        end
                    else
                        % Einfache Schätzung: Gesamtzeitbereich gleichmäßig aufteilen
                        if ~isempty(app.t)
                            t_min = app.t(1);
                            t_max = app.t(end);
                            section_width = (t_max - t_min) / app.d2_num_sections;
                            for j = 1:app.d2_num_sections
                                section_start = t_min + (j-1) * section_width;
                                section_end = t_min + j * section_width;
                                if current_time >= section_start && current_time <= section_end
                                    active_section = j;
                                    break;
                                end
                            end
                        end
                    end
                end
                
                % Sicherstellen, dass active_section im gültigen Bereich liegt
                active_section = max(1, min(active_section, length(app.d_2)));
                
                % Funktion auswerten
                if isa(app.d_2{active_section}, 'function_handle')
                    input_d2(i) = app.d_2{active_section}(current_time);
                else
                    input_d2(i) = app.d_2{active_section};
                end
            end
        end
    end
end

        
function updateMonteCarloFields(app, paramName)
    % Get references to the relevant dropdown and edit fields
    if strcmp(paramName, 'Kp')
        distDropdown = app.KpDistDropdown;
        param1Edit = app.KpParam1Edit;
        param2Edit = app.KpParam2Edit;
        meanMinLabel = app.MeanMinLabel;
        stdMaxLabel = app.StdMaxLabel;
    elseif strcmp(paramName, 'Ki')
        distDropdown = app.KiDistDropdown;
        param1Edit = app.KiParam1Edit;
        param2Edit = app.KiParam2Edit;
        meanMinLabel = app.MeanMinLabel_2;
        stdMaxLabel = app.StdMaxLabel_2;
    elseif strcmp(paramName, 'Kd')
        distDropdown = app.KdDistDropdown;
        param1Edit = app.KdParam1Edit;
        param2Edit = app.KdParam2Edit;
        meanMinLabel = app.MeanMinLabel_3;
        stdMaxLabel = app.StdMaxLabel_3;
    else
        return;
    end
    
    % Update field availability based on distribution
    switch distDropdown.Value
        case 'Normal'
            param1Edit.Enable = 'on';
            param2Edit.Enable = 'on';
            
            % Update labels to reflect normal distribution parameters
            meanMinLabel.Text = 'Mean:';
            stdMaxLabel.Text = 'Std:';
            
        case 'Uniform'
            param1Edit.Enable = 'on';
            param2Edit.Enable = 'on';
            
            % Update labels to reflect uniform distribution parameters
            meanMinLabel.Text = 'Min:';
            stdMaxLabel.Text = 'Max:';
            
        case 'Fixed'
            param1Edit.Enable = 'on';
            param2Edit.Enable = 'off';
            
            % Update labels to reflect fixed value
            meanMinLabel.Text = 'Value:';
            stdMaxLabel.Text = 'N/A:';
    end
end
        
function runSensitivityAnalysis(app)
    % RUNSENSITIVITYANALYSIS - Performs parameter sensitivity analysis
    % This function analyzes how changes in a selected parameter affect system performance
    
    % Check if G and K are defined
    if isempty(app.G) || isempty(app.K)
        uialert(app.UIFigure, 'Please define system G(s) and controller K(s) first.', 'Missing System');
        return;
    end
    
    % Get analysis parameters
    selectedParam = app.SensitivityParamDropdown.Value;
    variationRange = app.SensitivityRangeEdit.Value / 100; % Convert from percentage
    numPoints = app.SensitivityPointsEdit.Value;
    metric = app.SensitivityMetricDropdown.Value;
    
    % Create parameter variations
    variations = linspace(-variationRange, variationRange, numPoints);
    
    % Initialize result arrays
    metricValues = zeros(size(variations));
    baseValue = 0;
    
    % Display status
    app.SensitivityHTML.HTMLSource = createHtmlContent('Running sensitivity analysis...', ...
        ['Parameter: ' selectedParam], ...
        ['Metric: ' metric]);
    drawnow;
    
    try
        % Extract controller parameters
        [num_K, den_K] = tfdata(app.K, 'v');
        [num_G, den_G] = tfdata(app.G, 'v');
        
        % Calculate the nominal (base) system performance first
        nominal_K = app.K;
        nominal_G = app.G;
        [T_nominal, ~, ~] = transferfunctions(nominal_G, nominal_K);
        
        % Calculate base metric value
        baseValue = calculatePerformanceMetric(T_nominal, metric);
        
        if isnan(baseValue) || isinf(baseValue)
            error('Base system performance metric cannot be calculated. Please check system stability or select a different metric.');
        end
        
        % Loop through variations
        for i = 1:length(variations)
            % Create modified controller or plant based on selected parameter
            K_modified = app.K;
            G_modified = app.G;
            
            paramValue = variations(i);
            
            switch selectedParam
                case 'Kp (Controller Gain)'
                    if length(num_K) >= 1
                        K_modified = tf(num_K * (1 + paramValue), den_K);
                    end
                    
                case 'Ki (Integral Gain)'
                    % Find Ki in controller
                    if length(num_K) >= 2 && length(den_K) >= 2 && den_K(end) == 0
                        % For controllers with integrator Ki is typically the last coefficient
                        num_K_modified = num_K;
                        num_K_modified(end) = num_K(end) * (1 + paramValue);
                        K_modified = tf(num_K_modified, den_K);
                    end
                    
                case 'Kd (Derivative Gain)'
                    % For controllers with derivative term
                    if length(num_K) >= 2
                        num_K_modified = num_K;
                        num_K_modified(1) = num_K(1) * (1 + paramValue);
                        K_modified = tf(num_K_modified, den_K);
                    end
                    
                case 'Plant Gain'
                    G_modified = tf(num_G * (1 + paramValue), den_G);
                    
                case 'Time Constant'
                    % Approximate first-order time constant modification
                    % Assumes the first coefficient in denominator relates to time constant
                    if length(den_G) >= 2
                        den_G_modified = den_G;
                        den_G_modified(1) = den_G(1) * (1 + paramValue);
                        G_modified = tf(num_G, den_G_modified);
                    end
            end
            
            % Calculate modified closed-loop system
            [T_modified, ~, ~] = transferfunctions(G_modified, K_modified);
            
            % Calculate the selected performance metric
            metricValues(i) = calculatePerformanceMetric(T_modified, metric);
            
            % Handle problematic values
            if isnan(metricValues(i)) || isinf(metricValues(i))
                metricValues(i) = NaN;
            end
        end
        
        % Remove any NaN values for calculation
        validIndices = ~isnan(metricValues) & ~isnan(variations);
        
        % Calculate normalized sensitivity - percentage change in metric per percentage change in parameter
        if any(validIndices) && sum(validIndices) > 2
            % Calculate sensitivity coefficient using central difference
            normalizedMetrics = (metricValues(validIndices) - baseValue) / baseValue;
            validVariations = variations(validIndices);
            
            % Use polyfit to get the slope at the origin
            if length(normalizedMetrics) >= 3
                p = polyfit(validVariations, normalizedMetrics, 1);
                sensitivityCoefficient = p(1);
            else
                sensitivityCoefficient = NaN;
            end
        else
            sensitivityCoefficient = NaN;
        end
        
        % Enhanced plotting section for sensitivity analysis
        cla(app.SensitivityUIAxes);

        % Plot non-NaN values only
        validIdx = ~isnan(metricValues);
        if sum(validIdx) <= 1
            % Not enough valid points to plot a line
            text(app.SensitivityUIAxes, 0.5, 0.5, 'Insufficient valid data points for plotting', ...
                'HorizontalAlignment', 'center', 'Units', 'normalized', 'FontSize', 14, 'Color', 'red');
        else
            % Add special handling for Gain Margin
            if strcmp(metric, 'Gain Margin')
                % Plot with appropriate scaling for gain margin in dB
                plot(app.SensitivityUIAxes, variations(validIdx) * 100, metricValues(validIdx), 'LineWidth', 2);
                hold(app.SensitivityUIAxes, 'on');
                plot(app.SensitivityUIAxes, 0, baseValue, 'ro', 'MarkerSize', 10, 'MarkerFaceColor', 'r');
                
                % Add horizontal line at 0 dB (marginally stable system)
                yline(app.SensitivityUIAxes, 0, 'r--', 'Marginally Stable', 'LineWidth', 1.5, 'Alpha', 0.7);
                
                % Add horizontal line at 6 dB (recommended minimum)
                yline(app.SensitivityUIAxes, 6, 'g--', 'Recommended Min (6 dB)', 'LineWidth', 1.5, 'Alpha', 0.7);
                
                % Set appropriate y-axis limits
                validValues = metricValues(validIdx);
                yMin = min([0, min(validValues)]) - 2;
                yMax = max([10, max(validValues)]) + 2;
                ylim(app.SensitivityUIAxes, [yMin, yMax]);
                
                hold(app.SensitivityUIAxes, 'off');
                
                % Add labels and title
                title(app.SensitivityUIAxes, ['Gain Margin Sensitivity to ' selectedParam]);
                xlabel(app.SensitivityUIAxes, 'Parameter Variation (%)');
                ylabel(app.SensitivityUIAxes, 'Gain Margin (dB)');
                
            else
                % Standard plotting for other metrics
                plot(app.SensitivityUIAxes, variations(validIdx) * 100, metricValues(validIdx), 'LineWidth', 2);
                hold(app.SensitivityUIAxes, 'on');
                plot(app.SensitivityUIAxes, 0, baseValue, 'ro', 'MarkerSize', 10, 'MarkerFaceColor', 'r');
                hold(app.SensitivityUIAxes, 'off');
                
                % Add labels with appropriate units based on metric
                title(app.SensitivityUIAxes, ['Sensitivity Analysis for ' selectedParam]);
                xlabel(app.SensitivityUIAxes, 'Parameter Variation (%)');
                
                % Add appropriate units to y-axis based on metric
                switch metric
                    case 'Settling Time'
                        ylabel(app.SensitivityUIAxes, 'Settling Time (s)');
                    case 'Overshoot'
                        ylabel(app.SensitivityUIAxes, 'Overshoot (%)');
                    case 'Rise Time'
                        ylabel(app.SensitivityUIAxes, 'Rise Time (s)');
                    case 'Bandwidth'
                        ylabel(app.SensitivityUIAxes, 'Bandwidth (rad/s)');
                    case 'Phase Margin'
                        ylabel(app.SensitivityUIAxes, 'Phase Margin (degrees)');
                    otherwise
                        ylabel(app.SensitivityUIAxes, metric);
                end
                
                % Ensure axes have reasonable limits
                if ~all(isnan(metricValues))
                    validValues = metricValues(~isnan(metricValues));
                    if ~isempty(validValues)
                        yMin = min(validValues) * 0.9;
                        yMax = max(validValues) * 1.1;
                        if yMin ~= yMax
                            ylim(app.SensitivityUIAxes, [yMin, yMax]);
                        end
                    end
                end
            end
            
            grid(app.SensitivityUIAxes, 'on');
        end

        % Update results in HTML display
        if ~isnan(sensitivityCoefficient)
            sensText = num2str(sensitivityCoefficient, '%.3f');
            interpretation = interpretSensitivityCoefficient(sensitivityCoefficient, selectedParam, metric);
        else
            sensText = 'N/A (Could not calculate)';
            interpretation = 'Could not determine sensitivity coefficient. The system may become unstable for some parameter values.';
        end
        
        app.SensitivityHTML.HTMLSource = createHtmlContent('Sensitivity Analysis Results:', ...
            ['Parameter: ' selectedParam], ...
            ['Base Value: ' num2str(baseValue, '%.4g') getMetricUnit(metric)], ...
            ['Sensitivity Coefficient: ' sensText], ...
            '', ...
            'Interpretation:', ...
            interpretation);
        
    catch ME
        app.SensitivityHTML.HTMLSource = createHtmlContent('Error in sensitivity analysis:', ...
            ME.message, '', ...
            'Tips for troubleshooting:', ...
            '- Check if the system becomes unstable for the parameter range', ...
            '- Reduce the variation range', ...
            '- Try a different performance metric');
        
        % Create an empty plot with error message
        cla(app.SensitivityUIAxes);
        text(app.SensitivityUIAxes, 0.5, 0.5, {'Error performing sensitivity analysis', ME.message}, ...
            'HorizontalAlignment', 'center', 'Units', 'normalized');
    end
end


function runMuAnalysis(app)
    % RUNMUANALYSIS - Performs μ-analysis for robustness assessment
    % This function analyzes system robustness with parametric uncertainties
    
    % Check if G and K are defined
    if isempty(app.G) || isempty(app.K)
        uialert(app.UIFigure, 'Please define system G(s) and controller K(s) first.', 'Missing System');
        return;
    end
    
    % Get uncertainty parameters
    gainUncertainty = app.GainUncertaintyEdit.Value / 100;
    timeConstUncertainty = app.TimeConstUncertaintyEdit.Value / 100;
    delayUncertainty = app.DelayUncertaintyEdit.Value / 100;
    
    % Get frequency range
    freqMin = app.FreqMinEdit.Value;
    freqMax = app.FreqMaxEdit.Value;
    
    % Display status
    app.MuAnalysisHTML.HTMLSource = createHtmlContent('Running μ-analysis...', ...
        ['Gain Uncertainty: ' num2str(gainUncertainty*100) '%'], ...
        ['Time Constant Uncertainty: ' num2str(timeConstUncertainty*100) '%'], ...
        ['Delay Uncertainty: ' num2str(delayUncertainty*100) '%']);
    drawnow;
    
    % Check if Robust Control Toolbox is available
    hasRobustToolbox = false;
    try
        hasRobustToolbox = license('test', 'robust_control_toolbox');
    catch
        % If license check fails, assume it's not available
        hasRobustToolbox = false;
    end
    
    if ~hasRobustToolbox
        % Fall back to simplified analysis if toolbox is not available
        performSimplifiedRobustnessAnalysis(app);
        return;
    end
    
    try
        % Clear existing plot
        cla(app.MuAnalysisUIAxes);
        
        % Calculate nominal closed-loop transfer function
        T_nominal = feedback(app.G * app.K, 1);
        
        % Create frequency vector
        omega = logspace(log10(freqMin), log10(freqMax), 100);
        
        % Create uncertain model
        % Extract plant parameters for uncertainty modeling
        [num_G, den_G] = tfdata(app.G, 'v');
        
        % Create uncertain elements using ureal
        % Note: This requires Robust Control Toolbox
        gain_unc = ureal('gain', 1, 'Percentage', gainUncertainty*100);
        
        % Create uncertain plant model
        G_unc = gain_unc * app.G;
        
        % Add time constant uncertainty if applicable
        if length(den_G) >= 2
            % Simplistic approach - modify first coefficient in denominator
            den_G_unc = den_G;
            tc_unc = ureal('timeconst', 1, 'Percentage', timeConstUncertainty*100);
            den_G_unc(1) = den_G(1) * tc_unc;
            
            % Update uncertain model
            G_unc = tf(num_G, den_G_unc);
        end
        
        % Add delay uncertainty if specified
        if delayUncertainty > 0
            delay_unc = ureal('delay', 1, 'Percentage', delayUncertainty*100);
            delay_term = tf([delay_unc, 0], [1, 1]);
            G_unc = G_unc * delay_term;
        end
        
        % Create uncertain closed-loop system
        CL_unc = feedback(G_unc * app.K, 1);
        
        % Generate random samples of the uncertain system
        n_samples = 20;
        CL_samples = usample(CL_unc, n_samples);
        
        % Calculate worst-case frequency response
        worst_mag = zeros(size(omega));
        nominal_mag = zeros(size(omega));
        
        % Get nominal frequency response
        [nominal_mag, ~, ~] = bode(T_nominal, omega);
        nominal_mag = squeeze(nominal_mag);
        
        % Find worst-case magnitude at each frequency
        for i = 1:length(omega)
            w = omega(i);
            max_mag = 0;
            
            for j = 1:n_samples
                [mag, ~, ~] = bode(CL_samples(:,:,j), w);
                mag = squeeze(mag);
                if mag > max_mag
                    max_mag = mag;
                end
            end
            
            worst_mag(i) = max_mag;
        end
        
        % Calculate μ-bounds (simplified approximation)
        mu_upper = worst_mag ./ nominal_mag;
        mu_lower = 0.8 * mu_upper;  % Simple approximation for demonstration
        
        % Plot results
        semilogx(app.MuAnalysisUIAxes, omega, mu_upper, 'r-', 'LineWidth', 2, 'DisplayName', 'Upper Bound');
        hold(app.MuAnalysisUIAxes, 'on');
        semilogx(app.MuAnalysisUIAxes, omega, mu_lower, 'b--', 'LineWidth', 1.5, 'DisplayName', 'Lower Bound');
        yline(app.MuAnalysisUIAxes, 1, 'k:', 'LineWidth', 1, 'DisplayName', 'Robust Stability Boundary');
        hold(app.MuAnalysisUIAxes, 'off');
        
        % Format plot
        title(app.MuAnalysisUIAxes, 'μ-Analysis Results');
        xlabel(app.MuAnalysisUIAxes, 'Frequency (rad/s)');
        ylabel(app.MuAnalysisUIAxes, 'μ-Value');
        grid(app.MuAnalysisUIAxes, 'on');
        legend(app.MuAnalysisUIAxes, 'Location', 'best');
        
        % Calculate robust stability margin
        max_mu = max(mu_upper);
        robust_stability_margin = 1/max_mu;
        
        % Update results
        app.MuAnalysisHTML.HTMLSource = createHtmlContent('μ-Analysis Results:', ...
            ['Maximum μ-Value: ' num2str(max_mu, '%.4f')], ...
            ['Robust Stability Margin: ' num2str(robust_stability_margin, '%.4f') ' (>1 means robust)'], ...
            '', ...
            'Interpretation:', ...
            ['The system ' conditionalString(max_mu < 1, 'is', 'is not') ' robustly stable for the specified uncertainties.'], ...
            ['The system can tolerate up to ' num2str(100*robust_stability_margin, '%.1f') '% of the modeled uncertainty before potential instability.']);
        
    catch ME
        % If μ-analysis fails, use the simplified method
        disp(['μ-analysis error: ' ME.message]);
        app.MuAnalysisHTML.HTMLSource = createHtmlContent('Error in μ-analysis:', ...
            ME.message, '', ...
            'Using simplified robustness analysis instead...');
        
        % Fall back to simplified analysis
        performSimplifiedRobustnessAnalysis(app);
    end
end

function performSimplifiedRobustnessAnalysis(app)
    % PERFORMSIMPLIFIEDROBUSTNESSANALYSIS - Simplified robustness analysis
    % when the Robust Control Toolbox is not available
    
    % Get uncertainty parameters
    gainUncertainty = app.GainUncertaintyEdit.Value / 100;
    timeConstUncertainty = app.TimeConstUncertaintyEdit.Value / 100;
    
    % Get frequency range
    freqMin = app.FreqMinEdit.Value;
    freqMax = app.FreqMaxEdit.Value;
    omega = logspace(log10(freqMin), log10(freqMax), 100);
    
    try
        % Calculate nominal sensitivity and complementary sensitivity
        [T_nominal, S_nominal, L_nominal] = transferfunctions(app.G, app.K);
        
        % Calculate nominal stability margins
        [Gm, Pm, Wcg, Wcp] = margin(L_nominal);
        Gm_dB = 20*log10(Gm);
        
        % Calculate nominal robustness metrics
        % Sensitivity peak (Ms)
        [mag_S, ~, wout_S] = bode(S_nominal, omega);
        mag_S = squeeze(mag_S);
        Ms = max(mag_S);
        w_Ms = wout_S(mag_S == Ms);
        
        % Complementary sensitivity peak (Mt)
        [mag_T, ~, wout_T] = bode(T_nominal, omega);
        mag_T = squeeze(mag_T);
        Mt = max(mag_T);
        w_Mt = wout_T(mag_T == Mt);
        
        % Generate variations
        num_variations = 20;
        gain_variations = linspace(1-gainUncertainty, 1+gainUncertainty, num_variations);
        
        % Initialize arrays for results
        Gm_array = zeros(num_variations, 1);
        Pm_array = zeros(num_variations, 1);
        Ms_array = zeros(num_variations, 1);
        Mt_array = zeros(num_variations, 1);
        
        % Variables to track worst case
        worst_Gm = Inf;
        worst_Pm = Inf;
        worst_Ms = Ms;
        worst_Mt = Mt;
        
        % Perform analysis for different gain variations
        for i = 1:num_variations
            % Create varied plant
            [num_G, den_G] = tfdata(app.G, 'v');
            G_varied = tf(num_G * gain_variations(i), den_G);
            
            % Vary time constant if applicable
            if timeConstUncertainty > 0 && length(den_G) >= 2
                tc_variation = 1 + (rand()*2-1) * timeConstUncertainty;
                den_G_varied = den_G;
                den_G_varied(1) = den_G(1) * tc_variation;
                G_varied = tf(num_G * gain_variations(i), den_G_varied);
            end
            
            % Calculate loop transfer function and sensitivity functions
            [T_varied, S_varied, L_varied] = transferfunctions(G_varied, app.K);
            
            try
                % Calculate stability margins
                [Gm_var, Pm_var] = margin(L_varied);
                Gm_var_dB = 20*log10(Gm_var);
                
                % Calculate sensitivity peaks
                [mag_S_var, ~, ~] = bode(S_varied, omega);
                mag_S_var = squeeze(mag_S_var);
                Ms_var = max(mag_S_var);
                
                [mag_T_var, ~, ~] = bode(T_varied, omega);
                mag_T_var = squeeze(mag_T_var);
                Mt_var = max(mag_T_var);
                
                % Store results
                Gm_array(i) = Gm_var_dB;
                Pm_array(i) = Pm_var;
                Ms_array(i) = Ms_var;
                Mt_array(i) = Mt_var;
                
                % Update worst cases
                if Gm_var_dB < worst_Gm
                    worst_Gm = Gm_var_dB;
                end
                if Pm_var < worst_Pm
                    worst_Pm = Pm_var;
                end
                if Ms_var > worst_Ms
                    worst_Ms = Ms_var;
                end
                if Mt_var > worst_Mt
                    worst_Mt = Mt_var;
                end
            catch
                % Skip this variation if analysis fails
                Gm_array(i) = NaN;
                Pm_array(i) = NaN;
                Ms_array(i) = NaN;
                Mt_array(i) = NaN;
            end
        end
        
        % Clear existing plot and create a combined plot instead of multiple subplots
        cla(app.MuAnalysisUIAxes);
        
        % Plot all metrics on a single axis with a shared legend
        hold(app.MuAnalysisUIAxes, 'on');
        plot(app.MuAnalysisUIAxes, gain_variations, Gm_array, 'bo-', 'LineWidth', 1.5, 'DisplayName', 'Gain Margin (dB)');
        plot(app.MuAnalysisUIAxes, gain_variations, Pm_array/10, 'ro-', 'LineWidth', 1.5, 'DisplayName', 'Phase Margin/10 (deg)');
        plot(app.MuAnalysisUIAxes, gain_variations, Ms_array, 'go-', 'LineWidth', 1.5, 'DisplayName', 'Sensitivity Peak (Ms)');
        plot(app.MuAnalysisUIAxes, gain_variations, Mt_array, 'mo-', 'LineWidth', 1.5, 'DisplayName', 'Comp. Sensitivity Peak (Mt)');
        
        % Add labels and formatting
        xlabel(app.MuAnalysisUIAxes, 'Gain Variation');
        ylabel(app.MuAnalysisUIAxes, 'Performance Metrics');
        title(app.MuAnalysisUIAxes, 'Robustness Analysis Results');
        grid(app.MuAnalysisUIAxes, 'on');
        legend(app.MuAnalysisUIAxes, 'Location', 'best');
        hold(app.MuAnalysisUIAxes, 'off');
        
        % Update results HTML
        app.MuAnalysisHTML.HTMLSource = createHtmlContent('Simplified Robustness Analysis Results:', ...
            ['Nominal Gain Margin: ' num2str(Gm_dB, '%.2f') ' dB'], ...
            ['Worst-case Gain Margin: ' num2str(worst_Gm, '%.2f') ' dB'], ...
            ['Nominal Phase Margin: ' num2str(Pm, '%.2f') ' deg'], ...
            ['Worst-case Phase Margin: ' num2str(worst_Pm, '%.2f') ' deg'], ...
            ['Nominal Sensitivity Peak (Ms): ' num2str(Ms, '%.2f')], ...
            ['Worst-case Ms: ' num2str(worst_Ms, '%.2f')], ...
            ['Nominal Comp. Sensitivity Peak (Mt): ' num2str(Mt, '%.2f')], ...
            ['Worst-case Mt: ' num2str(worst_Mt, '%.2f')], ...
            '', ...
            'Robustness Assessment:', ...
            ['System ' conditionalString(worst_Gm > 6 && worst_Pm > 30, 'maintains acceptable', 'loses') ...
             ' stability margins under the specified uncertainties.'], ...
            ['Ms < 2 indicates good robustness (lower is better): ' conditionalString(worst_Ms < 2, 'Satisfied', 'Not satisfied')]);
        
    catch ME
        % Handle errors
        disp(['Simplified robustness analysis error: ' ME.message]);
        
        % Create minimal plot with error message
        cla(app.MuAnalysisUIAxes);
        text(app.MuAnalysisUIAxes, 0.5, 0.5, {'Error in robustness analysis', ME.message}, ...
            'HorizontalAlignment', 'center', 'Units', 'normalized');
        
        app.MuAnalysisHTML.HTMLSource = createHtmlContent('Error in robustness analysis:', ...
            ME.message, '', ...
            'Tips for troubleshooting:', ...
            '- Check that the system is stable', ...
            '- Try a smaller uncertainty range', ...
            '- Ensure frequency range is appropriate');
    end
end
        
function runMonteCarloSimulation(app)
    % RUNMONTECARLSIMULATION - Performs Monte Carlo simulations to analyze robustness
    % This enhanced function runs multiple simulations with randomly varied parameters
    % and displays results in a professionally styled HTML table
    
    % Check if G and K are defined
    if isempty(app.G) || isempty(app.K)
        uialert(app.UIFigure, 'Please define system G(s) and controller K(s) first.', 'Missing System');
        return;
    end
    
    % Get simulation parameters
    numSimulations = app.NumSimulationsEdit.Value;
    selectedMetric = app.MonteCarloMetricDropdown.Value;
    
    % Initialize parameter settings structure
    paramSettings = struct();
    
    % Kp distribution
    paramSettings.Kp.dist = app.KpDistDropdown.Value;
    paramSettings.Kp.param1 = app.KpParam1Edit.Value;
    paramSettings.Kp.param2 = app.KpParam2Edit.Value;
    
    % Ki distribution
    paramSettings.Ki.dist = app.KiDistDropdown.Value;
    paramSettings.Ki.param1 = app.KiParam1Edit.Value;
    paramSettings.Ki.param2 = app.KiParam2Edit.Value;
    
    % Kd distribution
    paramSettings.Kd.dist = app.KdDistDropdown.Value;
    paramSettings.Kd.param1 = app.KdParam1Edit.Value;
    paramSettings.Kd.param2 = app.KdParam2Edit.Value;
    
    % Display status
    app.MonteCarloHTML.HTMLSource = createHtmlContent('Running Monte Carlo simulation...', ...
        ['Number of Simulations: ' num2str(numSimulations)]);
    drawnow;
    
    try
        % Extract nominal controller parameters - assuming PID structure
        [num_K, den_K] = tfdata(app.K, 'v');
        
        % Determine controller type based on transfer function structure
        controllerType = determineControllerType(app.K);
        
        % Initialize result arrays
        settlingTimeValues = zeros(numSimulations, 1);
        overshootValues = zeros(numSimulations, 1);
        riseTimeValues = zeros(numSimulations, 1);
        
        % Time vector for step response
        t = linspace(0, 10, 100);
        responsesMatrix = zeros(numSimulations, length(t));
        
        % Create waitbar instead of uiprogressbar
        waitbarHandle = waitbar(0, 'Running Monte Carlo simulations...', ...
            'Name', 'Monte Carlo Progress');
        
        % Store parameters for each simulation (for statistical analysis)
        Kp_values = zeros(numSimulations, 1);
        Ki_values = zeros(numSimulations, 1);
        Kd_values = zeros(numSimulations, 1);
        
        % Count successful simulations
        successCount = 0;
        
        % Run simulations
        for i = 1:numSimulations
            try
                % Generate random parameter values
                Kp = generateRandomParameter(paramSettings.Kp);
                Ki = generateRandomParameter(paramSettings.Ki);
                Kd = generateRandomParameter(paramSettings.Kd);
                
                % Store parameter values
                Kp_values(i) = Kp;
                Ki_values(i) = Ki;
                Kd_values(i) = Kd;
                
                % Create modified controller based on controller type
                switch controllerType
                    case 'P'
                        K_modified = tf(Kp, 1);
                    case 'PI'
                        K_modified = tf([Kp, Ki], [1, 0]);
                    case 'PD'
                        % Add filter for derivative term
                        epsilon = 0.1;
                        K_modified = tf([Kd, Kp], [epsilon*Kd, 1]);
                    case 'PID'
                        % Add filter for derivative term
                        epsilon = 0.1;
                        K_modified = tf([Kd, Kp, Ki], [epsilon*Kd, 1, 0]);
                    otherwise
                        % Unknown controller type - use original with scaled gains
                        K_modified = tf(num_K * Kp, den_K);
                end
                
                % Calculate closed-loop system
                T_modified = feedback(app.G * K_modified, 1);
                
                % Simulate step response
                [y, ~] = step(T_modified, t);
                responsesMatrix(i, :) = y;
                
                % Calculate performance metrics
                info = stepinfo(T_modified);
                settlingTimeValues(i) = info.SettlingTime;
                overshootValues(i) = info.Overshoot;
                riseTimeValues(i) = info.RiseTime;
                
                % Increment success counter
                successCount = successCount + 1;
            catch ME
                % Handle error in this simulation
                disp(['Error in simulation ' num2str(i) ': ' ME.message]);
                settlingTimeValues(i) = NaN;
                overshootValues(i) = NaN;
                riseTimeValues(i) = NaN;
                responsesMatrix(i, :) = NaN(1, length(t));
            end
            
            % Update waitbar
            if ishandle(waitbarHandle)
                waitbar(i/numSimulations, waitbarHandle, ...
                    sprintf('Running simulations... %d/%d', i, numSimulations));
            end
        end
        
        % Close waitbar if it still exists
        if ishandle(waitbarHandle)
            close(waitbarHandle);
        end
        
        % Clear axes for plotting and completely reset properties
        cla(app.MonteCarloHistUIAxes);
        cla(app.MonteCarloResponseUIAxes);
        
        % Complete reset of the histogram axes - much more thorough
        cla(app.MonteCarloHistUIAxes, 'reset');  % Complete reset of axis properties
        
        % Make sure the axis is visible and has grid
        app.MonteCarloHistUIAxes.Visible = 'on';
        app.MonteCarloHistUIAxes.XGrid = 'on';
        app.MonteCarloHistUIAxes.YGrid = 'on';
        
        % Remove NaN values for statistics
        validIndices = ~isnan(settlingTimeValues);
        validSettling = settlingTimeValues(validIndices);
        validOvershoot = overshootValues(validIndices);
        validRiseTime = riseTimeValues(validIndices);
        
        % Check if we have enough valid results
        if successCount < 1
            error('All simulations failed. Try different parameter ranges or check system stability.');
        end
        
        % Get valid parameter values
        validKp = Kp_values(validIndices);
        validKi = Ki_values(validIndices);
        validKd = Kd_values(validIndices);
        
        % Compute correlation matrices for all metrics
        corrMatrices = struct();
        
        % For settling time
        corrMatrices.SettlingTime.Kp = calculateCorrelation(validKp, validSettling);
        corrMatrices.SettlingTime.Ki = calculateCorrelation(validKi, validSettling);
        corrMatrices.SettlingTime.Kd = calculateCorrelation(validKd, validSettling);
        
        % For overshoot
        corrMatrices.Overshoot.Kp = calculateCorrelation(validKp, validOvershoot);
        corrMatrices.Overshoot.Ki = calculateCorrelation(validKi, validOvershoot);
        corrMatrices.Overshoot.Kd = calculateCorrelation(validKd, validOvershoot);
        
        % For rise time
        corrMatrices.RiseTime.Kp = calculateCorrelation(validKp, validRiseTime);
        corrMatrices.RiseTime.Ki = calculateCorrelation(validKi, validRiseTime);
        corrMatrices.RiseTime.Kd = calculateCorrelation(validKd, validRiseTime);
        
        % Calculate statistics for all metrics for comprehensive display
        % Settling Time statistics
        meanST = mean(validSettling);
        stdST = std(validSettling);
        minST = min(validSettling);
        maxST = max(validSettling);
        covST = 100*stdST/meanST;
        
        % Overshoot statistics
        meanOS = mean(validOvershoot);
        stdOS = std(validOvershoot);
        minOS = min(validOvershoot);
        maxOS = max(validOvershoot);
        covOS = 100*stdOS/meanOS;
        
        % Rise Time statistics
        meanRT = mean(validRiseTime);
        stdRT = std(validRiseTime);
        minRT = min(validRiseTime);
        maxRT = max(validRiseTime);
        covRT = 100*stdRT/meanRT;
        
        % Create metrics data for comprehensive table
        metricsData = {
            {'Settling Time', formatNumber(meanST, 3), formatNumber(stdST, 3), formatNumber(covST, 1), 's', formatNumber(minST, 3), formatNumber(maxST, 3)},
            {'Overshoot', formatNumber(meanOS, 2), formatNumber(stdOS, 2), formatNumber(covOS, 1), '%', formatNumber(minOS, 2), formatNumber(maxOS, 2)},
            {'Rise Time', formatNumber(meanRT, 3), formatNumber(stdRT, 3), formatNumber(covRT, 1), 's', formatNumber(minRT, 3), formatNumber(maxRT, 3)}
        };
        
        % Create correlation data for comprehensive table
        correlationData = {
            {'Settling Time', formatNumber(corrMatrices.SettlingTime.Kp, 2), formatNumber(corrMatrices.SettlingTime.Ki, 2), formatNumber(corrMatrices.SettlingTime.Kd, 2)},
            {'Overshoot', formatNumber(corrMatrices.Overshoot.Kp, 2), formatNumber(corrMatrices.Overshoot.Ki, 2), formatNumber(corrMatrices.Overshoot.Kd, 2)},
            {'Rise Time', formatNumber(corrMatrices.RiseTime.Kp, 2), formatNumber(corrMatrices.RiseTime.Ki, 2), formatNumber(corrMatrices.RiseTime.Kd, 2)}
        };
        
        % Create comprehensive HTML results table only for Correlation Matrix view
        htmlComprehensive = createComprehensiveResultsTable(successCount, numSimulations, metricsData, correlationData);
        
        % Plot based on selected metric type
        switch selectedMetric
            case 'Settling Time'
                % Set titles and labels for histogram
                title(app.MonteCarloHistUIAxes, 'Settling Time Distribution');
                xlabel(app.MonteCarloHistUIAxes, 'Settling Time (s)');
                ylabel(app.MonteCarloHistUIAxes, 'Frequency');
                
                % Plot histogram
                histogram(app.MonteCarloHistUIAxes, validSettling);
                
                % Calculate 95% confidence interval for Settling Time
                ci95 = [meanST - 1.96*stdST/sqrt(length(validSettling)), ...
                       meanST + 1.96*stdST/sqrt(length(validSettling))];
                
                % Create standard HTML table for selected metric (NOT comprehensive)
                htmlContent = createResultsTable(selectedMetric, successCount, numSimulations, {
                    {'Mean', formatNumber(meanST, 3), 's'},
                    {'Std Dev', formatNumber(stdST, 3), 's'},
                    {'95% CI', [formatNumber(ci95(1), 3) ' - ' formatNumber(ci95(2), 3)], 's'},
                    {'Min', formatNumber(minST, 3), 's'},
                    {'Max', formatNumber(maxST, 3), 's'},
                    {'Median', formatNumber(median(validSettling), 3), 's'},
                    {'CoV', formatNumber(covST, 1), '%'}
                }, {
                    {'Kp Correlation', formatNumber(corrMatrices.SettlingTime.Kp, 2), getCorrelationStrength(corrMatrices.SettlingTime.Kp)},
                    {'Ki Correlation', formatNumber(corrMatrices.SettlingTime.Ki, 2), getCorrelationStrength(corrMatrices.SettlingTime.Ki)},
                    {'Kd Correlation', formatNumber(corrMatrices.SettlingTime.Kd, 2), getCorrelationStrength(corrMatrices.SettlingTime.Kd)}
                });
                
                % Update HTML with standard table
                app.MonteCarloHTML.HTMLSource = htmlContent;
                
            case 'Overshoot'
                % Set titles and labels for histogram
                title(app.MonteCarloHistUIAxes, 'Overshoot Distribution');
                xlabel(app.MonteCarloHistUIAxes, 'Overshoot (%)');
                ylabel(app.MonteCarloHistUIAxes, 'Frequency');
                
                % Plot histogram
                histogram(app.MonteCarloHistUIAxes, validOvershoot);
                
                % Calculate 95% confidence interval for Overshoot
                ci95 = [meanOS - 1.96*stdOS/sqrt(length(validOvershoot)), ...
                       meanOS + 1.96*stdOS/sqrt(length(validOvershoot))];
                
                % Create standard HTML table for selected metric (NOT comprehensive)
                htmlContent = createResultsTable(selectedMetric, successCount, numSimulations, {
                    {'Mean', formatNumber(meanOS, 2), '%'},
                    {'Std Dev', formatNumber(stdOS, 2), '%'},
                    {'95% CI', [formatNumber(ci95(1), 2) ' - ' formatNumber(ci95(2), 2)], '%'},
                    {'Min', formatNumber(minOS, 2), '%'},
                    {'Max', formatNumber(maxOS, 2), '%'},
                    {'Median', formatNumber(median(validOvershoot), 2), '%'},
                    {'CoV', formatNumber(covOS, 1), '%'}
                }, {
                    {'Kp Correlation', formatNumber(corrMatrices.Overshoot.Kp, 2), getCorrelationStrength(corrMatrices.Overshoot.Kp)},
                    {'Ki Correlation', formatNumber(corrMatrices.Overshoot.Ki, 2), getCorrelationStrength(corrMatrices.Overshoot.Ki)},
                    {'Kd Correlation', formatNumber(corrMatrices.Overshoot.Kd, 2), getCorrelationStrength(corrMatrices.Overshoot.Kd)}
                });
                
                % Update HTML with standard table
                app.MonteCarloHTML.HTMLSource = htmlContent;
                
            case 'Rise Time'
                % Set titles and labels for histogram
                title(app.MonteCarloHistUIAxes, 'Rise Time Distribution');
                xlabel(app.MonteCarloHistUIAxes, 'Rise Time (s)');
                ylabel(app.MonteCarloHistUIAxes, 'Frequency');
                
                % Plot histogram
                histogram(app.MonteCarloHistUIAxes, validRiseTime);
                
                % Calculate 95% confidence interval for Rise Time
                ci95 = [meanRT - 1.96*stdRT/sqrt(length(validRiseTime)), ...
                       meanRT + 1.96*stdRT/sqrt(length(validRiseTime))];
                
                % Create standard HTML table for selected metric (NOT comprehensive)
                htmlContent = createResultsTable(selectedMetric, successCount, numSimulations, {
                    {'Mean', formatNumber(meanRT, 3), 's'},
                    {'Std Dev', formatNumber(stdRT, 3), 's'},
                    {'95% CI', [formatNumber(ci95(1), 3) ' - ' formatNumber(ci95(2), 3)], 's'},
                    {'Min', formatNumber(minRT, 3), 's'},
                    {'Max', formatNumber(maxRT, 3), 's'},
                    {'Median', formatNumber(median(validRiseTime), 3), 's'},
                    {'CoV', formatNumber(covRT, 1), '%'}
                }, {
                    {'Kp Correlation', formatNumber(corrMatrices.RiseTime.Kp, 2), getCorrelationStrength(corrMatrices.RiseTime.Kp)},
                    {'Ki Correlation', formatNumber(corrMatrices.RiseTime.Ki, 2), getCorrelationStrength(corrMatrices.RiseTime.Ki)},
                    {'Kd Correlation', formatNumber(corrMatrices.RiseTime.Kd, 2), getCorrelationStrength(corrMatrices.RiseTime.Kd)}
                });
                
                % Update HTML with standard table
                app.MonteCarloHTML.HTMLSource = htmlContent;
                
            case 'Correlation Matrix'
                % Create correlation matrix for visualization
                corrMatrix = zeros(3, 3);
                
                % Fill correlation matrix with values
                corrMatrix(1, 1) = corrMatrices.SettlingTime.Kp;
                corrMatrix(1, 2) = corrMatrices.SettlingTime.Ki;
                corrMatrix(1, 3) = corrMatrices.SettlingTime.Kd;
                
                corrMatrix(2, 1) = corrMatrices.Overshoot.Kp;
                corrMatrix(2, 2) = corrMatrices.Overshoot.Ki;
                corrMatrix(2, 3) = corrMatrices.Overshoot.Kd;
                
                corrMatrix(3, 1) = corrMatrices.RiseTime.Kp;
                corrMatrix(3, 2) = corrMatrices.RiseTime.Ki;
                corrMatrix(3, 3) = corrMatrices.RiseTime.Kd;
                
                % Create a heatmap visualization in the histogram axis
                imagesc(app.MonteCarloHistUIAxes, corrMatrix);
                colormap(app.MonteCarloHistUIAxes, redblue(21)); % Red-blue colormap centered at zero
                colorbar(app.MonteCarloHistUIAxes);
                caxis(app.MonteCarloHistUIAxes, [-1 1]); % Set color scale from -1 to 1
                
                % Set axis labels
                set(app.MonteCarloHistUIAxes, 'XTick', 1:3, 'XTickLabel', {'Kp', 'Ki', 'Kd'});
                set(app.MonteCarloHistUIAxes, 'YTick', 1:3, 'YTickLabel', {'Settling Time', 'Overshoot', 'Rise Time'});
                
                % Add correlation values as text in each cell
                for i = 1:3
                    for j = 1:3
                        textColor = 'k'; % Default text color (black)
                        
                        % Make text white for dark background cells (strong correlations)
                        if abs(corrMatrix(i, j)) > 0.7
                            textColor = 'w';
                        end
                        
                        % Add text with correlation value to cell
                        text(app.MonteCarloHistUIAxes, j, i, num2str(corrMatrix(i, j), '%.2f'), ...
                            'HorizontalAlignment', 'center', 'Color', textColor, 'FontWeight', 'bold');
                    end
                end
                
                title(app.MonteCarloHistUIAxes, 'Parameter-Metric Correlation Matrix');
                
                % ONLY for Correlation Matrix: Update HTML with comprehensive table
                app.MonteCarloHTML.HTMLSource = htmlComprehensive;
        end
        
        % Plot all valid response curves
        validRows = find(~any(isnan(responsesMatrix), 2));
        
        if ~isempty(validRows)
            % Hold for multiple plots
            hold(app.MonteCarloResponseUIAxes, 'on');
            
            % Create a colormap with enough colors for all valid simulations
            numValidSimulations = length(validRows);
            colorMap = jet(numValidSimulations);
            
            % Optimized rendering approach for large numbers of plots
            % For efficiency with large datasets, we handle plotting differently
            if numValidSimulations > 100
                % Split the data into smaller batches and plot multiple lines at once
                % This is much more efficient than individual plot calls
                BATCH_SIZE = 20;  % Process in batches of 20 for better performance
                numBatches = ceil(numValidSimulations / BATCH_SIZE);
                
                for batch = 1:numBatches
                    startIdx = (batch-1)*BATCH_SIZE + 1;
                    endIdx = min(batch*BATCH_SIZE, numValidSimulations);
                    batchIndices = startIdx:endIdx;
                    
                    if endIdx >= startIdx
                        % Extract batch data
                        batchMatrix = zeros(length(t), length(batchIndices));
                        for i = 1:length(batchIndices)
                            rowIdx = validRows(batchIndices(i));
                            batchMatrix(:, i) = responsesMatrix(rowIdx, :)';
                        end
                        
                        % Plot the batch
                        for i = 1:length(batchIndices)
                            plot(app.MonteCarloResponseUIAxes, t, batchMatrix(:, i), ...
                                'LineWidth', 0.5, 'Color', colorMap(batchIndices(i), :));
                        end
                        drawnow;  % Update display after each batch
                    end
                end
            else
                % For smaller datasets, plot each line individually
                for i = 1:numValidSimulations
                    plot(app.MonteCarloResponseUIAxes, t, responsesMatrix(validRows(i), :), ...
                        'LineWidth', 1, 'Color', colorMap(i, :));
                end
            end
            
            % Display the nominal response with a thicker black line if available
            try
                nominalSystem = feedback(app.G * app.K, 1);
                [nominalResponse, ~] = step(nominalSystem, t);
                plot(app.MonteCarloResponseUIAxes, t, nominalResponse, 'b-', 'LineWidth', 2, 'DisplayName', 'Nominal');
                legend(app.MonteCarloResponseUIAxes, 'Nominal Response');
            catch
                % Skip nominal response if it can't be calculated
            end
            
            % Add a grid and improve the visual appearance
            grid(app.MonteCarloResponseUIAxes, 'on');
            title(app.MonteCarloResponseUIAxes, sprintf('System Responses (All %d Valid Simulations)', numValidSimulations));
            xlabel(app.MonteCarloResponseUIAxes, 'Time (s)');
            ylabel(app.MonteCarloResponseUIAxes, 'Output');
            
            % Add a border around the plot to improve visibility
            box(app.MonteCarloResponseUIAxes, 'on');
            
            hold(app.MonteCarloResponseUIAxes, 'off');
        else
            % No valid responses to plot
            text(app.MonteCarloResponseUIAxes, 0.5, 0.5, 'No valid responses to display', ...
                'HorizontalAlignment', 'center', 'Units', 'normalized');
        end
        
    catch ME
        % Handle any general errors
        if exist('waitbarHandle', 'var') && ishandle(waitbarHandle)
            close(waitbarHandle);
        end
        
        app.MonteCarloHTML.HTMLSource = createHtmlContent('Error in Monte Carlo simulation:', ...
            ME.message, '', ...
            'Tips for troubleshooting:', ...
            '- Check parameter ranges', ...
            '- Ensure the controller structure is correctly identified', ...
            '- Try fewer simulations to start');
        
        % Create empty plots
        cla(app.MonteCarloHistUIAxes);
        cla(app.MonteCarloResponseUIAxes);
        
        text(app.MonteCarloHistUIAxes, 0.5, 0.5, {'Error in Monte Carlo simulation', ME.message}, ...
            'HorizontalAlignment', 'center', 'Units', 'normalized');
    end
end
        
        
function displaySavedTimeFunction(app, latex_string, label)
    % DISPLAYSAVEDTIMEFUNCTION
    % ----------------------
    % This function displays previously saved LaTeX string representations
    % of time functions when loading the app state.
    %
    % Inputs:
    %   app          - The app object containing UI components
    %   latex_string - The LaTeX string representation of the function
    %   label        - Function label (e.g., 'r', 'd_1', etc.)
    
    try
        % Get the target UIAxes component
        uiAxesName = [label, '_UIAxes'];
        uiAxesHandle = app.(uiAxesName);
        
        % Clear the axes and turn off axis lines
        cla(uiAxesHandle);
        axis(uiAxesHandle, 'off');
        
        % Display the saved LaTeX formula
        text(uiAxesHandle, 0.5, 0.5, latex_string, ...
            'Interpreter', 'latex', 'FontSize', 13, ...
            'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle');
        
        % Store the LaTeX string in the appropriate app property
        latex_prop_name = '';
        switch label
            case 'r'
                app.r_latex_string = latex_string;
            case 'd_1'
                app.d1_latex_string = latex_string;
            case 'd_2'
                app.d2_latex_string = latex_string;
        end
    catch ME
        % Handle errors gracefully
        disp(['Error displaying saved time function: ' ME.message]);
        disp(['Error details: ', getReport(ME, 'basic')]);
        
        % Get the target UIAxes
        uiAxesName = [label, '_UIAxes'];
        uiAxesHandle = app.(uiAxesName);
        cla(uiAxesHandle);
        
        % Display error message
        text(uiAxesHandle, 0.5, 0.5, ['Error displaying ' label '(t)'], ...
            'FontSize', 12, 'HorizontalAlignment', 'center', 'Color', 'red');
    end


end
        
function updateAnalysisDisplay(app, analysisType)
    % Updates analysis display based on selected analysis type
    
    % Update UI elements based on analysis type
    switch analysisType
        case 'Eigenvalues'
            app.AnalysisParamInput.Value = '';
            app.AnalysisParamInput.Enable = 'off';
            
        case {'Step Response', 'Impulse Response'}
            app.AnalysisParamInput.Value = 't=0:0.1:10';
            app.AnalysisParamInput.Enable = 'on';
            
        case 'Phase Portrait'
            app.AnalysisParamInput.Value = '';
            app.AnalysisParamInput.Enable = 'off';
            
        case {'Controllability', 'Observability'}
            app.AnalysisParamInput.Value = '';
            app.AnalysisParamInput.Enable = 'off';
    end
end
        
function [tf_sys, success, errorMsg] = convertSS2TF(app, ss_model, inputIdx, outputIdx)
    % CONVERTSS2TF - Converts state-space model to transfer function with robust error handling
    %
    % This enhanced function converts a state-space model to a transfer function
    % with improved error handling, input validation, and system analysis.
    %
    % Inputs:
    %   app      - App object with UI components for feedback
    %   ss_model - State-space model to convert
    %   inputIdx - Input index for MIMO systems (default: 1)
    %   outputIdx - Output index for MIMO systems (default: 1)
    %
    % Outputs:
    %   tf_sys   - Resulting transfer function (empty if conversion fails)
    %   success  - Boolean flag indicating success (1) or failure (0)
    %   errorMsg - Error message if conversion fails
    
    success = 0;
    errorMsg = '';
    tf_sys = [];
    
    try
        % Validate inputs
        if nargin < 2 || isempty(ss_model)
            errorMsg = 'No state-space model provided.';
            return;
        end
        
        if ~isa(ss_model, 'ss')
            errorMsg = 'Input must be a state-space model of type "ss".';
            return;
        end
        
        % Get state-space matrices
        A = ss_model.A;
        B = ss_model.B;
        C = ss_model.C;
        D = ss_model.D;
        Ts = ss_model.Ts;
        
        % Get dimensions
        [~, n_inputs] = size(B);
        [n_outputs, ~] = size(C);
        
        % Validate input and output indices
        if nargin < 3 || isempty(inputIdx)
            inputIdx = 1;
        end
        
        if nargin < 4 || isempty(outputIdx)
            outputIdx = 1;
        end
        
        if inputIdx > n_inputs
            errorMsg = sprintf('Input index %d exceeds the number of inputs (%d).', inputIdx, n_inputs);
            return;
        end
        
        if outputIdx > n_outputs
            errorMsg = sprintf('Output index %d exceeds the number of outputs (%d).', outputIdx, n_outputs);
            return;
        end
        
        % Extract relevant vectors for SISO conversion
        b_vec = B(:, inputIdx);
        c_vec = C(outputIdx, :);
        d_val = D(outputIdx, inputIdx);
        
        % Check for potential numerical issues
        if any(isnan(A(:))) || any(isinf(A(:)))
            errorMsg = 'A matrix contains NaN or Inf values.';
            return;
        end
        
        if any(isnan(b_vec)) || any(isinf(b_vec))
            errorMsg = 'B vector contains NaN or Inf values.';
            return;
        end
        
        if any(isnan(c_vec)) || any(isinf(c_vec))
            errorMsg = 'C vector contains NaN or Inf values.';
            return;
        end
        
        if isnan(d_val) || isinf(d_val)
            errorMsg = 'D value is NaN or Inf.';
            return;
        end
        
        % Analyze system properties (for information only)
        try
            % Check controllability
            Co = ctrb(A, b_vec);
            rankCo = rank(Co);
            
            % Check observability
            Ob = obsv(A, c_vec);
            rankOb = rank(Ob);
            
            n = size(A, 1);
            
            % Show warnings for potentially problematic models
            if rankCo < n
                disp(['Warning: System is not fully controllable for selected input. Rank = ' num2str(rankCo) ' of ' num2str(n)]);
            end
            
            if rankOb < n
                disp(['Warning: System is not fully observable for selected output. Rank = ' num2str(rankOb) ' of ' num2str(n)]);
            end
            
            % Calculate stability (for information only)
            eigs = eig(A);
            if any(real(eigs) > 0)
                disp('Warning: System is unstable, which may affect transfer function conversion.');
            end
        catch ME
            % Don't fail conversion if this analysis fails
            disp(['Note: Could not analyze system properties: ' ME.message]);
        end
        
        % Try direct conversion first with ss2tf
        try
            [num, den] = ss2tf(A, b_vec, c_vec, d_val);
            
            % Check for potential numerical issues in the result
            if any(isnan(num)) || any(isinf(num)) || any(isnan(den)) || any(isinf(den))
                errorMsg = 'Conversion resulted in NaN or Inf values in the transfer function.';
                return;
            end
            
            % Create transfer function
            tf_sys = tf(num, den);
            
            % Clean up very small coefficients that may be computational noise
            % This can improve conversion quality for higher order systems
            num_clean = cleanup_tf_coefficients(num);
            den_clean = cleanup_tf_coefficients(den);
            tf_sys = tf(num_clean, den_clean);
            
            % Preserve sampling time if discrete
            if Ts > 0
                tf_sys.Ts = Ts;
            end
        catch ME
            % If direct conversion fails, try alternative method via zpk
            try
                disp('Direct conversion failed, trying via zero-pole-gain...');
                sys_zpk = ss2zpk(ss_model);
                
                % Extract single I/O channel
                if n_inputs > 1 || n_outputs > 1
                    sys_zpk_io = zpk(sys_zpk.z{outputIdx, inputIdx}, ...
                                    sys_zpk.p{outputIdx, inputIdx}, ...
                                    sys_zpk.k(outputIdx, inputIdx));
                else
                    sys_zpk_io = sys_zpk;
                end
                
                % Convert to transfer function
                tf_sys = tf(sys_zpk_io);
                
                % Preserve sampling time if discrete
                if Ts > 0
                    tf_sys.Ts = Ts;
                end
            catch ME2
                % Both methods failed
                errorMsg = ['Error converting state-space to transfer function: ' ME.message ...
                           '. Alternative method also failed: ' ME2.message];
                return;
            end
        end
        
        % Check if transfer function is valid
        if ~isa(tf_sys, 'tf')
            errorMsg = 'Failed to create a valid transfer function.';
            return;
        end
        
        % Display information about the conversion
        disp(['Successfully converted state-space to transfer function.']);
        if Ts > 0
            disp(['Created discrete-time transfer function with Ts = ' num2str(Ts) '.']);
        else
            disp('Created continuous-time transfer function.');
        end
        
        [num_final, den_final] = tfdata(tf_sys, 'v');
        disp(['Transfer function order: numerator = ' num2str(length(num_final)-1) ...
              ', denominator = ' num2str(length(den_final)-1) '.']);
        
        success = 1;
        
    catch ME
        % Detailed error handling with stack trace
        errorMsg = ['Error converting state-space to transfer function: ' ME.message];
        disp(errorMsg);
        disp(getReport(ME, 'extended'));
    end
end


function updateStateSpaceInfo(app, model, isNonlinear)
    % UPDATESTATESPACEINFO - Updates the system information display with enhanced details
    %
    % This function creates a rich HTML display of state-space model properties
    % including stability, controllability, observability, and eigenvalues.
    %
    % Inputs:
    %   app         - App object with UI components
    %   model       - State-space model to analyze
    %   isNonlinear - Flag indicating if the model was derived from a nonlinear system
    
    if nargin < 3
        isNonlinear = false;
    end
    
    try
        % Get model properties
        A = model.A;
        B = model.B;
        C = model.C;
        D = model.D;
        Ts = model.Ts;
        
        % Calculate system dimensions
        [n, ~] = size(A);  % Number of states
        [~, m] = size(B);  % Number of inputs
        [p, ~] = size(C);  % Number of outputs
        
        % Calculate eigenvalues with error handling
        try
            eigs = eig(A);
        catch ME
            eigs = []; % Set empty if calculation fails
            % Fixed: Avoid using format specifiers in warning
            warning(['Could not compute eigenvalues: ' ME.message]);
        end
        
        % Determine stability
        if isempty(eigs)
            isStable = false;
            stabilityMsg = 'Could not be determined';
            stabilityClass = 'warning';
        else
            if Ts > 0
                % Discrete-time stability (eigenvalues inside unit circle)
                isStable = all(abs(eigs) < 1);
            else
                % Continuous-time stability (eigenvalues in left half-plane)
                isStable = all(real(eigs) < 0);
            end
            
            if isStable
                stabilityMsg = 'Stable';
                stabilityClass = 'stable';
            else
                stabilityMsg = 'Unstable';
                stabilityClass = 'unstable';
            end
        end
        
        % Calculate controllability and observability with error handling
        try
            Co = ctrb(A, B);
            rankCo = rank(Co);
            isControllable = (rankCo == n);
            % Fixed: Use string concatenation instead of sprintf
            if isControllable
                ctrbMsg = ['Controllable (rank: ' num2str(rankCo) '/' num2str(n) ')'];
            else
                ctrbMsg = ['Not Controllable (rank: ' num2str(rankCo) '/' num2str(n) ')'];
            end
        catch ME
            isControllable = false;
            % Fixed: Avoid format specifiers
            ctrbMsg = ['Error: ' ME.message];
            rankCo = 0;
        end
        
        try
            Ob = obsv(A, C);
            rankOb = rank(Ob);
            isObservable = (rankOb == n);
            % Fixed: Use string concatenation instead of sprintf
            if isObservable
                obsvMsg = ['Observable (rank: ' num2str(rankOb) '/' num2str(n) ')'];
            else
                obsvMsg = ['Not Observable (rank: ' num2str(rankOb) '/' num2str(n) ')'];
            end
        catch ME
            isObservable = false;
            % Fixed: Avoid format specifiers
            obsvMsg = ['Error: ' ME.message];
            rankOb = 0;
        end
        
        % Determine system type (continuous/discrete)
        if Ts > 0
            systemType = ['Discrete-time (Ts = ' num2str(Ts, '%.4g') ' s)'];
        else
            systemType = 'Continuous-time';
        end
        
        % Create enhanced HTML table with improved styling
        html = ['<html><head><style>', ...
                'body { font-family: Arial, sans-serif; font-size: 12px; }', ...
                'table { border-collapse: collapse; width: 100%; margin-bottom: 15px; }', ...
                'th { background-color: #4472C4; color: white; padding: 8px; text-align: center; font-size: 14px; }', ...
                'td { border: 1px solid #DDDDDD; padding: 8px; font-size: 13px; }', ...
                'td:first-child { width: 40%; font-weight: bold; }', ...
                'tr:nth-child(even) { background-color: #F2F2F2; }', ...
                'tr:hover { background-color: #E8F4F8; }', ...
                '.stable { color: #2ECC71; font-weight: bold; }', ...
                '.unstable { color: #E74C3C; font-weight: bold; }', ...
                '.warning { color: #F39C12; font-weight: bold; }', ...
                '.yes { color: #2ECC71; font-weight: bold; }', ...
                '.no { color: #E74C3C; font-weight: bold; }', ...
                '.eigenvalue-container { max-height: 120px; overflow-y: auto; margin-top: 5px; }', ...
                '.system-title { text-align: center; font-size: 16px; margin: 5px 0; color: #2980B9; font-weight: bold; }', ...
                '</style></head><body>'];
        
        % Title and system type
        html = [html, '<div class="system-title">'];
        if isNonlinear
            html = [html, 'Nonlinear System (Linearized)'];
        else
            html = [html, 'Linear State-Space System'];
        end
        html = [html, '</div>'];
        
        % System type and dimensions table
        html = [html, '<table>'];
        html = [html, '<tr><th colspan="2">System Overview</th></tr>'];
        html = [html, '<tr><td>System Type</td><td>', systemType, '</td></tr>'];
        html = [html, '<tr><td>Dimensions</td><td>', ...
                num2str(n), ' states, ', ...
                num2str(m), ' inputs, ', ...
                num2str(p), ' outputs', ...
                '</td></tr>'];
        
        % Stability information
        html = [html, '<tr><td>Stability</td><td class="', stabilityClass, '">', stabilityMsg, '</td></tr>'];
        
        % Controllability
        if isControllable
            html = [html, '<tr><td>Controllability</td><td class="yes">', ctrbMsg, '</td></tr>'];
        else
            html = [html, '<tr><td>Controllability</td><td class="no">', ctrbMsg, '</td></tr>'];
        end
        
        % Observability
        if isObservable
            html = [html, '<tr><td>Observability</td><td class="yes">', obsvMsg, '</td></tr>'];
        else
            html = [html, '<tr><td>Observability</td><td class="no">', obsvMsg, '</td></tr>'];
        end
        
        % If system is not controllable or observable, show more info
        if ~isControllable && rankCo > 0
            html = [html, '<tr><td>Uncontrollable Modes</td><td>', num2str(n-rankCo), '</td></tr>'];
        end
        
        if ~isObservable && rankOb > 0
            html = [html, '<tr><td>Unobservable Modes</td><td>', num2str(n-rankOb), '</td></tr>'];
        end
        
        html = [html, '</table>'];
        
        % Eigenvalues table
        html = [html, '<table>'];
        html = [html, '<tr><th colspan="2">Eigenvalues</th></tr>'];
        html = [html, '<tr><td colspan="2"><div class="eigenvalue-container">'];
        
        if ~isempty(eigs)
            for i = 1:length(eigs)
                % Format eigenvalue nicely
                if imag(eigs(i)) == 0
                    % Fixed: Use string concatenation for HTML content with numbers
                    eigStr = ['λ<sub>' num2str(i) '</sub> = ' num2str(real(eigs(i)), '%.4f')];
                    
                    % Mark stable/unstable
                    if (Ts > 0 && abs(eigs(i)) >= 1) || (Ts == 0 && real(eigs(i)) >= 0)
                        eigStr = ['<span class="unstable">', eigStr, '</span>'];
                    end
                else
                    if real(eigs(i)) == 0
                        % Fixed: Use string concatenation for HTML content with numbers
                        eigStr = ['λ<sub>' num2str(i) '</sub> = ' num2str(imag(eigs(i)), '%.4f') 'i'];
                    elseif imag(eigs(i)) > 0
                        % Fixed: Use string concatenation for HTML content with numbers
                        eigStr = ['λ<sub>' num2str(i) '</sub> = ' num2str(real(eigs(i)), '%.4f') ' + ' num2str(imag(eigs(i)), '%.4f') 'i'];
                    else
                        % Fixed: Use string concatenation for HTML content with numbers
                        eigStr = ['λ<sub>' num2str(i) '</sub> = ' num2str(real(eigs(i)), '%.4f') ' - ' num2str(abs(imag(eigs(i))), '%.4f') 'i'];
                    end
                    
                    % Mark stable/unstable (only for continuous-time systems)
                    if (Ts > 0 && abs(eigs(i)) >= 1) || (Ts == 0 && real(eigs(i)) >= 0)
                        eigStr = ['<span class="unstable">', eigStr, '</span>'];
                    end
                end
                
                html = [html, eigStr, '<br>'];
            end
        else
            html = [html, '<span class="warning">Could not compute eigenvalues</span>'];
        end
        
        html = [html, '</div></td></tr></table>'];
        
        % Close the HTML
        html = [html, '</body></html>'];
        
        % Update the HTML component
        app.StateSpaceInfoHTML.HTMLSource = html;
        
    catch ME
        % Enhanced error handling with detailed error message
        disp(['Error updating state-space information: ' ME.message]);
        disp(getReport(ME, 'extended'));
        
        try
            % Create a user-friendly error message
            errorHtml = ['<html><div style="color: red; text-align: center; margin-top: 20px; font-weight: bold;">', ...
                        'Error updating system information:<br>', ...
                        ME.message, ...
                        '</div></html>'];
            app.StateSpaceInfoHTML.HTMLSource = errorHtml;
        catch
            % If even creating the error message fails, don't crash
            disp('Could not update HTML component with error message.');
        end
    end
end
        
function [A, B, C, D] = linearizeSystem(app, state_vars, input_vars, state_eqs, output_eqs, linearization_point)
    % LINEARIZESYSTEM - Linearizes a nonlinear system around an operating point
    %
    % This enhanced function computes the linearized state-space matrices (A, B, C, D) 
    % for a nonlinear system, with improved error handling, progress reporting and 
    % matrix calculation.
    %
    % Inputs:
    %   app                 - App object with UI components for feedback
    %   state_vars          - Cell array of state variable names
    %   input_vars          - Cell array of input variable names
    %   state_eqs           - Cell array of state equations (dx/dt expressions)
    %   output_eqs          - Cell array of output equations
    %   linearization_point - Container with variable values at linearization point
    %
    % Outputs:
    %   A, B, C, D - Linearized state-space matrices
    
    try
        % Check if Symbolic Math Toolbox is available
        if ~license('test', 'Symbolic_Toolbox')
            error('Symbolic Math Toolbox is required for linearization but is not available.');
        end
        
        % Validate inputs
        if isempty(state_vars) || isempty(input_vars) || isempty(state_eqs) || isempty(output_eqs)
            error('All parameters (state variables, input variables, state equations, output equations) must be provided.');
        end
        
        n = length(state_vars);  % Number of states
        m = length(input_vars);  % Number of inputs
        p = length(output_eqs);  % Number of outputs
        
        % Check dimensions match
        if length(state_eqs) ~= n
            error('Number of state equations (%d) must match number of state variables (%d).', length(state_eqs), n);
        end
        
        % Initialize matrices
        A = zeros(n, n);
        B = zeros(n, m);
        C = zeros(p, n);
        D = zeros(p, m);
        
        % Update status
        statusMsg = 'Creating symbolic variables...';
        disp(statusMsg);
        try
            % Create status display in UI
            if ~isempty(app) && isfield(app, 'StateSpaceInfoHTML')
                currentHTML = app.StateSpaceInfoHTML.HTMLSource;
                statusHTML = ['<html><div style="color: blue; text-align: center; margin-top: 10px;">', ...
                              statusMsg, '</div></html>'];
                app.StateSpaceInfoHTML.HTMLSource = [currentHTML, statusHTML];
                drawnow;
            end
        catch
            % Continue without UI update if it fails
        end
        
        % Ensure all variables are valid
        for i = 1:length(state_vars)
            if ~isvarname(state_vars{i})
                error('Invalid state variable name: %s', state_vars{i});
            end
        end
        
        for i = 1:length(input_vars)
            if ~isvarname(input_vars{i})
                error('Invalid input variable name: %s', input_vars{i});
            end
        end
        
        % Create symbolic variables
        try
            % Convert cell arrays to strings for syms function
            state_vars_str = strjoin(state_vars, ' ');
            input_vars_str = strjoin(input_vars, ' ');
            
            % Create symbolic variables
            eval(['syms ', state_vars_str, ' ', input_vars_str, ' real']);
            
            % Store symbolic variables in arrays for easier access
            sym_state_vars = sym(state_vars);
            sym_input_vars = sym(input_vars);
        catch ME
            error('Error creating symbolic variables: %s\nDetail: %s', ME.message, getReport(ME, 'basic'));
        end
        
        % Convert state equations to symbolic expressions
        disp('Converting state equations to symbolic form...');
        sym_state_eqs = cell(size(state_eqs));
        
        for i = 1:length(state_eqs)
            try
                if ischar(state_eqs{i})
                    % If it's a string, convert to symbolic expression
                    sym_state_eqs{i} = sym(state_eqs{i});
                elseif isa(state_eqs{i}, 'sym')
                    % If it's already symbolic, use as is
                    sym_state_eqs{i} = state_eqs{i};
                else
                    error('State equation must be a string or symbolic expression');
                end
            catch ME
                error('Error in state equation %d (%s): %s', i, state_eqs{i}, ME.message);
            end
        end
        
        % Convert output equations to symbolic expressions
        disp('Converting output equations to symbolic form...');
        sym_output_eqs = cell(size(output_eqs));
        
        for i = 1:length(output_eqs)
            try
                if ischar(output_eqs{i})
                    % If it's a string, convert to symbolic expression
                    sym_output_eqs{i} = sym(output_eqs{i});
                elseif isa(output_eqs{i}, 'sym')
                    % If it's already symbolic, use as is
                    sym_output_eqs{i} = output_eqs{i};
                else
                    error('Output equation must be a string or symbolic expression');
                end
            catch ME
                error('Error in output equation %d (%s): %s', i, output_eqs{i}, ME.message);
            end
        end
        
        % Verify linearization point contains all required variables
        missing_vars = {};
        for i = 1:length(state_vars)
            if ~isKey(linearization_point, state_vars{i})
                missing_vars{end+1} = state_vars{i};
            end
        end
        
        for i = 1:length(input_vars)
            if ~isKey(linearization_point, input_vars{i})
                missing_vars{end+1} = input_vars{i};
            end
        end
        
        if ~isempty(missing_vars)
            error('Linearization point missing values for: %s', strjoin(missing_vars, ', '));
        end
        
        % ----------------------------------------------------------------------
        % Calculate A matrix (df/dx) - Jacobian of state equations w.r.t. states
        % ----------------------------------------------------------------------
        disp('Computing A matrix (df/dx)...');
        
        for i = 1:n
            for j = 1:n
                try
                    % Calculate partial derivative
                    Aij = diff(sym_state_eqs{i}, sym(state_vars{j}));
                    
                    % Substitute linearization point values
                    for k = 1:n
                        Aij = subs(Aij, sym(state_vars{k}), linearization_point(state_vars{k}));
                    end
                    
                    for k = 1:m
                        Aij = subs(Aij, sym(input_vars{k}), linearization_point(input_vars{k}));
                    end
                    
                    % Convert to double
                    A(i, j) = double(Aij);
                    
                    % Check for NaN or Inf
                    if isnan(A(i, j)) || isinf(A(i, j))
                        warning('A(%d,%d) evaluated to %s. Using 0 instead.', i, j, char(Aij));
                        A(i, j) = 0;
                    end
                catch ME
                    warning('Error computing A(%d,%d): %s. Using 0 instead.', i, j, ME.message);
                    A(i, j) = 0;
                end
            end
        end
        
        % ----------------------------------------------------------------------
        % Calculate B matrix (df/du) - Jacobian of state equations w.r.t. inputs
        % ----------------------------------------------------------------------
        disp('Computing B matrix (df/du)...');
        
        for i = 1:n
            for j = 1:m
                try
                    % Calculate partial derivative
                    Bij = diff(sym_state_eqs{i}, sym(input_vars{j}));
                    
                    % Substitute linearization point values
                    for k = 1:n
                        Bij = subs(Bij, sym(state_vars{k}), linearization_point(state_vars{k}));
                    end
                    
                    for k = 1:m
                        Bij = subs(Bij, sym(input_vars{k}), linearization_point(input_vars{k}));
                    end
                    
                    % Convert to double
                    B(i, j) = double(Bij);
                    
                    % Check for NaN or Inf
                    if isnan(B(i, j)) || isinf(B(i, j))
                        warning('B(%d,%d) evaluated to %s. Using 0 instead.', i, j, char(Bij));
                        B(i, j) = 0;
                    end
                catch ME
                    warning('Error computing B(%d,%d): %s. Using 0 instead.', i, j, ME.message);
                    B(i, j) = 0;
                end
            end
        end
        
        % ----------------------------------------------------------------------
        % Calculate C matrix (dg/dx) - Jacobian of output equations w.r.t. states
        % ----------------------------------------------------------------------
        disp('Computing C matrix (dg/dx)...');
        
        for i = 1:p
            for j = 1:n
                try
                    % Calculate partial derivative
                    Cij = diff(sym_output_eqs{i}, sym(state_vars{j}));
                    
                    % Substitute linearization point values
                    for k = 1:n
                        Cij = subs(Cij, sym(state_vars{k}), linearization_point(state_vars{k}));
                    end
                    
                    for k = 1:m
                        Cij = subs(Cij, sym(input_vars{k}), linearization_point(input_vars{k}));
                    end
                    
                    % Convert to double
                    C(i, j) = double(Cij);
                    
                    % Check for NaN or Inf
                    if isnan(C(i, j)) || isinf(C(i, j))
                        warning('C(%d,%d) evaluated to %s. Using 0 instead.', i, j, char(Cij));
                        C(i, j) = 0;
                    end
                catch ME
                    warning('Error computing C(%d,%d): %s. Using 0 instead.', i, j, ME.message);
                    C(i, j) = 0;
                end
            end
        end
        
        % ----------------------------------------------------------------------
        % Calculate D matrix (dg/du) - Jacobian of output equations w.r.t. inputs
        % ----------------------------------------------------------------------
        disp('Computing D matrix (dg/du)...');
        
        for i = 1:p
            for j = 1:m
                try
                    % Calculate partial derivative
                    Dij = diff(sym_output_eqs{i}, sym(input_vars{j}));
                    
                    % Substitute linearization point values
                    for k = 1:n
                        Dij = subs(Dij, sym(state_vars{k}), linearization_point(state_vars{k}));
                    end
                    
                    for k = 1:m
                        Dij = subs(Dij, sym(input_vars{k}), linearization_point(input_vars{k}));
                    end
                    
                    % Convert to double
                    D(i, j) = double(Dij);
                    
                    % Check for NaN or Inf
                    if isnan(D(i, j)) || isinf(D(i, j))
                        warning('D(%d,%d) evaluated to %s. Using 0 instead.', i, j, char(Dij));
                        D(i, j) = 0;
                    end
                catch ME
                    warning('Error computing D(%d,%d): %s. Using 0 instead.', i, j, ME.message);
                    D(i, j) = 0;
                end
            end
        end
        
        % Update status
        disp('Linearization completed successfully.');
        
    catch ME
        % Enhanced error handling with context details
        errorMsg = sprintf('Linearization error: %s\n\nContext: %s', ...
                           ME.message, getReport(ME, 'basic'));
        disp(errorMsg);
        
        % Re-throw with more context
        error('Linearization failed: %s', ME.message);
    end
end


function performEigenvalueAnalysis(app)
    % PERFORMEIGENVALUEANALYSIS - Performs and visualizes eigenvalue analysis
    %
    % This enhanced function calculates eigenvalues, determines stability,
    % and creates a professional visualization of the results
    
    try
        % Get the linearized model with validation
        if ~validateStateSpaceModel(app)
            % Error message already displayed by validation function
            return;
        end
        
        model = app.LinearizedModel;
        A = model.A;
        Ts = model.Ts;
        
        % Calculate eigenvalues
        eigs = eig(A);
        
        % Clear axes and prepare for plotting
        cla(app.StateSpaceAxes);
        hold(app.StateSpaceAxes, 'on');
        
        % Determine system type
        isDiscrete = (Ts > 0);
        
        % Define the plotting area
        max_abs_real = max(abs(real(eigs))) * 1.5;
        max_abs_imag = max(abs(imag(eigs))) * 1.5;
        
        % Ensure minimum axis limits for visibility
        max_abs_real = max(max_abs_real, 1);
        max_abs_imag = max(max_abs_imag, 1);
        
        % For discrete systems, ensure the unit circle is fully visible
        if isDiscrete
            max_radius = max(1.2, max(abs(eigs)) * 1.2);
            max_abs_real = max(max_abs_real, max_radius);
            max_abs_imag = max(max_abs_imag, max_radius);
        end
        
        % Improved visualization with background colors for stable/unstable regions
        if isDiscrete
            % For discrete systems, stability is inside the unit circle
            
            % Create filled red region outside unit circle (unstable)
            patch_outer = [max_abs_real, max_abs_real, -max_abs_real, -max_abs_real];
            patch_outer_y = [max_abs_imag, -max_abs_imag, -max_abs_imag, max_abs_imag];
            h_unstable = fill(app.StateSpaceAxes, patch_outer, patch_outer_y, 'r', 'FaceAlpha', 0.1, 'EdgeColor', 'none');
            
            % Draw unit circle (stability boundary)
            theta = linspace(0, 2*pi, 200);
            x_circle = cos(theta);
            y_circle = sin(theta);
            
            % Create filled green region inside unit circle (stable)
            h_stable = fill(app.StateSpaceAxes, x_circle, y_circle, 'g', 'FaceAlpha', 0.1, 'EdgeColor', 'none');
            
            % Draw the unit circle boundary
            h_boundary = plot(app.StateSpaceAxes, x_circle, y_circle, 'k-', 'LineWidth', 2);
            
            % Set correct drawing order
            uistack(h_stable, 'bottom');
            uistack(h_unstable, 'bottom');
            
        else
            % For continuous systems, stability is in the left half-plane
            
            % Create filled red region in right half-plane (unstable)
            patch_right = [0, max_abs_real, max_abs_real, 0];
            patch_right_y = [-max_abs_imag, -max_abs_imag, max_abs_imag, max_abs_imag];
            h_unstable = fill(app.StateSpaceAxes, patch_right, patch_right_y, 'r', 'FaceAlpha', 0.1, 'EdgeColor', 'none');
            
            % Create filled green region in left half-plane (stable)
            patch_left = [0, -max_abs_real, -max_abs_real, 0];
            patch_left_y = [-max_abs_imag, -max_abs_imag, max_abs_imag, max_abs_imag];
            h_stable = fill(app.StateSpaceAxes, patch_left, patch_left_y, 'g', 'FaceAlpha', 0.1, 'EdgeColor', 'none');
            
            % Draw the imaginary axis (stability boundary)
            h_boundary = plot(app.StateSpaceAxes, [0, 0], [-max_abs_imag, max_abs_imag], 'k-', 'LineWidth', 2);
            
            % Set correct drawing order
            uistack(h_stable, 'bottom');
            uistack(h_unstable, 'bottom');
        end
        
        % Add main coordinate axes
        plot(app.StateSpaceAxes, [-max_abs_real, max_abs_real], [0, 0], 'k-', 'LineWidth', 1.5);
        plot(app.StateSpaceAxes, [0, 0], [-max_abs_imag, max_abs_imag], 'k-', 'LineWidth', 1.5);
        
        % Add region labels - improved positioning that doesn't overlap with eigenvalues
        if isDiscrete
            text(app.StateSpaceAxes, 0, 0.9*max_abs_imag, 'Stable Region', 'HorizontalAlignment', 'center', ...
                'Color', [0 0.5 0], 'FontWeight', 'bold', 'FontSize', 12, 'BackgroundColor', [1 1 1 0.7]);
            text(app.StateSpaceAxes, 0, -0.9*max_abs_imag, 'Unstable Region', 'HorizontalAlignment', 'center', ...
                'Color', [0.6 0 0], 'FontWeight', 'bold', 'FontSize', 12, 'BackgroundColor', [1 1 1 0.7]);
        else
            text(app.StateSpaceAxes, -0.7*max_abs_real, 0.9*max_abs_imag, 'Stable Region', 'HorizontalAlignment', 'center', ...
                'Color', [0 0.5 0], 'FontWeight', 'bold', 'FontSize', 12, 'BackgroundColor', [1 1 1 0.7]);
            text(app.StateSpaceAxes, 0.7*max_abs_real, 0.9*max_abs_imag, 'Unstable Region', 'HorizontalAlignment', 'center', ...
                'Color', [0.6 0 0], 'FontWeight', 'bold', 'FontSize', 12, 'BackgroundColor', [1 1 1 0.7]);
        end
        
        % Plot eigenvalues with better visualization
        for i = 1:length(eigs)
            if isDiscrete
                isStableEig = abs(eigs(i)) < 1;
            else
                isStableEig = real(eigs(i)) < 0;
            end
            
            % Choose colors based on stability
            if isStableEig
                markerColor = [0 0.6 0]; % Stable: green
                fillColor = [0.3 0.9 0.3];
            else
                markerColor = [0.8 0 0]; % Unstable: red
                fillColor = [1 0.3 0.3];
            end
            
            % Plot with distinguishable markers
            scatter(app.StateSpaceAxes, real(eigs(i)), imag(eigs(i)), 100, 'x', 'LineWidth', 2, ...
                'MarkerEdgeColor', markerColor);
            
            % Add a circle around the eigenvalue for better visibility
            scatter(app.StateSpaceAxes, real(eigs(i)), imag(eigs(i)), 70, 'o', 'LineWidth', 1.5, ...
                'MarkerEdgeColor', markerColor, 'MarkerFaceColor', fillColor);
            
            % Format eigenvalue label based on its form
            if imag(eigs(i)) == 0
                eigStr = num2str(real(eigs(i)), '%.3f');
            elseif real(eigs(i)) == 0
                eigStr = [num2str(imag(eigs(i)), '%.3f'), 'i'];
            elseif imag(eigs(i)) > 0
                eigStr = [num2str(real(eigs(i)), '%.3f'), '+', num2str(imag(eigs(i)), '%.3f'), 'i'];
            else
                eigStr = [num2str(real(eigs(i)), '%.3f'), num2str(imag(eigs(i)), '%.3f'), 'i'];
            end
            
            % Add label with lambda number and value
            label = ['λ', num2str(i), ' = ', eigStr];
            
            % Position label based on eigenvalue location
            label_offset_x = 0.04 * max_abs_real * sign(real(eigs(i)));
            if label_offset_x == 0
                label_offset_x = 0.04 * max_abs_real; % Default offset if on imaginary axis
            end
            
            label_offset_y = 0.04 * max_abs_imag;
            
            % Add text with background for readability
            text(app.StateSpaceAxes, real(eigs(i)) + label_offset_x, imag(eigs(i)) + label_offset_y, ...
                label, 'HorizontalAlignment', 'left', 'VerticalAlignment', 'bottom', ...
                'BackgroundColor', [1 1 1 0.7], 'EdgeColor', [0.7 0.7 0.7], 'Margin', 3);
        end
        
        % Determine overall stability
        if isDiscrete
            isStable = all(abs(eigs) < 1);
        else
            isStable = all(real(eigs) < 0);
        end
        
        % Add stability annotation
        if isStable
            stabilityText = 'System is STABLE';
            stabilityColor = [0 0.6 0]; % Green
        else
            stabilityText = 'System is UNSTABLE';
            stabilityColor = [0.8 0 0]; % Red
        end
        
        % Create a textbox annotation with stability status
        annotation(app.StateSpaceAxes.Parent, 'textbox', [0.5, 0.05, 0.3, 0.05], ...
            'String', stabilityText, 'HorizontalAlignment', 'center', ...
            'FontWeight', 'bold', 'FontSize', 12, 'Color', stabilityColor, ...
            'BackgroundColor', [1 1 1 0.8], 'EdgeColor', [0.7 0.7 0.7]);
        
        % Set axis properties
        grid(app.StateSpaceAxes, 'on');
        axis(app.StateSpaceAxes, 'equal');
        xlim(app.StateSpaceAxes, [-max_abs_real, max_abs_real]);
        ylim(app.StateSpaceAxes, [-max_abs_imag, max_abs_imag]);
        
        % Set title based on system type
        if isDiscrete
            title(app.StateSpaceAxes, 'Eigenvalue Analysis (z-plane)', 'FontSize', 14, 'FontWeight', 'bold');
            xlabel(app.StateSpaceAxes, 'Real Axis', 'FontSize', 12);
            ylabel(app.StateSpaceAxes, 'Imaginary Axis', 'FontSize', 12);
        else
            title(app.StateSpaceAxes, 'Eigenvalue Analysis (s-plane)', 'FontSize', 14, 'FontWeight', 'bold');
            xlabel(app.StateSpaceAxes, 'Real Axis (seconds^{-1})', 'FontSize', 12);
            ylabel(app.StateSpaceAxes, 'Imaginary Axis (seconds^{-1})', 'FontSize', 12);
        end
        
        hold(app.StateSpaceAxes, 'off');
        
    catch ME
        % Enhanced error handling with user feedback
        cla(app.StateSpaceAxes);
        
        errorMsg = ['Error in eigenvalue analysis: ' ME.message];
        disp(errorMsg);
        disp(getReport(ME, 'extended'));
        
        % Display error in plot
        text(app.StateSpaceAxes, 0.5, 0.6, 'Eigenvalue Analysis Error', ...
            'HorizontalAlignment', 'center', 'FontSize', 14, 'FontWeight', 'bold', 'Color', 'red', ...
            'Units', 'normalized');
        
        text(app.StateSpaceAxes, 0.5, 0.5, ME.message, ...
            'HorizontalAlignment', 'center', 'FontSize', 12, 'Color', 'red', ...
            'Units', 'normalized');
        
        text(app.StateSpaceAxes, 0.5, 0.4, 'Troubleshooting: Check if model exists and is valid', ...
            'HorizontalAlignment', 'center', 'FontSize', 12, 'Color', [0.6 0.6 0.6], ...
            'Units', 'normalized');
    end
end

function performStepResponse(app, t)
    % Perform step response analysis and visualize in the StateSpaceAxes
    
    % Get the linearized model
    model = app.LinearizedModel;
    
    % Get dimensions
    [~, n_inputs] = size(model.B);
    [n_outputs, n_states] = size(model.C);
    
    % Clear axes
    cla(app.StateSpaceAxes);
    
    % Enable hold for multiple plots
    hold(app.StateSpaceAxes, 'on');
    
    % Calculate step response for all inputs and outputs
    [y, t, x] = step(model, t);
    
    % Plot the outputs
    colors = lines(max(n_outputs, n_states));
    
    % Create subplot for outputs
    if n_outputs > 0 && n_states > 0
        subplot(app.StateSpaceAxes, 2, 1);
        hold on;
        
        % Plot outputs for first input
        for i = 1:n_outputs
            plot(t, squeeze(y(:, i, 1)), 'LineWidth', 2, 'Color', colors(i, :));
        end
        
        grid on;
        title('Output Response to Step Input');
        xlabel('Time (s)');
        ylabel('Output Amplitude');
        
        % Add legend for outputs
        if n_outputs > 1
            legend_labels = cell(1, n_outputs);
            for i = 1:n_outputs
                legend_labels{i} = ['Output ', num2str(i)];
            end
            legend(legend_labels, 'Location', 'best');
        end
        
        % Create subplot for states
        subplot(app.StateSpaceAxes, 2, 2);
        hold on;
        
        % Plot states for first input
        for i = 1:n_states
            plot(t, squeeze(x(:, i, 1)), 'LineWidth', 1.5, 'Color', colors(i, :));
        end
        
        grid on;
        title('State Response to Step Input');
        xlabel('Time (s)');
        ylabel('State Amplitude');
        
        % Add legend for states
        if n_states > 1
            legend_labels = cell(1, n_states);
            for i = 1:n_states
                legend_labels{i} = ['State ', num2str(i)];
            end
            legend(legend_labels, 'Location', 'best');
        end
    else
        % No multiple outputs or states, just show the response
        [y, t] = step(model, t);
        plot(app.StateSpaceAxes, t, y, 'LineWidth', 2);
        
        grid(app.StateSpaceAxes, 'on');
        title(app.StateSpaceAxes, 'Step Response');
        xlabel(app.StateSpaceAxes, 'Time (s)');
        ylabel(app.StateSpaceAxes, 'Amplitude');
    end
    
    hold(app.StateSpaceAxes, 'off');
end


function performImpulseResponse(app, t)
    % Perform impulse response analysis and visualize in the StateSpaceAxes
    
    % Get the linearized model
    model = app.LinearizedModel;
    
    % Get dimensions
    [~, n_inputs] = size(model.B);
    [n_outputs, n_states] = size(model.C);
    
    % Clear axes
    cla(app.StateSpaceAxes);
    
    % Enable hold for multiple plots
    hold(app.StateSpaceAxes, 'on');
    
    % Calculate impulse response for all inputs and outputs
    [y, t, x] = impulse(model, t);
    
    % Plot the outputs
    colors = lines(max(n_outputs, n_states));
    
    % Create subplot for outputs
    if n_outputs > 0 && n_states > 0
        subplot(app.StateSpaceAxes, 2, 1);
        hold on;
        
        % Plot outputs for first input
        for i = 1:n_outputs
            plot(t, squeeze(y(:, i, 1)), 'LineWidth', 2, 'Color', colors(i, :));
        end
        
        grid on;
        title('Output Response to Impulse Input');
        xlabel('Time (s)');
        ylabel('Output Amplitude');
        
        % Add legend for outputs
        if n_outputs > 1
            legend_labels = cell(1, n_outputs);
            for i = 1:n_outputs
                legend_labels{i} = ['Output ', num2str(i)];
            end
            legend(legend_labels, 'Location', 'best');
        end
        
        % Create subplot for states
        subplot(app.StateSpaceAxes, 2, 2);
        hold on;
        
        % Plot states for first input
        for i = 1:n_states
            plot(t, squeeze(x(:, i, 1)), 'LineWidth', 1.5, 'Color', colors(i, :));
        end
        
        grid on;
        title('State Response to Impulse Input');
        xlabel('Time (s)');
        ylabel('State Amplitude');
        
        % Add legend for states
        if n_states > 1
            legend_labels = cell(1, n_states);
            for i = 1:n_states
                legend_labels{i} = ['State ', num2str(i)];
            end
            legend(legend_labels, 'Location', 'best');
        end
    else
        % No multiple outputs or states, just show the response
        [y, t] = impulse(model, t);
        plot(app.StateSpaceAxes, t, y, 'LineWidth', 2);
        
        grid(app.StateSpaceAxes, 'on');
        title(app.StateSpaceAxes, 'Impulse Response');
        xlabel(app.StateSpaceAxes, 'Time (s)');
        ylabel(app.StateSpaceAxes, 'Amplitude');
    end
    
    hold(app.StateSpaceAxes, 'off');
end


function performPhasePortrait(app)
    % Perform phase portrait analysis for a 2D system
    
    % Check if the model has 2 states
    model = app.LinearizedModel;
    A = model.A;
    
    [n, ~] = size(A);
    
    if n ~= 2
        warningMsg = ['<html><div style="color: orange; text-align: center; margin-top: 10px; font-weight: bold;">', ...
                     'Phase portrait visualization is only available for 2-state systems.<br>', ...
                     'This system has ', num2str(n), ' states.', ...
                     '</div></html>'];
        app.StateSpaceInfoHTML.HTMLSource = [app.StateSpaceInfoHTML.HTMLSource, warningMsg];
        return;
    end
    
    % Clear axes
    cla(app.StateSpaceAxes);
    
    % Create meshgrid for phase portrait
    [x1, x2] = meshgrid(linspace(-2, 2, 20), linspace(-2, 2, 20));
    
    % Calculate vector field (derivatives)
    dx1 = zeros(size(x1));
    dx2 = zeros(size(x2));
    
    for i = 1:numel(x1)
        x = [x1(i); x2(i)];
        xdot = A * x;
        dx1(i) = xdot(1);
        dx2(i) = xdot(2);
    end
    
    % Normalize vectors for better visualization
    magnitude = sqrt(dx1.^2 + dx2.^2);
    max_magnitude = max(magnitude(:));
    
    if max_magnitude > 0
        scale_factor = 0.5;
        dx1 = scale_factor * dx1 ./ max_magnitude;
        dx2 = scale_factor * dx2 ./ max_magnitude;
    end
    
    % Plot the vector field
    hold(app.StateSpaceAxes, 'on');
    quiver(app.StateSpaceAxes, x1, x2, dx1, dx2, 0, 'b');
    
    % Calculate and plot nullclines
    % x1-nullcline: dx1/dt = 0
    if A(1, 2) ~= 0
        x1_range = linspace(-2, 2, 100);
        x2_nullcline1 = -A(1, 1) * x1_range / A(1, 2);
        plot(app.StateSpaceAxes, x1_range, x2_nullcline1, 'r--', 'LineWidth', 1.5);
    end
    
    % x2-nullcline: dx2/dt = 0
    if A(2, 2) ~= 0
        x1_range = linspace(-2, 2, 100);
        x2_nullcline2 = -A(2, 1) * x1_range / A(2, 2);
        plot(app.StateSpaceAxes, x1_range, x2_nullcline2, 'g--', 'LineWidth', 1.5);
    end
    
    % Plot equilibrium point (origin for linear system)
    plot(app.StateSpaceAxes, 0, 0, 'ro', 'MarkerSize', 10, 'MarkerFaceColor', 'r');
    
    % Calculate and plot some trajectories
    num_trajectories = 6;
    max_time = 10;
    t = linspace(0, max_time, 100);
    
    % Initial conditions for trajectories
    r = 2;
    angles = linspace(0, 2*pi, num_trajectories+1);
    angles = angles(1:end-1); % Remove duplicate at 2pi
    
    % Plot trajectories
    colors = jet(num_trajectories);
    for i = 1:num_trajectories
        % Initial condition
        x0 = [r*cos(angles(i)); r*sin(angles(i))];
        
        % Simulate the system response
        [y, t, x] = initial(model, x0, t);
        
        % Plot the trajectory
        plot(app.StateSpaceAxes, x(:,1), x(:,2), 'Color', colors(i,:), 'LineWidth', 1.5);
        
        % Plot the starting point
        plot(app.StateSpaceAxes, x0(1), x0(2), 'o', 'MarkerSize', 6, 'MarkerFaceColor', colors(i,:), 'MarkerEdgeColor', 'k');
    end
    
    % Set axis properties
    grid(app.StateSpaceAxes, 'on');
    axis(app.StateSpaceAxes, 'equal');
    xlim(app.StateSpaceAxes, [-2, 2]);
    ylim(app.StateSpaceAxes, [-2, 2]);
    
    title(app.StateSpaceAxes, 'Phase Portrait');
    xlabel(app.StateSpaceAxes, 'x_1');
    ylabel(app.StateSpaceAxes, 'x_2');
    
    % Add legend
    legend(app.StateSpaceAxes, {'Vector Field', 'x_1-nullcline', 'x_2-nullcline', 'Equilibrium'}, 'Location', 'best');
    
    hold(app.StateSpaceAxes, 'off');
end
        
function performControllabilityAnalysis(app)
    % Perform controllability analysis and visualize in the StateSpaceAxes
    
    % Get the linearized model
    model = app.LinearizedModel;
    A = model.A;
    B = model.B;
    
    % Get dimensions
    [n, m] = size(B);
    
    % Calculate controllability matrix
    Co = ctrb(A, B);
    
    % Calculate rank of controllability matrix
    rankCo = rank(Co);
    
    % Define system as controllable if rank(Co) = n
    isControllable = (rankCo == n);
    
    % Calculate controllability Gramian for finite time (assuming stable system)
    t_final = 10;
    
    % Check if system is stable before calculating Gramian
    eigenvalues = eig(A);
    isStable = all(real(eigenvalues) < 0);
    
    if isStable
        % Calculate controllability Gramian for stable system
        % Solve Lyapunov equation: A*Wc + Wc*A' + B*B' = 0
        Wc = gram(model, 'c');
        
        % Calculate eigenvalues and eigenvectors of Gramian
        [V, D] = eig(Wc);
        d = diag(D);
        
        % Sort eigenvalues in descending order
        [d, idx] = sort(d, 'descend');
        V = V(:, idx);
    else
        % For unstable systems, cannot compute infinite-time Gramian
        Wc = [];
        V = [];
        d = [];
    end
    
    % Clear axes
    cla(app.StateSpaceAxes);
    
    % Create controllability information plot
    subplot(app.StateSpaceAxes, 2, 1);
    
    % Bar plot of rank of controllability matrix
    bar([rankCo, n]);
    grid on;
    ylabel('Rank');
    title('Controllability Analysis');
    set(gca, 'XTickLabel', {'rank(Co)', 'n'});
    
    % Add text about controllability
    if isControllable
        text(1.5, 0.5, 'System is controllable', 'HorizontalAlignment', 'center', ...
             'VerticalAlignment', 'bottom', 'Color', 'g', 'FontWeight', 'bold');
    else
        text(1.5, 0.5, 'System is not controllable', 'HorizontalAlignment', 'center', ...
             'VerticalAlignment', 'bottom', 'Color', 'r', 'FontWeight', 'bold');
    end
    
    % Create plot for eigenvalues of controllability Gramian (if available)
    subplot(app.StateSpaceAxes, 2, 2);
    
    if isStable && ~isempty(d)
        % Bar plot of eigenvalues of Gramian
        bar(d);
        grid on;
        xlabel('Eigenvalue Index');
        ylabel('Magnitude');
        title('Eigenvalues of Controllability Gramian');
        
        % Calculate condition number of Gramian
        cond_number = max(d) / min(d);
        
        % Add text about condition number
        text(n/2, max(d)/2, ['Condition Number: ', num2str(cond_number, '%.2e')], ...
             'HorizontalAlignment', 'center', 'FontWeight', 'bold');
        
        % Add interpretation of controllability "effort"
        if cond_number > 1e6
            text(n/2, max(d)/4, 'Poor controllability (high effort directions)', ...
                 'HorizontalAlignment', 'center', 'Color', 'r');
        elseif cond_number > 1e3
            text(n/2, max(d)/4, 'Moderate controllability', ...
                 'HorizontalAlignment', 'center', 'Color', [1 0.5 0]);
        else
            text(n/2, max(d)/4, 'Good controllability (balanced)', ...
                 'HorizontalAlignment', 'center', 'Color', 'g');
        end
    else
        % System is unstable, cannot compute infinite-time Gramian
        text(0.5, 0.5, 'Cannot compute Gramian for unstable system', ...
             'HorizontalAlignment', 'center', 'Color', 'r');
    end
    
    % Update system information with controllability details
    detailsHTML = ['<html><div style="text-align: center; margin-top: 10px;">', ...
                  '<h3>Controllability Analysis Details</h3>', ...
                  '<p>Controllability Matrix Rank: ', num2str(rankCo), ' of ', num2str(n), '</p>'];
    
    if isControllable
        detailsHTML = [detailsHTML, '<p style="color: green; font-weight: bold;">System is controllable</p>'];
    else
        detailsHTML = [detailsHTML, '<p style="color: red; font-weight: bold;">System is not controllable</p>'];
        
        % Add information about uncontrollable modes
        uncontrollable_modes = n - rankCo;
        detailsHTML = [detailsHTML, '<p>Number of uncontrollable modes: ', num2str(uncontrollable_modes), '</p>'];
    end
    
    if isStable && ~isempty(d)
        detailsHTML = [detailsHTML, '<p>Controllability Gramian Condition Number: ', num2str(cond_number, '%.2e'), '</p>'];
    end
    
    detailsHTML = [detailsHTML, '</div></html>'];
    
    app.StateSpaceInfoHTML.HTMLSource = [app.StateSpaceInfoHTML.HTMLSource, detailsHTML];
end


function performObservabilityAnalysis(app)
    % Perform observability analysis and visualize in the StateSpaceAxes
    
    % Get the linearized model
    model = app.LinearizedModel;
    A = model.A;
    C = model.C;
    
    % Get dimensions
    [p, n] = size(C);
    
    % Calculate observability matrix
    Ob = obsv(A, C);
    
    % Calculate rank of observability matrix
    rankOb = rank(Ob);
    
    % Define system as observable if rank(Ob) = n
    isObservable = (rankOb == n);
    
    % Calculate observability Gramian for finite time (assuming stable system)
    t_final = 10;
    
    % Check if system is stable before calculating Gramian
    eigenvalues = eig(A);
    isStable = all(real(eigenvalues) < 0);
    
    if isStable
        % Calculate observability Gramian for stable system
        % Solve Lyapunov equation: A'*Wo + Wo*A + C'*C = 0
        Wo = gram(model, 'o');
        
        % Calculate eigenvalues and eigenvectors of Gramian
        [V, D] = eig(Wo);
        d = diag(D);
        
        % Sort eigenvalues in descending order
        [d, idx] = sort(d, 'descend');
        V = V(:, idx);
    else
        % For unstable systems, cannot compute infinite-time Gramian
        Wo = [];
        V = [];
        d = [];
    end
    
    % Clear axes
    cla(app.StateSpaceAxes);
    
    % Create observability information plot
    subplot(app.StateSpaceAxes, 2, 1);
    
    % Bar plot of rank of observability matrix
    bar([rankOb, n]);
    grid on;
    ylabel('Rank');
    title('Observability Analysis');
    set(gca, 'XTickLabel', {'rank(Ob)', 'n'});
    
    % Add text about observability
    if isObservable
        text(1.5, 0.5, 'System is observable', 'HorizontalAlignment', 'center', ...
             'VerticalAlignment', 'bottom', 'Color', 'g', 'FontWeight', 'bold');
    else
        text(1.5, 0.5, 'System is not observable', 'HorizontalAlignment', 'center', ...
             'VerticalAlignment', 'bottom', 'Color', 'r', 'FontWeight', 'bold');
    end
    
    % Create plot for eigenvalues of observability Gramian (if available)
    subplot(app.StateSpaceAxes, 2, 2);
    
    if isStable && ~isempty(d)
        % Bar plot of eigenvalues of Gramian
        bar(d);
        grid on;
        xlabel('Eigenvalue Index');
        ylabel('Magnitude');
        title('Eigenvalues of Observability Gramian');
        
        % Calculate condition number of Gramian
        cond_number = max(d) / min(d);
        
        % Add text about condition number
        text(n/2, max(d)/2, ['Condition Number: ', num2str(cond_number, '%.2e')], ...
             'HorizontalAlignment', 'center', 'FontWeight', 'bold');
        
        % Add interpretation of observability "effort"
        if cond_number > 1e6
            text(n/2, max(d)/4, 'Poor observability (hard-to-observe directions)', ...
                 'HorizontalAlignment', 'center', 'Color', 'r');
        elseif cond_number > 1e3
            text(n/2, max(d)/4, 'Moderate observability', ...
                 'HorizontalAlignment', 'center', 'Color', [1 0.5 0]);
        else
            text(n/2, max(d)/4, 'Good observability (balanced)', ...
                 'HorizontalAlignment', 'center', 'Color', 'g');
        end
    else
        % System is unstable, cannot compute infinite-time Gramian
        text(0.5, 0.5, 'Cannot compute Gramian for unstable system', ...
             'HorizontalAlignment', 'center', 'Color', 'r');
    end
    
    % Update system information with observability details
    detailsHTML = ['<html><div style="text-align: center; margin-top: 10px;">', ...
                  '<h3>Observability Analysis Details</h3>', ...
                  '<p>Observability Matrix Rank: ', num2str(rankOb), ' of ', num2str(n), '</p>'];
    
    if isObservable
        detailsHTML = [detailsHTML, '<p style="color: green; font-weight: bold;">System is observable</p>'];
    else
        detailsHTML = [detailsHTML, '<p style="color: red; font-weight: bold;">System is not observable</p>'];
        
        % Add information about unobservable modes
        unobservable_modes = n - rankOb;
        detailsHTML = [detailsHTML, '<p>Number of unobservable modes: ', num2str(unobservable_modes), '</p>'];
    end
    
    if isStable && ~isempty(d)
        detailsHTML = [detailsHTML, '<p>Observability Gramian Condition Number: ', num2str(cond_number, '%.2e'), '</p>'];
    end
    
    detailsHTML = [detailsHTML, '</div></html>'];
    
    app.StateSpaceInfoHTML.HTMLSource = [app.StateSpaceInfoHTML.HTMLSource, detailsHTML];
end


function isValid = validateStateSpaceModel(app)
    % VALIDATESTATESPACEMODEL - Performs comprehensive validation of the state-space model
    % 
    % This function checks if a valid state-space model exists in the app,
    % validates matrix dimensions, and checks for NaN/Inf values.
    %
    % Input:
    %   app - The app object containing the state-space model
    %
    % Output:
    %   isValid - Boolean indicating if the model is valid (1) or not (0)
    
    try
        % First check: Model existence
        if ~isfield(app, 'LinearizedModel') || isempty(app.LinearizedModel)
            % Try to use StateSpaceModel if LinearizedModel doesn't exist
            if isfield(app, 'StateSpaceModel') && ~isempty(app.StateSpaceModel)
                app.LinearizedModel = app.StateSpaceModel;
                app.StateSpaceInfoHTML.HTMLSource = createErrorHTML('Warning: Using main state-space model as linearized model.');
                % Continue validation with the substituted model
            else
                app.StateSpaceInfoHTML.HTMLSource = createErrorHTML('No state-space model available. Please create a model first.');
                isValid = 0;
                return;
            end
        end
        
        % Get matrices
        try
            A = app.LinearizedModel.A;
            B = app.LinearizedModel.B;
            C = app.LinearizedModel.C;
            D = app.LinearizedModel.D;
        catch ME
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(['Invalid state-space model structure: ' ME.message]);
            isValid = 0;
            return;
        end
        
        % Matrix dimension checks
        [n_states, n_check] = size(A);
        if n_states ~= n_check
            % Fixed: Use concatenation instead of sprintf to avoid parsing issues
            errorMsg = ['A matrix must be square (' num2str(n_states) 'x' num2str(n_check) ').'];
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(errorMsg);
            isValid = 0;
            return;
        end
        
        [b_rows, b_cols] = size(B);
        if b_rows ~= n_states
            % Fixed: Use concatenation instead of sprintf to avoid parsing issues
            errorMsg = ['B matrix rows (' num2str(b_rows) ') must match A matrix dimension (' num2str(n_states) ').'];
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(errorMsg);
            isValid = 0;
            return;
        end
        
        [c_rows, c_cols] = size(C);
        if c_cols ~= n_states
            % Fixed: Use concatenation instead of sprintf to avoid parsing issues
            errorMsg = ['C matrix columns (' num2str(c_cols) ') must match A matrix dimension (' num2str(n_states) ').'];
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(errorMsg);
            isValid = 0;
            return;
        end
        
        [d_rows, d_cols] = size(D);
        if d_rows ~= c_rows || d_cols ~= b_cols
            % Fixed: Use concatenation instead of sprintf to avoid parsing issues
            errorMsg = ['D matrix dimensions (' num2str(d_rows) 'x' num2str(d_cols) ...
                        ') must match C rows (' num2str(c_rows) ') x B columns (' num2str(b_cols) ').'];
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(errorMsg);
            isValid = 0;
            return;
        end
        
        % Check for NaN or Inf values
        if any(isnan(A(:))) || any(isinf(A(:)))
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML('A matrix contains NaN or Inf values.');
            isValid = 0;
            return;
        end
        
        if any(isnan(B(:))) || any(isinf(B(:)))
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML('B matrix contains NaN or Inf values.');
            isValid = 0;
            return;
        end
        
        if any(isnan(C(:))) || any(isinf(C(:)))
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML('C matrix contains NaN or Inf values.');
            isValid = 0;
            return;
        end
        
        if any(isnan(D(:))) || any(isinf(D(:)))
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML('D matrix contains NaN or Inf values.');
            isValid = 0;
            return;
        end
        
        % All checks passed
        isValid = 1;
        
    catch ME
        % Handle any unexpected errors
        errorMsg = ['Validation error: ' ME.message];
        disp(errorMsg);
        
        % Create error HTML
        try
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(errorMsg);
        catch
            % If even that fails, just log to console
            disp('Failed to update HTML with error message');
        end
        
        isValid = 0;
    end
end
        
function integrateStateSpaceWithControlLoop(app)
    % Integrates state-space analysis results with control loop analysis
    
    try
        % Check if G and K exist
        if isempty(app.G) || isempty(app.K)
            disp('Transfer functions G(s) and K(s) must be defined first.');
            return;
        end
        
        % Calculate transfer functions
        [app.T, app.S, app.L, app.GS, app.KS] = transferfunctions(app.G, app.K);

        % Update HTML table
        render_html_table(app);
                
        % Make UI components visible
        app.PZButton.Visible = 'on';
        app.HtmlTable.Visible = 'on';
        app.HTMLPZ.Visible = 'on';
        app.SaveButton.Visible = 'on';
        app.ButtonGroup.Visible = 'on';
        app.ButtonGroup_2.Visible = 'on';
        app.nyquist_UIAxes2.Visible = "on";
        app.Bandwidth_UIAxes.Visible = "on";
        app.HTMLkeyparamTable_2.Visible = "on";
        app.HTMLNyquistInfo.Visible = "on";
        app.HTMLkeyparamTable.Visible = "on";

        % Perform various analyses
        plotnyquist(app);
        app.char_eq = get_characteristic_equation(app, app.G, app.K);
        controlloopstable(app);
        plot_TS_bandwidth(app, app.T, app.S);
        plot_bode_margins(app, app.L);
        calculateAndDisplayErrorandOutput(app, app.ButtonGroup_2.SelectedObject);
        calculateAndDisplayResponse(app, app.ButtonGroup.SelectedObject);
        PZchange(app, app.PZButton.SelectedObject);
        
        % Display success message
        disp('Control loop analysis updated successfully with state-space model results.');
        
    catch ME
        disp(['Error integrating state-space with control loop: ' ME.message]);
        disp(getReport(ME, 'extended'));
    end
end
    end %end of private methods

    methods (Access = public)
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
     
            app.IsNonlinearModel = false;
            app.LinearizedModel = [];
            displayPlaceholder(app, 'G', 's')
            displayPlaceholder(app, 'K', 's')
            displayPlaceholder(app, 'r', 't')
            displayPlaceholder(app, 'd_1', 't')
            displayPlaceholder(app, 'd_2', 't')
            
           
        end

        % Button pushed function: UpdateGButton
        function UpdateGButtonPushed(app, event)

        build_G(app);

        end

        % Button pushed function: UpdateKButton
        function UpdateKButtonPushed(app, event)
            build_K(app);
        end

        % Button pushed function: UpdaterButton
        function UpdaterButtonPushed(app, event)
            build_r(app);
        end

        % Button pushed function: Updated1Button
        function Updated1ButtonPushed(app, event)
    label = 'd_1';
    
    % Use get_disturbance_input_version3 function without nested definitions
    [app.d_1, app.d1_num_sections, app.d1_start_time, app.d1_end_time] = get_disturbance_input_version3(label);
    
    % If valid data was returned, create time vector and display
    if ~isempty(app.d_1) && ~isempty(app.d1_start_time) && ~isempty(app.d1_end_time)
        app.d1_time_vector = create_linear_time_vector_disturbance(app.d1_num_sections, label);
        
        % Display the time function with the specific time parameters for d_1
        displayTimeFunction(app, app.d_1, app.d1_num_sections, label, app.d1_start_time, app.d1_end_time);
    end
        end

        % Button pushed function: Updated2Button
        function Updated2ButtonPushed(app, event)
    label = 'd_2';
    
    % Use get_disturbance_input_version3 function without nested definitions
    [app.d_2, app.d2_num_sections, app.d2_start_time, app.d2_end_time] = get_disturbance_input_version3(label);
    
    % If valid data was returned, create time vector and display
    if ~isempty(app.d_2) && ~isempty(app.d2_start_time) && ~isempty(app.d2_end_time)
        app.d2_time_vector = create_linear_time_vector_disturbance(app.d2_num_sections, label);
        
        % Display the time function with the specific time parameters for d_2
        displayTimeFunction(app, app.d_2, app.d2_num_sections, label, app.d2_start_time, app.d2_end_time);
    end
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
    % Check if G, K, and r(t) are entered - if not, prompt for them
    % If d1 and d2 are empty, they will be set to 0

    % Check if G is entered
    if isempty(app.G)
        build_G(app);
    end
    
    % Check if K is entered
    if isempty(app.K)
        build_K(app);
    end
    
    % Check if r(t) is entered
    if isempty(app.r)
        build_r(app);
    end
    
    % Create time vector if not already defined
    if isempty(app.t)
        % Check if we have time parameters already
        if ~isempty(app.r_num_sections) && ~isempty(app.r_start_time) && ~isempty(app.r_end_time)
            disp('Creating time vector from existing parameters...');
            app.t = create_linear_time_vector(app.r_num_sections);
        else
            disp('No time parameters found. Prompting for time input...');
            app.t = create_linear_time_vector(app.r_num_sections);
        end
    else
        disp('Time vector already defined. Using cached values.');
    end
    
    % Create disturbance time vectors if necessary
    if ~isempty(app.d_1) && ~isempty(app.d1_start_time) && isempty(app.d1_time_vector)
        if ~isempty(app.d1_num_sections)
            app.d1_time_vector = create_linear_time_vector_disturbance(app.d1_num_sections, 'd_1');
        end
    end
    
    if ~isempty(app.d_2) && ~isempty(app.d2_start_time) && isempty(app.d2_time_vector)
        if ~isempty(app.d2_num_sections)
            app.d2_time_vector = create_linear_time_vector_disturbance(app.d2_num_sections, 'd_2');
        end
    end

    % Check if d1(t) and d2(t) were not entered 
    % If so, set both to zero
    if isempty(app.d_1)
        app.d_1 = @(t) 0 + 0*t;
        app.d1_num_sections = 1;
        label = 'd_1';
        displayTimeFunction(app, app.d_1, app.d1_num_sections, label);
    end    

    if isempty(app.d_2)
        app.d_2 = @(t) 0 + 0*t;
        app.d2_num_sections = 1;
        label = 'd_2';
        displayTimeFunction(app, app.d_2, app.d2_num_sections, label);
    end

    % Calculate transfer functions
    [app.T, app.S, app.L, app.GS, app.KS] = transferfunctions(app.G, app.K);

    % Render HTML table and update UI components
    render_html_table(app);
            
    app.PZButton.Visible = 'on';
    app.HtmlTable.Visible = 'on';
    app.HTMLPZ.Visible = 'on';
    app.SaveButton.Visible = 'on';
    app.ButtonGroup.Visible = 'on';
    app.ButtonGroup_2.Visible = 'on';
    app.nyquist_UIAxes2.Visible = "on";
    app.Bandwidth_UIAxes.Visible = "on";
    app.HTMLkeyparamTable_2.Visible = "on";
    app.HTMLNyquistInfo.Visible = "on";
    app.HTMLkeyparamTable.Visible = "on";

    % Perform various analyses
    plotnyquist(app);
    app.char_eq = get_characteristic_equation(app, app.G, app.K);
    controlloopstable(app);
    plot_TS_bandwidth(app, app.T, app.S);
    plot_bode_margins(app, app.L);
    calculateAndDisplayErrorandOutput(app, app.ButtonGroup_2.SelectedObject);
    calculateAndDisplayResponse(app, app.ButtonGroup.SelectedObject);
    PZchange(app, app.PZButton.SelectedObject);
            
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)

            selectedButton = app.ButtonGroup.SelectedObject;

            calculateAndDisplayResponse(app, selectedButton);
        

        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_2.SelectedObject;
    
            calculateAndDisplayErrorandOutput(app, selectedButton);

            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            saveAppVariables(app);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
    % LOADBUTTONPUSHED - Callback for the Load button
    %
    % This improved function loads app variables from a file and properly
    % displays all function representations, with enhanced handling of
    % piecewise function rendering.
     
    if loadAppVariables(app)
        % Display transfer functions
        if ~isempty(app.G)
            displayTransferFunctionInApp(app, app.G, 'G');
        else
            displayPlaceholder(app, 'G', 's');
        end
        
        if ~isempty(app.K)
            displayTransferFunctionInApp(app, app.K, 'K');
        else
            displayPlaceholder(app, 'K', 's');
        end
        
        % Display time functions using LaTeX strings if available
        % For reference signal r(t)
        if ~isempty(app.r)
            if isfield(app, 'r_latex_string') && ~isempty(app.r_latex_string)
                % Use the LaTeX string directly if available
                displaySavedTimeFunction(app, app.r_latex_string, 'r');
                disp('Displayed r(t) from saved LaTeX string');
            else
                % Regenerate the LaTeX representation
                disp('Regenerating r(t) LaTeX representation');
                displayTimeFunction(app, app.r, app.r_num_sections, 'r', app.r_start_time, app.r_end_time);
            end
        else
            displayPlaceholder(app, 'r', 't');
        end
        
        % For disturbance signal d_1(t)
        if ~isempty(app.d_1)
            if isfield(app, 'd1_latex_string') && ~isempty(app.d1_latex_string)
                % Use the LaTeX string directly if available
                displaySavedTimeFunction(app, app.d1_latex_string, 'd_1');
                disp('Displayed d_1(t) from saved LaTeX string');
            else
                % Regenerate the LaTeX representation
                disp('Regenerating d_1(t) LaTeX representation');
                displayTimeFunction(app, app.d_1, app.d1_num_sections, 'd_1', app.d1_start_time, app.d1_end_time);
            end
        else
            displayPlaceholder(app, 'd_1', 't');
        end
        
        % For disturbance signal d_2(t)
        if ~isempty(app.d_2)
            if isfield(app, 'd2_latex_string') && ~isempty(app.d2_latex_string)
                % Use the LaTeX string directly if available
                displaySavedTimeFunction(app, app.d2_latex_string, 'd_2');
                disp('Displayed d_2(t) from saved LaTeX string');
            else
                % Regenerate the LaTeX representation
                disp('Regenerating d_2(t) LaTeX representation');
                displayTimeFunction(app, app.d_2, app.d2_num_sections, 'd_2', app.d2_start_time, app.d2_end_time);
            end
        else
            displayPlaceholder(app, 'd_2', 't');
        end
        
        % Inform the user of successful loading
        msgbox('Successfully loaded all variables!', 'Load Successful', 'help');
    else
        % Optional: Inform the user that no file was selected
        uialert(app.UIFigure, 'No file selected!', 'Note');
    end
        end

        % Selection changed function: PZButton
        function PZButtonSelectionChanged(app, event)
            selectedButton = app.PZButton.SelectedObject;

            PZchange(app, selectedButton)
            
        end

        % Button pushed function: RunSensitivityButton
        function RunSensitivityButtonPushed(app, event)
            runSensitivityAnalysis(app);
        end

        % Button pushed function: RunMonteCarloButton
        function RunMonteCarloButtonPushed(app, event)
            runMonteCarloSimulation(app);
        end

        % Button pushed function: RunMuAnalysisButton
        function RunMuAnalysisButtonPushed(app, event)
            runMuAnalysis(app);
        end

        % Value changed function: KpDistDropdown
        function KpDistDropdownValueChanged(app, event)
            updateMonteCarloFields(app, 'Kp');
            
        end

        % Value changed function: KdDistDropdown
        function KdDistDropdownValueChanged(app, event)
            updateMonteCarloFields(app, 'Kd');
            
        end

        % Value changed function: KiDistDropdown
        function KiDistDropdownValueChanged(app, event)
            updateMonteCarloFields(app, 'Ki');
            
        end

        % Button pushed function: CreateLinearStateSpaceButton
        function CreateLinearStateSpaceButtonPushed(app, event)
   % CREATELINEARSTATESPACEBUTTONPUSHED - Creates a linear state-space model
    %
    % This enhanced function creates a linear state-space model from user input
    % with improved error handling, input validation and user feedback.
    
    try
        % Update UI with processing status
        app.StateSpaceInfoHTML.HTMLSource = '<html><div style="color: blue; text-align: center; margin-top: 10px; font-weight: bold;">Processing state-space matrices...</div></html>';
        drawnow;
        
        % Get matrix values from the text inputs with validation
        A_str = app.LinearAInput.Value;
        B_str = app.LinearBInput.Value;
        C_str = app.LinearCInput.Value;
        D_str = app.LinearDInput.Value;
        
        % Validate inputs are not empty
        if isempty(A_str)
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML('A matrix must be provided.');
            return;
        end
        
        if isempty(B_str)
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML('B matrix must be provided.');
            return;
        end
        
        if isempty(C_str)
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML('C matrix must be provided.');
            return;
        end
        
        if isempty(D_str)
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML('D matrix must be provided.');
            return;
        end
        
        % Convert to matrices with detailed error handling
        try
            A = str2matrix(A_str);
            if isempty(A)
                app.StateSpaceInfoHTML.HTMLSource = createErrorHTML('Invalid A matrix format. Use space-separated values for columns and semicolons for rows.');
                return;
            end
        catch ME
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(['Error in A matrix: ' ME.message]);
            return;
        end
        
        try
            B = str2matrix(B_str);
            if isempty(B)
                app.StateSpaceInfoHTML.HTMLSource = createErrorHTML('Invalid B matrix format. Use space-separated values for columns and semicolons for rows.');
                return;
            end
        catch ME
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(['Error in B matrix: ' ME.message]);
            return;
        end
        
        try
            C = str2matrix(C_str);
            if isempty(C)
                app.StateSpaceInfoHTML.HTMLSource = createErrorHTML('Invalid C matrix format. Use space-separated values for columns and semicolons for rows.');
                return;
            end
        catch ME
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(['Error in C matrix: ' ME.message]);
            return;
        end
        
        try
            D = str2matrix(D_str);
            if isempty(D)
                app.StateSpaceInfoHTML.HTMLSource = createErrorHTML('Invalid D matrix format. Use space-separated values for columns and semicolons for rows.');
                return;
            end
        catch ME
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(['Error in D matrix: ' ME.message]);
            return;
        end
        
        % Validate matrix dimensions with clear error messages
        [n_A, m_A] = size(A);
        [n_B, m_B] = size(B);
        [n_C, m_C] = size(C);
        [n_D, m_D] = size(D);
        
        if n_A ~= m_A
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(sprintf('A matrix must be square. Current dimensions: %dx%d', n_A, m_A));
            return;
        end
        
        if n_B ~= n_A
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(sprintf('B matrix rows (%d) must match A matrix dimension (%d).', n_B, n_A));
            return;
        end
        
        if m_C ~= n_A
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(sprintf('C matrix columns (%d) must match A matrix dimension (%d).', m_C, n_A));
            return;
        end
        
        if n_D ~= n_C || m_D ~= m_B
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(sprintf('D matrix dimensions (%dx%d) must match C rows (%d) x B columns (%d).', n_D, m_D, n_C, m_B));
            return;
        end
        
        % Check for NaN or Inf values
        if any(isnan(A(:))) || any(isinf(A(:)))
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML('A matrix contains NaN or Inf values.');
            return;
        end
        
        if any(isnan(B(:))) || any(isinf(B(:)))
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML('B matrix contains NaN or Inf values.');
            return;
        end
        
        if any(isnan(C(:))) || any(isinf(C(:)))
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML('C matrix contains NaN or Inf values.');
            return;
        end
        
        if any(isnan(D(:))) || any(isinf(D(:)))
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML('D matrix contains NaN or Inf values.');
            return;
        end
        
        % Create state-space model
        try
            ss_model = ss(A, B, C, D);
        catch ME
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(['Error creating state-space model: ' ME.message]);
            return;
        end
        
        % Store the model
        app.StateSpaceModel = ss_model;
        app.IsNonlinearModel = false;
        app.LinearizedModel = ss_model; % Same for linear system
        
        % Update system information display
        updateStateSpaceInfo(app, ss_model, false);
        
        % Add success message
        successHTML = '<html><div style="color: green; text-align: center; margin-top: 20px; font-weight: bold; padding: 10px; border: 1px solid green; border-radius: 5px;">';
        successHTML = [successHTML, 'Linear state-space model created successfully!'];
        successHTML = [successHTML, '<br><br>'];
        successHTML = [successHTML, 'System dimensions: ', num2str(n_A), ' states, ', num2str(m_B), ' inputs, ', num2str(n_C), ' outputs'];
        successHTML = [successHTML, '<br><br>'];
        successHTML = [successHTML, 'You can now analyze this model or convert it to a transfer function G(s).'];
        successHTML = [successHTML, '</div></html>'];
        
        app.StateSpaceInfoHTML.HTMLSource = [app.StateSpaceInfoHTML.HTMLSource, successHTML];
        
    catch ME
        % Enhanced error handling with context
        errorMsg = ['Error creating state-space model: ' ME.message];
        disp(errorMsg);
        disp(getReport(ME, 'extended'));
        
        % Create error HTML with troubleshooting tips
        errorHTML = '<html><div style="color: red; text-align: center; margin-top: 10px; font-weight: bold; padding: 10px; border: 1px solid red; border-radius: 5px;">';
        errorHTML = [errorHTML, 'Error creating state-space model:<br>'];
        errorHTML = [errorHTML, ME.message];
        errorHTML = [errorHTML, '<br><br>'];
        errorHTML = [errorHTML, 'Troubleshooting tips:<br>'];
        errorHTML = [errorHTML, '• Ensure all matrices have correct dimensions<br>'];
        errorHTML = [errorHTML, '• Use spaces to separate elements in a row<br>'];
        errorHTML = [errorHTML, '• Use semicolons (;) to separate rows<br>'];
        errorHTML = [errorHTML, '• Example: "1 2; 3 4" creates a 2×2 matrix<br>'];
        errorHTML = [errorHTML, '• Check for typos and invalid characters'];
        errorHTML = [errorHTML, '</div></html>'];
        
        app.StateSpaceInfoHTML.HTMLSource = errorHTML;
    end
        end

        % Button pushed function: LinearHelpButton
        function LinearHelpButtonPushed(app, event)
              % Callback function for the Linear Help Button
    msgbox({
        'Matrix Format Help:', ...
        '', ...
        '1. Enter matrices as space-separated values', ...
        '   Example: "1 2 3" for a row vector', ...
        '', ...
        '2. Use semicolons to separate rows', ...
        '   Example: "1 2; 3 4" for a 2x2 matrix', ...
        '', ...
        '3. For A matrix: nxn matrix where n is the number of states', ...
        '   Example for 2-state system: "-1 0.5; 0 -2"', ...
        '', ...
        '4. For B matrix: nxm matrix where n is the number of states', ...
        '   and m is the number of inputs', ...
        '   Example for 2-state, 1-input system: "1; 0"', ...
        '', ...
        '5. For C matrix: pxn matrix where p is the number of outputs', ...
        '   and n is the number of states', ...
        '   Example for 1-output, 2-state system: "1 0"', ...
        '', ...
        '6. For D matrix: pxm matrix where p is the number of outputs', ...
        '   and m is the number of inputs', ...
        '   Example for 1-output, 1-input system: "0"'
    }, 'Matrix Format Help', 'help');
        end

        % Button pushed function: CreateNonlinearStateSpaceButton
        function CreateNonlinearStateSpaceButtonPushed(app, event)
   % CREATENONLINEARSTATESPACEPUSHED - Callback for Create Nonlinear State Space Button
    %
    % This enhanced function creates and linearizes a nonlinear state-space model
    % with improved error handling, user feedback, and input validation.
    
    try
        % First check if Symbolic Math Toolbox is available
        if ~license('test', 'Symbolic_Toolbox')
            errorMsg = 'Symbolic Math Toolbox is required for nonlinear modeling but is not available.';
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(errorMsg);
            return;
        end
        
        % Update status to show progress
        progressMsg = '<html><div style="color: blue; text-align: center; margin-top: 10px; font-weight: bold;">Creating nonlinear model...</div></html>';
        app.StateSpaceInfoHTML.HTMLSource = progressMsg;
        drawnow; % Force display update
        
        % Get symbolic variables and equations
        state_vars_str = app.NonlinearStateVarsInput.Value;
        input_vars_str = app.NonlinearInputVarsInput.Value;
        state_eqs_str = app.NonlinearStateEqInput.Value;
        output_eqs_str = app.NonlinearOutputEqInput.Value;
        linearization_point_str = app.LinearizationPointInput.Value;
        
        % Enhanced input validation
        if isempty(state_vars_str)
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML('State variables must be provided.');
            return;
        end
        
        if isempty(input_vars_str)
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML('Input variables must be provided.');
            return;
        end
        
        if isempty(state_eqs_str)
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML('State equations must be provided.');
            return;
        end
        
        if isempty(output_eqs_str)
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML('Output equations must be provided.');
            return;
        end
        
        % Parse variables and equations with improved error handling
        try
            % Parse state variables
            state_vars = parseVariableList(state_vars_str);
            if isempty(state_vars)
                app.StateSpaceInfoHTML.HTMLSource = createErrorHTML('Invalid state variables. Please enter comma-separated list of valid variable names.');
                return;
            end
            
            % Parse input variables
            input_vars = parseVariableList(input_vars_str);
            if isempty(input_vars)
                app.StateSpaceInfoHTML.HTMLSource = createErrorHTML('Invalid input variables. Please enter comma-separated list of valid variable names.');
                return;
            end
            
            % Parse state equations
            state_eqs = parseEquationList(state_eqs_str);
            if isempty(state_eqs) || length(state_eqs) ~= length(state_vars)
                app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(sprintf('Invalid state equations. Number of equations (%d) must match number of state variables (%d).', ...
                                                     length(state_eqs), length(state_vars)));
                return;
            end
            
            % Parse output equations
            output_eqs = parseEquationList(output_eqs_str);
            if isempty(output_eqs)
                app.StateSpaceInfoHTML.HTMLSource = createErrorHTML('Invalid output equations. Please enter semicolon-separated equations.');
                return;
            end
        catch ME
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(['Error parsing inputs: ' ME.message]);
            return;
        end
        
        % Update progress
        app.StateSpaceInfoHTML.HTMLSource = '<html><div style="color: blue; text-align: center; margin-top: 10px; font-weight: bold;">Creating symbolic variables...</div></html>';
        drawnow;
        
        % Try to create symbolic variables
        try
            % Create a test symbolic variable to verify toolbox
            syms test_var
            
            % Verify variable names are valid
            for i = 1:length(state_vars)
                if ~isvarname(state_vars{i})
                    app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(['Invalid state variable name: ' state_vars{i}]);
                    return;
                end
            end
            
            for i = 1:length(input_vars)
                if ~isvarname(input_vars{i})
                    app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(['Invalid input variable name: ' input_vars{i}]);
                    return;
                end
            end
        catch ME
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(['Error creating symbolic variables: ' ME.message]);
            return;
        end
        
        % Update progress
        app.StateSpaceInfoHTML.HTMLSource = '<html><div style="color: blue; text-align: center; margin-top: 10px; font-weight: bold;">Converting equations to symbolic form...</div></html>';
        drawnow;
        
        % Convert state equations to symbolic expressions
        try
            % Test converting first state equation to ensure syntax is valid
            test_eq = ['syms ' strjoin(state_vars, ' ') ' ' strjoin(input_vars, ' ') '; ' state_vars{1} '_dot = ' state_eqs{1} ';'];
            eval(test_eq);
        catch ME
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(['Error in state equation syntax: ' ME.message]);
            return;
        end
        
        % Convert output equations to symbolic expressions
        try
            % Test converting first output equation to ensure syntax is valid
            test_out_eq = ['syms ' strjoin(state_vars, ' ') ' ' strjoin(input_vars, ' ') '; y1 = ' output_eqs{1} ';'];
            eval(test_out_eq);
        catch ME
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(['Error in output equation syntax: ' ME.message]);
            return;
        end
        
        % Parse linearization point
        linearization_point = containers.Map();
        
        if isempty(linearization_point_str)
            % Use origin as default linearization point
            app.StateSpaceInfoHTML.HTMLSource = '<html><div style="color: blue; text-align: center; margin-top: 10px; font-weight: bold;">Using default linearization point at origin...</div></html>';
            drawnow;
            
            for i = 1:length(state_vars)
                linearization_point(state_vars{i}) = 0;
            end
            
            for i = 1:length(input_vars)
                linearization_point(input_vars{i}) = 0;
            end
        else
            try
                app.StateSpaceInfoHTML.HTMLSource = '<html><div style="color: blue; text-align: center; margin-top: 10px; font-weight: bold;">Parsing linearization point...</div></html>';
                drawnow;
                
                % Split by commas
                point_items = strsplit(linearization_point_str, ',');
                
                for i = 1:length(point_items)
                    item = strtrim(point_items{i});
                    if isempty(item)
                        continue;
                    end
                    
                    % Check for equals sign
                    if ~contains(item, '=')
                        app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(['Invalid linearization point format: ' item]);
                        return;
                    end
                    
                    % Split at equals sign
                    parts = strsplit(item, '=');
                    if length(parts) ~= 2
                        app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(['Invalid linearization point format: ' item]);
                        return;
                    end
                    
                    var_name = strtrim(parts{1});
                    var_value_str = strtrim(parts{2});
                    
                    % Validate variable name
                    isStateVar = any(strcmp(var_name, state_vars));
                    isInputVar = any(strcmp(var_name, input_vars));
                    
                    if ~isStateVar && ~isInputVar
                        app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(['Unknown variable in linearization point: ' var_name]);
                        return;
                    end
                    
                    % Convert to numeric value
                    try
                        var_value = str2double(var_value_str);
                        if isnan(var_value)
                            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(['Invalid numeric value: ' var_value_str]);
                            return;
                        end
                    catch
                        app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(['Error parsing value for ' var_name]);
                        return;
                    end
                    
                    linearization_point(var_name) = var_value;
                end
                
                % Check if all variables have values
                missing_vars = {};
                for i = 1:length(state_vars)
                    if ~isKey(linearization_point, state_vars{i})
                        missing_vars{end+1} = state_vars{i};
                    end
                end
                
                for i = 1:length(input_vars)
                    if ~isKey(linearization_point, input_vars{i})
                        missing_vars{end+1} = input_vars{i};
                    end
                end
                
                if ~isempty(missing_vars)
                    app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(['Missing linearization point values for: ' strjoin(missing_vars, ', ')]);
                    return;
                end
            catch ME
                app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(['Error parsing linearization point: ' ME.message]);
                return;
            end
        end
        
        % Store nonlinear model parameters
        app.NonlinearStateVars = state_vars;
        app.NonlinearInputVars = input_vars;
        app.NonlinearStateEqs = state_eqs;
        app.NonlinearOutputEqs = output_eqs;
        app.LinearizationPoint = linearization_point;
        app.IsNonlinearModel = true;
        
        % Update progress
        app.StateSpaceInfoHTML.HTMLSource = '<html><div style="color: blue; text-align: center; margin-top: 10px; font-weight: bold;">Linearizing system...</div></html>';
        drawnow;
        
        % Call linearization function with better error handling
        try
            [A, B, C, D] = linearizeSystem(app, state_vars, input_vars, state_eqs, output_eqs, linearization_point);
        catch ME
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(['Linearization failed: ' ME.message]);
            return;
        end
        
        % Create linearized state-space model
        try
            linearized_model = ss(A, B, C, D);
            app.LinearizedModel = linearized_model;
        catch ME
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(['Error creating state-space model: ' ME.message]);
            return;
        end
        
        % Update system information display
        updateStateSpaceInfo(app, linearized_model, app.IsNonlinearModel);
        
        % Add success message
        successHTML = '<html><div style="color: green; text-align: center; margin-top: 20px; font-weight: bold; padding: 10px; border: 1px solid green; border-radius: 5px;">';
        successHTML = [successHTML, 'Nonlinear state-space model created and linearized successfully!'];
        successHTML = [successHTML, '<br><br>'];
        successHTML = [successHTML, 'You can now analyze this model or convert it to a transfer function G(s).'];
        successHTML = [successHTML, '</div></html>'];
        
        app.StateSpaceInfoHTML.HTMLSource = [app.StateSpaceInfoHTML.HTMLSource, successHTML];
        
    catch ME
        % Enhanced error handling with context details
        disp('*** ERROR IN NONLINEAR MODEL CREATION ***');
        disp(['Error message: ', ME.message]);
        disp('Stack trace:');
        disp(getReport(ME, 'extended'));
        
        % Create html error message with troubleshooting tips
        errorHTML = '<html><div style="color: red; text-align: center; margin-top: 10px; font-weight: bold; padding: 10px; border: 1px solid red; border-radius: 5px;">';
        errorHTML = [errorHTML, 'Error creating nonlinear model:<br>'];
        errorHTML = [errorHTML, ME.message];
        errorHTML = [errorHTML, '<br><br>'];
        errorHTML = [errorHTML, 'Troubleshooting tips:<br>'];
        
        % Add context-specific tips based on the error
        if contains(lower(ME.message), {'symbolic', 'symbol', 'sym'})
            errorHTML = [errorHTML, '• Make sure Symbolic Math Toolbox is installed<br>'];
            errorHTML = [errorHTML, '• Check variable names for valid MATLAB syntax<br>'];
        elseif contains(lower(ME.message), {'variable', 'undefined'})
            errorHTML = [errorHTML, '• Use valid MATLAB variable names (no spaces or special characters)<br>'];
            errorHTML = [errorHTML, '• Check for typographical errors in variable references<br>'];
        elseif contains(lower(ME.message), {'equation', 'parse', 'syntax'})
            errorHTML = [errorHTML, '• Use standard MATLAB operators (+, -, *, /, ^)<br>'];
            errorHTML = [errorHTML, '• Check equation syntax and balance of parentheses<br>'];
        elseif contains(lower(ME.message), 'lineariz')
            errorHTML = [errorHTML, '• Check linearization point format (var=value)<br>'];
            errorHTML = [errorHTML, '• Ensure all variables have valid linearization values<br>'];
        else
            errorHTML = [errorHTML, '• Check all inputs for valid MATLAB syntax<br>'];
            errorHTML = [errorHTML, '• Ensure system is properly defined (states, inputs, equations)<br>'];
        end
        
        errorHTML = [errorHTML, '• Try a simpler model first to verify functionality'];
        errorHTML = [errorHTML, '</div></html>'];
        
        app.StateSpaceInfoHTML.HTMLSource = errorHTML;
    end
        end

        % Button pushed function: NonlinearHelpButton
        function NonlinearHelpButtonPushed(app, event)
              % Callback function for the Nonlinear Help Button
    msgbox({
        'Nonlinear Model Format Help:', ...
        '', ...
        '1. State Variables: List each state variable, separated by commas', ...
        '   Example: "x1, x2, x3"', ...
        '', ...
        '2. Input Variables: List each input variable, separated by commas', ...
        '   Example: "u1, u2" or just "u" for single input', ...
        '', ...
        '3. State Equations: Enter each dx/dt equation, separated by semicolons', ...
        '   Example for a 2-state system:', ...
        '   "-x1 + x2^2; -x2 - sin(x1) + u"', ...
        '', ...
        '4. Output Equations: Enter each output equation, separated by semicolons', ...
        '   Example: "x1 + x2; x1*x2" for two outputs', ...
        '', ...
        '5. Linearization Point: Enter the linearization point as comma-separated', ...
        '   variable assignments', ...
        '   Example: "x1=0, x2=1, u=0"', ...
        '   Leave empty to linearize around the origin', ...
        '', ...
        '6. Supported Functions:', ...
        '   - Basic operators: +, -, *, /, ^', ...
        '   - Trigonometric: sin, cos, tan', ...
        '   - Exponential/Logarithmic: exp, log, log10', ...
        '   - Hyperbolic: sinh, cosh, tanh', ...
        '   - Others: sqrt, abs'
    }, 'Nonlinear Model Format Help', 'help');
        end

        % Button pushed function: RunAnalysisButton
        function RunAnalysisButtonPushed(app, event)
      % Callback function for the Run Analysis Button
    try
        % More robust check for model existence with detailed error handling
        if ~isfield(app, 'LinearizedModel') || isempty(app.LinearizedModel)
            % Try to recover by checking if there's a state space model we can use
            if isfield(app, 'StateSpaceModel') && ~isempty(app.StateSpaceModel)
                app.LinearizedModel = app.StateSpaceModel;
                app.StateSpaceInfoHTML.HTMLSource = [app.StateSpaceInfoHTML.HTMLSource, ...
                    '<div style="color: orange; text-align: center; margin-top: 10px;">', ...
                    'Warning: Using main state space model for analysis.', ...
                    '</div>'];
            else
                error('No state-space model available. Please create a linear or nonlinear model first.');
            end
        end
        
        % Get analysis type
        analysisType = app.AnalysisTypeDropdown.Value;
        
        % Get analysis parameters
        paramStr = app.AnalysisParamInput.Value;
        
        % Perform the selected analysis
        switch analysisType
            case 'Eigenvalues'
                disp('Performing eigenvalue analysis...');
                performEigenvalueAnalysis(app);
                
            case 'Step Response'
                disp('Performing step response analysis...');
                % Extract time vector from parameters
                if contains(paramStr, 't=')
                    try
                        t_str = extractAfter(paramStr, 't=');
                        t = eval(t_str);
                    catch ME
                        disp(['Error evaluating time vector: ', ME.message]);
                        t = 0:0.1:10; % Default if evaluation fails
                    end
                else
                    t = 0:0.1:10; % Default time vector
                end
                performStepResponse(app, t);
                
            case 'Impulse Response'
                disp('Performing impulse response analysis...');
                % Extract time vector from parameters
                if contains(paramStr, 't=')
                    try
                        t_str = extractAfter(paramStr, 't=');
                        t = eval(t_str);
                    catch 
                        t = 0:0.1:10; % Default if evaluation fails
                    end
                else
                    t = 0:0.1:10; % Default time vector
                end
                performImpulseResponse(app, t);
                
            case 'Phase Portrait'
                disp('Performing phase portrait analysis...');
                performPhasePortrait(app);
                
            case 'Controllability'
                disp('Performing controllability analysis...');
                performControllabilityAnalysis(app);
                
            case 'Observability'
                disp('Performing observability analysis...');
                performObservabilityAnalysis(app);
                
            otherwise
                error('Unknown analysis type: %s', analysisType);
        end
        
        % Success message
        app.StateSpaceInfoHTML.HTMLSource = [app.StateSpaceInfoHTML.HTMLSource, ...
            '<div style="color: green; text-align: center; margin-top: 10px; font-weight: bold;">', ...
            'Analysis completed successfully!', ...
            '</div>'];
            
    catch ME
        % Enhanced error handling with stack trace
        disp('*** ERROR IN RUN ANALYSIS ***');
        disp(['Error message: ', ME.message]);
        disp('Stack trace:');
        disp(getReport(ME, 'extended'));
        
        % Display user-friendly error message
        errorMsg = ['<html><div style="color: red; text-align: center; margin-top: 10px; font-weight: bold;">', ...
                   'Error performing analysis:<br>', ...
                   ME.message, '<br><br>', ...
                   'Troubleshooting tips:<br>', ...
                   '- Make sure you have created a state-space model first<br>', ...
                   '- Check that your model has valid state-space matrices<br>', ...
                   '- For nonlinear models, ensure linearization was successful<br>', ...
                   '</div></html>'];
        app.StateSpaceInfoHTML.HTMLSource = errorMsg;
        
        % Also attempt to create an error visualization on the plot
        try
            cla(app.StateSpaceAxes);
            text(app.StateSpaceAxes, 0.5, 0.5, {'Analysis Error', ME.message}, ...
                'HorizontalAlignment', 'center', 'Units', 'normalized', ...
                'FontSize', 14, 'Color', 'red', 'FontWeight', 'bold');
        catch
            % If even visualization fails, just log the error
            disp('Failed to create error visualization.');
        end
    end
        end

        % Button pushed function: ConvertToTransferFunctionButton
        function ConvertToTransferFunctionButtonPushed(app, event)
       % CONVERTTOTRANSFERFUNCTIONBUTTONPUSHED - Converts state-space model to G(s)
    %
    % This enhanced function converts a state-space model to a transfer function
    % with improved validation, error handling, and user feedback
    
    try
        % First validate the state-space model
        if ~validateStateSpaceModel(app)
            % Error message already displayed by validation function
            return;
        end
        
        % Update status to show conversion in progress
        app.StateSpaceInfoHTML.HTMLSource = '<html><div style="color: blue; text-align: center; margin-top: 10px; font-weight: bold;">Converting to transfer function...</div></html>';
        drawnow;
        
        % Get selected input and output indices for MIMO systems
        inputIdx = app.InputIndexInput.Value;
        outputIdx = app.OutputIndexInput.Value;
        
        % Validate indices
        [~, n_inputs] = size(app.LinearizedModel.B);
        [n_outputs, ~] = size(app.LinearizedModel.C);
        
        if inputIdx > n_inputs
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(sprintf('Input index %d exceeds the number of inputs (%d).', inputIdx, n_inputs));
            return;
        end
        
        if outputIdx > n_outputs
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(sprintf('Output index %d exceeds the number of outputs (%d).', outputIdx, n_outputs));
            return;
        end
        
        % Convert to transfer function using the enhanced convertSS2TF function
        [sys_tf, success, errorMsg] = convertSS2TF(app, app.LinearizedModel, inputIdx, outputIdx);
        
        if ~success
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(errorMsg);
            return;
        end
        
        % Conversion successful - store and display the transfer function
        
        % Store the transfer function as G
        app.G = sys_tf;
        
        % Display the transfer function
        try
            displayTransferFunctionInApp(app, app.G, 'G');
        catch ME
            app.StateSpaceInfoHTML.HTMLSource = createErrorHTML(['Error displaying transfer function: ' ME.message]);
            return;
        end
        
        % Get transfer function properties for display
        [num, den] = tfdata(sys_tf, 'v');
        
        % Create success message with transfer function details
        successMsg = '<html><div style="color: green; text-align: center; margin-top: 10px; font-weight: bold; padding: 10px; border: 1px solid green; border-radius: 5px;">';
        successMsg = [successMsg, 'State-space model successfully converted to transfer function G(s)!<br><br>'];
        
        % Add model information
        if app.LinearizedModel.Ts > 0
            successMsg = [successMsg, 'Discrete-time transfer function (Ts = ', num2str(app.LinearizedModel.Ts), ' s)<br>'];
        else
            successMsg = [successMsg, 'Continuous-time transfer function<br>'];
        end
        
        successMsg = [successMsg, 'Numerator order: ', num2str(length(num)-1), '<br>'];
        successMsg = [successMsg, 'Denominator order: ', num2str(length(den)-1), '<br><br>'];
        
        % Print coefficient information
        successMsg = [successMsg, 'Numerator coefficients: ['];
        for i = 1:length(num)
            if i > 1
                successMsg = [successMsg, ', '];
            end
            successMsg = [successMsg, num2str(num(i), '%.4g')];
        end
        successMsg = [successMsg, ']<br>'];
        
        successMsg = [successMsg, 'Denominator coefficients: ['];
        for i = 1:length(den)
            if i > 1
                successMsg = [successMsg, ', '];
            end
            successMsg = [successMsg, num2str(den(i), '%.4g')];
        end
        successMsg = [successMsg, ']<br><br>'];
        
        % Add next steps
        successMsg = [successMsg, 'You can now define K(s) and analyze the control system.<br>'];
        successMsg = [successMsg, 'Click "Analyze" after setting K(s) to analyze the control system.'];
        successMsg = [successMsg, '</div></html>'];
        
        app.StateSpaceInfoHTML.HTMLSource = successMsg;
        
        % Highlight the Analyze button to guide the user to the next step
        app.AnalyzeButton.BackgroundColor = [0.4 0.8 0.4];  % Green highlight
        
        % Show success dialog
        % msgbox('Transfer function conversion successful!', 'Success', 'help');
        
    catch ME
        % Handle errors
        disp(['Error in conversion: ' ME.message]);
        disp(getReport(ME, 'extended'));
        
        errorMsg = ['<html><div style="color: red; text-align: center; margin-top: 10px; font-weight: bold; padding: 10px; border: 1px solid red; border-radius: 5px;">', ...
                   'Error converting to transfer function:<br>', ...
                   ME.message, ...
                   '<br><br>Troubleshooting tips:<br>', ...
                   '• Check that the state-space model is valid<br>', ...
                   '• Ensure input/output indices are correct<br>', ...
                   '• For high-order systems, numerical issues might occur<br>', ...
                   '• Verify system is controllable and observable', ...
                   '</div></html>'];
        app.StateSpaceInfoHTML.HTMLSource = errorMsg;
    end
        end

        % Button pushed function: SaveStateSpaceButton
        function SaveStateSpaceButtonPushed(app, event)
             % Callback function for the Save Model Button
    try
        % Check if a model exists
        if (~isfield(app, 'LinearizedModel') || isempty(app.LinearizedModel)) && ...
           (~isfield(app, 'IsNonlinearModel') || ~app.IsNonlinearModel)
            error('No state-space model available to save.');
        end
        
        % Create a structure to save
        saveStruct = struct();
        
        if isfield(app, 'IsNonlinearModel') && app.IsNonlinearModel
            % For nonlinear model, save the symbolic representation
            saveStruct.IsNonlinearModel = true;
            saveStruct.NonlinearStateVars = app.NonlinearStateVars;
            saveStruct.NonlinearInputVars = app.NonlinearInputVars;
            saveStruct.NonlinearStateEqs = app.NonlinearStateEqs;
            saveStruct.NonlinearOutputEqs = app.NonlinearOutputEqs;
            saveStruct.LinearizationPoint = app.LinearizationPoint;
        else
            % For linear model, save the matrices
            saveStruct.IsNonlinearModel = false;
            saveStruct.A = app.LinearizedModel.A;
            saveStruct.B = app.LinearizedModel.B;
            saveStruct.C = app.LinearizedModel.C;
            saveStruct.D = app.LinearizedModel.D;
            saveStruct.Ts = app.LinearizedModel.Ts;
        end
        
        % Also save the linearized model regardless of type
        if isfield(app, 'LinearizedModel') && ~isempty(app.LinearizedModel)
            saveStruct.LinearizedA = app.LinearizedModel.A;
            saveStruct.LinearizedB = app.LinearizedModel.B;
            saveStruct.LinearizedC = app.LinearizedModel.C;
            saveStruct.LinearizedD = app.LinearizedModel.D;
            saveStruct.LinearizedTs = app.LinearizedModel.Ts;
        end
        
        % Prompt user for filename
        [file, path] = uiputfile('*.mat', 'Save State-Space Model');
        
        if isequal(file, 0) || isequal(path, 0)
            % User cancelled
            return;
        end
        
        % Combine path and filename
        fullPath = fullfile(path, file);
        
        % Save the structure
        save(fullPath, '-struct', 'saveStruct');
        
        % Show success message
        successMsg = ['<html><div style="color: green; text-align: center; margin-top: 10px; font-weight: bold;">', ...
                     'State-space model successfully saved to:<br>', ...
                     fullPath, ...
                     '</div></html>'];
        app.StateSpaceInfoHTML.HTMLSource = successMsg;
        
    catch ME
        % Handle errors
        errorMsg = ['<html><div style="color: red; text-align: center; margin-top: 10px; font-weight: bold;">', ...
                   'Error saving state-space model:<br>', ...
                   ME.message, ...
                   '</div></html>'];
        app.StateSpaceInfoHTML.HTMLSource = errorMsg;
    end
        end

        % Button pushed function: LoadStateSpaceButton
        function LoadStateSpaceButtonPushed(app, event)
               % Callback function for the Load Model Button
    try
        % Prompt user for file
        [file, path] = uigetfile('*.mat', 'Load State-Space Model');
        
        if isequal(file, 0) || isequal(path, 0)
            % User cancelled
            return;
        end
        
        % Combine path and filename
        fullPath = fullfile(path, file);
        
        % Load the saved structure
        loadedData = load(fullPath);
        
        % Check type of model
        if isfield(loadedData, 'IsNonlinearModel') && loadedData.IsNonlinearModel
            % Nonlinear model
            app.IsNonlinearModel = true;
            
            % Restore nonlinear model parameters
            app.NonlinearStateVars = loadedData.NonlinearStateVars;
            app.NonlinearInputVars = loadedData.NonlinearInputVars;
            app.NonlinearStateEqs = loadedData.NonlinearStateEqs;
            app.NonlinearOutputEqs = loadedData.NonlinearOutputEqs;
            app.LinearizationPoint = loadedData.LinearizationPoint;
            
            % Update nonlinear model inputs
            app.NonlinearStateVarsInput.Value = strjoin(app.NonlinearStateVars, ', ');
            app.NonlinearInputVarsInput.Value = strjoin(app.NonlinearInputVars, ', ');
            
            % Convert symbolic state equations back to string
            stateEqsStr = '';
            for i = 1:length(app.NonlinearStateEqs)
                if i > 1
                    stateEqsStr = [stateEqsStr, '; '];
                end
                stateEqsStr = [stateEqsStr, char(app.NonlinearStateEqs{i})];
            end
            app.NonlinearStateEqInput.Value = stateEqsStr;
            
            % Convert symbolic output equations back to string
            outputEqsStr = '';
            for i = 1:length(app.NonlinearOutputEqs)
                if i > 1
                    outputEqsStr = [outputEqsStr, '; '];
                end
                outputEqsStr = [outputEqsStr, char(app.NonlinearOutputEqs{i})];
            end
            app.NonlinearOutputEqInput.Value = outputEqsStr;
            
            % Convert linearization point to string
            linearizationPointStr = '';
            keys = app.LinearizationPoint.keys;
            for i = 1:length(keys)
                if i > 1
                    linearizationPointStr = [linearizationPointStr, ', '];
                end
                key = keys{i};
                value = app.LinearizationPoint(key);
                linearizationPointStr = [linearizationPointStr, key, '=', num2str(value)];
            end
            app.LinearizationPointInput.Value = linearizationPointStr;
            
            % Set the Nonlinear Model tab as active
            app.StateSpaceTypeTabGroup.SelectedTab = app.NonlinearModelTab;
            
        else
            % Linear model
            app.IsNonlinearModel = false;
            
            % Restore matrices to inputs
            app.LinearAInput.Value = matrix2str(loadedData.A);
            app.LinearBInput.Value = matrix2str(loadedData.B);
            app.LinearCInput.Value = matrix2str(loadedData.C);
            app.LinearDInput.Value = matrix2str(loadedData.D);
            
            % Set the Linear Model tab as active
            app.StateSpaceTypeTabGroup.SelectedTab = app.LinearModelTab;
        end
        
        % Restore linearized model
        if isfield(loadedData, 'LinearizedA')
            app.LinearizedModel = ss(loadedData.LinearizedA, loadedData.LinearizedB, ...
                                     loadedData.LinearizedC, loadedData.LinearizedD, ...
                                     loadedData.LinearizedTs);
        elseif ~app.IsNonlinearModel
            % For linear models without saved linearized model
            app.LinearizedModel = ss(loadedData.A, loadedData.B, loadedData.C, loadedData.D, loadedData.Ts);
        end
        
        % Update system information display
        updateStateSpaceInfo(app, app.LinearizedModel, app.IsNonlinearModel);
        
        % Show success message
        successMsg = ['<html><div style="color: green; text-align: center; margin-top: 10px; font-weight: bold;">', ...
                     'State-space model successfully loaded from:<br>', ...
                     fullPath, ...
                     '</div></html>'];
        app.StateSpaceInfoHTML.HTMLSource = successMsg;
        
    catch ME
        % Handle errors
        errorMsg = ['<html><div style="color: red; text-align: center; margin-top: 10px; font-weight: bold;">', ...
                   'Error loading state-space model:<br>', ...
                   ME.message, ...
                   '</div></html>'];
        app.StateSpaceInfoHTML.HTMLSource = errorMsg;
    end
        end

        % Button pushed function: ClearStateSpaceButton
        function ClearStateSpaceButtonPushed(app, event)
             % Callback function for the Clear Button
    try
        % Clear stored models
        if isfield(app, 'StateSpaceModel')
            app.StateSpaceModel = [];
        end
        
        if isfield(app, 'LinearizedModel')
            app.LinearizedModel = [];
        end
        
        app.IsNonlinearModel = false;
        
        % Clear nonlinear model fields
        if isfield(app, 'NonlinearStateVars')
            app.NonlinearStateVars = [];
        end
        
        if isfield(app, 'NonlinearInputVars')
            app.NonlinearInputVars = [];
        end
        
        if isfield(app, 'NonlinearStateEqs')
            app.NonlinearStateEqs = [];
        end
        
        if isfield(app, 'NonlinearOutputEqs')
            app.NonlinearOutputEqs = [];
        end
        
        if isfield(app, 'LinearizationPoint')
            app.LinearizationPoint = [];
        end
        
        % Reset UI elements
        app.StateSpaceInfoHTML.HTMLSource = '<html><body><p style="font-family: Arial; text-align: center; margin-top: 50px; color: #666;">System information will be displayed here.</p></body></html>';
        
        % Clear axes
        cla(app.StateSpaceAxes);
        title(app.StateSpaceAxes, 'System Visualization');
        xlabel(app.StateSpaceAxes, 'X-Axis');
        ylabel(app.StateSpaceAxes, 'Y-Axis');
        
        % Show success message
        successMsg = ['<html><div style="color: blue; text-align: center; margin-top: 10px; font-weight: bold;">', ...
                     'State-space model and visualization cleared.', ...
                     '</div></html>'];
        app.StateSpaceInfoHTML.HTMLSource = successMsg;
        
    catch ME
        % Handle errors
        errorMsg = ['<html><div style="color: red; text-align: center; margin-top: 10px; font-weight: bold;">', ...
                   'Error clearing state-space model:<br>', ...
                   ME.message, ...
                   '</div></html>'];
        app.StateSpaceInfoHTML.HTMLSource = errorMsg;
    end
        end

        % Value changed function: AnalysisTypeDropdown
        function AnalysisTypeDropdownValueChanged(app, event)
               % Update UI based on selected analysis type
               updateAnalysisDisplay(app, app.AnalysisTypeDropdown.Value);
            
        end

        % Button down function: StateSpaceTab
        function StateSpaceTabSelected(app, event)
             % Called when State Space tab is selected
            if isfield(app, 'LinearizedModel') && ~isempty(app.LinearizedModel)
                updateStateSpaceInfo(app, app.LinearizedModel, app.IsNonlinearModel);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.Panel = uipanel(app.UIFigure);
            app.d_1_UIAxes = uiaxes(app.Panel);
            app.d_1_UIAxes.XTick = [];
            app.d_1_UIAxes.YTick = [];
            app.d_1_UIAxes.ZTick = [];
            app.d_1_UIAxes.Visible = 'off';
            app.d_2_UIAxes = uiaxes(app.Panel);
            app.d_2_UIAxes.XTick = [];
            app.d_2_UIAxes.YTick = [];
            app.d_2_UIAxes.ZTick = [];
            app.d_2_UIAxes.Visible = 'off';
            app.G_UIAxes = uiaxes(app.Panel);
            app.G_UIAxes.XTick = [];
            app.G_UIAxes.YTick = [];
            app.G_UIAxes.ZTick = [];
            app.G_UIAxes.Visible = 'off';
            app.K_UIAxes = uiaxes(app.Panel);
            app.K_UIAxes.XTick = [];
            app.K_UIAxes.YTick = [];
            app.K_UIAxes.ZTick = [];
            app.K_UIAxes.Visible = 'off';
            app.r_UIAxes = uiaxes(app.Panel);
            app.r_UIAxes.XTick = [];
            app.r_UIAxes.YTick = [];
            app.r_UIAxes.ZTick = [];
            app.r_UIAxes.Visible = 'off';
            app.Image = uiimage(app.Panel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'App Bilder', 'Standard Regelkreis.png');
            app.UpdateGButton = uibutton(app.Panel, 'push');
            app.UpdateGButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateGButtonPushed, true);
            app.UpdateGButton.Text = 'Update G';
            app.UpdateKButton = uibutton(app.Panel, 'push');
            app.UpdateKButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateKButtonPushed, true);
            app.UpdateKButton.Text = 'Update K';
            app.UpdaterButton = uibutton(app.Panel, 'push');
            app.UpdaterButton.ButtonPushedFcn = createCallbackFcn(app, @UpdaterButtonPushed, true);
            app.UpdaterButton.Text = 'Update r';
            app.Updated1Button = uibutton(app.Panel, 'push');
            app.Updated1Button.ButtonPushedFcn = createCallbackFcn(app, @Updated1ButtonPushed, true);
            app.Updated1Button.Text = 'Update d1';
            app.Updated2Button = uibutton(app.Panel, 'push');
            app.Updated2Button.ButtonPushedFcn = createCallbackFcn(app, @Updated2ButtonPushed, true);
            app.Updated2Button.Text = 'Update d2';
            app.AnalyzeButton = uibutton(app.Panel, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.controlloopstabilityLabel = uilabel(app.Panel);
            app.controlloopstabilityLabel.Text = '';
            app.SaveButton = uibutton(app.Panel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Visible = 'off';
            app.SaveButton.Text = 'Save';
            app.LoadButton = uibutton(app.Panel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.HtmlTable = uihtml(app.Panel);
            app.HtmlTable.Visible = 'off';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ResponseTab = uitab(app.TabGroup);
            app.ResponseTab.Title = 'Response';
            app.Response_UIAxes = uiaxes(app.ResponseTab);
            title(app.Response_UIAxes, 'Title')
            xlabel(app.Response_UIAxes, 'X')
            ylabel(app.Response_UIAxes, 'Y')
            zlabel(app.Response_UIAxes, 'Z')
            app.Response_UIAxes.Visible = 'off';
            app.ButtonGroup = uibuttongroup(app.ResponseTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Visible = 'off';
            app.StepButton = uiradiobutton(app.ButtonGroup);
            app.StepButton.Text = 'Step';
            app.StepButton.Value = true;
            app.SetpointButton = uiradiobutton(app.ButtonGroup);
            app.SetpointButton.Text = 'Setpoint';
            app.r_d12Button = uiradiobutton(app.ButtonGroup);
            app.r_d12Button.Text = 'Setpoint and Disturbances';
            app.HTMLkeyparamTable = uihtml(app.ResponseTab);
            app.HTMLkeyparamTable.Visible = 'off';
            app.StationaryValuesTab = uitab(app.TabGroup);
            app.StationaryValuesTab.Title = 'Stationary Values';
            app.Controllanderrorplo = uiaxes(app.StationaryValuesTab);
            title(app.Controllanderrorplo, 'Title')
            xlabel(app.Controllanderrorplo, 'X')
            ylabel(app.Controllanderrorplo, 'Y')
            zlabel(app.Controllanderrorplo, 'Z')
            app.Controllanderrorplo.Visible = 'off';
            app.ButtonGroup_2 = uibuttongroup(app.StationaryValuesTab);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.ButtonGroup_2.Visible = 'off';
            app.SetButton = uiradiobutton(app.ButtonGroup_2);
            app.SetButton.Text = 'Setpoint';
            app.SetButton.Value = true;
            app.OutputdisturbanceButton = uiradiobutton(app.ButtonGroup_2);
            app.OutputdisturbanceButton.Text = 'Outputdisturbance';
            app.SetpointandDisturbanceButton = uiradiobutton(app.ButtonGroup_2);
            app.SetpointandDisturbanceButton.Text = 'Setpoint and Outputdisturbance';
            app.JumpLabel = uilabel(app.StationaryValuesTab);
            app.JumpLabel.Visible = 'off';
            app.JumpLabel.Text = '';
            app.HTMLkeyparamTable_2 = uihtml(app.StationaryValuesTab);
            app.HTMLkeyparamTable_2.Visible = 'off';
            app.StateSpaceTab = uitab(app.TabGroup);
            app.StateSpaceTab.Title = 'State-Space ';
            app.StateSpaceTab.ButtonDownFcn = createCallbackFcn(app, @StateSpaceTabSelected, true);
            app.StateSpaceTypeTabGroup = uitabgroup(app.StateSpaceTab);
            app.LinearModelTab = uitab(app.StateSpaceTypeTabGroup);
            app.LinearModelTab.Title = 'Linear Model';
            app.LinearALabel = uilabel(app.LinearModelTab);
            app.LinearALabel.Text = 'A (State Matrix):';
            app.LinearAInput = uitextarea(app.LinearModelTab);
            app.LinearAInput.Value = {'-1 0;0 -2'};
            app.LinearBLabel = uilabel(app.LinearModelTab);
            app.LinearBLabel.Text = 'B (Input Matrix):';
            app.LinearBInput = uitextarea(app.LinearModelTab);
            app.LinearBInput.Value = {'1; 0'};
            app.LinearCLabel = uilabel(app.LinearModelTab);
            app.LinearCLabel.Text = 'C (Output Matrix):';
            app.LinearCInput = uitextarea(app.LinearModelTab);
            app.LinearCInput.Value = {'1 1'};
            app.LinearDLabel = uilabel(app.LinearModelTab);
            app.LinearDLabel.Text = 'D (Feedthrough Matrix):';
            app.LinearDInput = uitextarea(app.LinearModelTab);
            app.LinearDInput.Value = {'0'};
            app.CreateLinearStateSpaceButton = uibutton(app.LinearModelTab, 'push');
            app.CreateLinearStateSpaceButton.ButtonPushedFcn = createCallbackFcn(app, @CreateLinearStateSpaceButtonPushed, true);
            app.CreateLinearStateSpaceButton.Text = 'Create State Space Model';
            app.LinearHelpButton = uibutton(app.LinearModelTab, 'push');
            app.LinearHelpButton.ButtonPushedFcn = createCallbackFcn(app, @LinearHelpButtonPushed, true);
            app.LinearHelpButton.Text = 'Format Help';
            app.NonlinearModelTab = uitab(app.StateSpaceTypeTabGroup);
            app.NonlinearModelTab.Title = 'Nonlinear Model';
            app.NonlinearStateVarsLabel = uilabel(app.NonlinearModelTab);
            app.NonlinearStateVarsLabel.Text = 'State Variables (comma separated):';
            app.NonlinearStateVarsInput = uieditfield(app.NonlinearModelTab, 'text');
            app.NonlinearStateVarsInput.Value = 'x1, x2';
            app.NonlinearInputVarsLabel = uilabel(app.NonlinearModelTab);
            app.NonlinearInputVarsLabel.Text = 'Input Variables (comma separated):';
            app.NonlinearInputVarsInput = uieditfield(app.NonlinearModelTab, 'text');
            app.NonlinearInputVarsInput.Value = 'u';
            app.NonlinearOutputEqLabel = uilabel(app.NonlinearModelTab);
            app.NonlinearOutputEqLabel.Text = 'Output Equations:';
            app.NonlinearOutputEqInput = uieditfield(app.NonlinearModelTab, 'text');
            app.NonlinearOutputEqInput.Value = 'x1 + x2';
            app.NonlinearOutputEqLabel_2 = uilabel(app.NonlinearModelTab);
            app.NonlinearOutputEqLabel_2.Text = 'State Equations (dx/dt):';
            app.NonlinearStateEqInput = uieditfield(app.NonlinearModelTab, 'text');
            app.NonlinearStateEqInput.Value = '-x1 + x2^2; -x2 - sin(x1) + u';
            app.LinearizationPointLabel = uilabel(app.NonlinearModelTab);
            app.LinearizationPointLabel.Text = 'Linearization Point (optional):';
            app.LinearizationPointInput = uieditfield(app.NonlinearModelTab, 'text');
            app.LinearizationPointInput.Value = 'x1=0, x2=0, u=0';
            app.CreateNonlinearStateSpaceButton = uibutton(app.NonlinearModelTab, 'push');
            app.CreateNonlinearStateSpaceButton.ButtonPushedFcn = createCallbackFcn(app, @CreateNonlinearStateSpaceButtonPushed, true);
            app.CreateNonlinearStateSpaceButton.Text = 'Create & Linearize';
            app.NonlinearHelpButton = uibutton(app.NonlinearModelTab, 'push');
            app.NonlinearHelpButton.ButtonPushedFcn = createCallbackFcn(app, @NonlinearHelpButtonPushed, true);
            app.NonlinearHelpButton.Text = 'Format Help';
            app.StateSpaceInfoPanel = uipanel(app.StateSpaceTab);
            app.StateSpaceInfoPanel.Title = 'System Information';
            app.StateSpaceInfoHTML = uihtml(app.StateSpaceInfoPanel);
            app.StateSpaceInfoHTML.HTMLSource = '<html><body><p style="font-family: Arial; text-align: center; margin-top: 50px; color: #666;">System information will be displayed here.</p></body></html>';
            app.StateSpaceControlPanel = uipanel(app.StateSpaceTab);
            app.StateSpaceControlPanel.Title = 'Analysis & Conversion';
            app.AnalysisTypeLabel = uilabel(app.StateSpaceControlPanel);
            app.AnalysisTypeLabel.Text = 'Analysis Type:';
            app.AnalysisTypeDropdown = uidropdown(app.StateSpaceControlPanel);
            app.AnalysisTypeDropdown.Items = {'Eigenvalues', 'Step Response', 'Impulse Response', 'Phase Portrait', 'Controllability', 'Observability'};
            app.AnalysisTypeDropdown.ValueChangedFcn = createCallbackFcn(app, @AnalysisTypeDropdownValueChanged, true);
            app.AnalysisTypeDropdown.Value = 'Eigenvalues';
            app.AnalysisParamLabel = uilabel(app.StateSpaceControlPanel);
            app.AnalysisParamLabel.Text = 'Parameters:';
            app.AnalysisParamInput = uieditfield(app.StateSpaceControlPanel, 'text');
            app.AnalysisParamInput.Value = 't=0:0.1:10';
            app.RunAnalysisButton = uibutton(app.StateSpaceControlPanel, 'push');
            app.RunAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysisButtonPushed, true);
            app.RunAnalysisButton.Text = 'Run Analysis';
            app.ConversionHeaderLabel = uilabel(app.StateSpaceControlPanel);
            app.ConversionHeaderLabel.Text = 'Conversion to Transfer Function';
            app.InputOutputLabel = uilabel(app.StateSpaceControlPanel);
            app.InputOutputLabel.Text = 'For MIMO Systems:';
            app.InputIndexInput = uieditfield(app.StateSpaceControlPanel, 'numeric');
            app.InputIndexInput.Value = 1;
            app.InputIndexLabel = uilabel(app.StateSpaceControlPanel);
            app.InputIndexLabel.Text = 'Input Index:';
            app.OutputIndexLabel = uilabel(app.StateSpaceControlPanel);
            app.OutputIndexLabel.Text = 'Output Index:';
            app.OutputIndexInput = uieditfield(app.StateSpaceControlPanel, 'numeric');
            app.OutputIndexInput.Value = 1;
            app.ConvertToTransferFunctionButton = uibutton(app.StateSpaceControlPanel, 'push');
            app.ConvertToTransferFunctionButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertToTransferFunctionButtonPushed, true);
            app.ConvertToTransferFunctionButton.Text = 'Convert to G(s)';
            app.SaveStateSpaceButton = uibutton(app.StateSpaceControlPanel, 'push');
            app.SaveStateSpaceButton.ButtonPushedFcn = createCallbackFcn(app, @SaveStateSpaceButtonPushed, true);
            app.SaveStateSpaceButton.Text = 'Save Model';
            app.LoadStateSpaceButton = uibutton(app.StateSpaceControlPanel, 'push');
            app.LoadStateSpaceButton.ButtonPushedFcn = createCallbackFcn(app, @LoadStateSpaceButtonPushed, true);
            app.LoadStateSpaceButton.Text = 'Load Model';
            app.ClearStateSpaceButton = uibutton(app.StateSpaceControlPanel, 'push');
            app.ClearStateSpaceButton.ButtonPushedFcn = createCallbackFcn(app, @ClearStateSpaceButtonPushed, true);
            app.ClearStateSpaceButton.Text = 'Clear';
            app.StateSpaceVisualizationPanel = uipanel(app.StateSpaceTab);
            app.StateSpaceVisualizationPanel.Title = 'Visualization';
            app.StateSpaceAxes = uiaxes(app.StateSpaceVisualizationPanel);
            title(app.StateSpaceAxes, 'System Visualization')
            xlabel(app.StateSpaceAxes, 'X-Axis')
            ylabel(app.StateSpaceAxes, 'Y-Axis')
            zlabel(app.StateSpaceAxes, 'Z')
            app.TabGroup_2 = uitabgroup(app.UIFigure);
            app.ZeroPoleTab = uitab(app.TabGroup_2);
            app.ZeroPoleTab.Title = 'Zero/Pole';
            app.pzplotUIAxes = uiaxes(app.ZeroPoleTab);
            title(app.pzplotUIAxes, 'Title')
            xlabel(app.pzplotUIAxes, 'X')
            ylabel(app.pzplotUIAxes, 'Y')
            zlabel(app.pzplotUIAxes, 'Z')
            app.pzplotUIAxes.Visible = 'off';
            app.PZButton = uibuttongroup(app.ZeroPoleTab);
            app.PZButton.SelectionChangedFcn = createCallbackFcn(app, @PZButtonSelectionChanged, true);
            app.PZButton.Visible = 'off';
            app.GsButton = uiradiobutton(app.PZButton);
            app.GsButton.Text = 'G(s)';
            app.GsButton.Value = true;
            app.KsButton = uiradiobutton(app.PZButton);
            app.KsButton.Text = 'K(s)';
            app.LsButton = uiradiobutton(app.PZButton);
            app.LsButton.Text = 'L(s)';
            app.GSsButton = uiradiobutton(app.PZButton);
            app.GSsButton.Text = 'GS(s)';
            app.KSsButton = uiradiobutton(app.PZButton);
            app.KSsButton.Text = 'KS(s)';
            app.TsButton = uiradiobutton(app.PZButton);
            app.TsButton.Text = 'T(s)';
            app.SsButton = uiradiobutton(app.PZButton);
            app.SsButton.Text = 'S(s)';
            app.HTMLPZ = uihtml(app.ZeroPoleTab);
            app.HTMLPZ.Visible = 'off';
            app.NyquistTab = uitab(app.TabGroup_2);
            app.NyquistTab.Title = 'Nyquist';
            app.nyquist_UIAxes2 = uiaxes(app.NyquistTab);
            title(app.nyquist_UIAxes2, 'Nyquist Diagram for \omega=0...+\infty, o=L(j0), x=L(j\infty), +=critical point')
            xlabel(app.nyquist_UIAxes2, 'Real Axis')
            ylabel(app.nyquist_UIAxes2, 'Imaginary Axis')
            zlabel(app.nyquist_UIAxes2, 'Z')
            app.nyquist_UIAxes2.Visible = 'off';
            app.HTMLNyquistInfo = uihtml(app.NyquistTab);
            app.HTMLNyquistInfo.Visible = 'off';
            app.MoveslidertovisualizeNyquistdiagramatdifferentfrequenciesLabel = uilabel(app.NyquistTab);
            app.MoveslidertovisualizeNyquistdiagramatdifferentfrequenciesLabel.Text = 'Move slider to visualize Nyquist diagram at different frequencies';
            app.BodeTab = uitab(app.TabGroup_2);
            app.BodeTab.Title = 'Bode';
            app.Bode_UIAxes = uiaxes(app.BodeTab);
            title(app.Bode_UIAxes, 'Title')
            xlabel(app.Bode_UIAxes, 'X')
            ylabel(app.Bode_UIAxes, 'Y')
            zlabel(app.Bode_UIAxes, 'Z')
            app.Bode_UIAxes.Visible = 'off';
            app.BandwidthTab = uitab(app.TabGroup_2);
            app.BandwidthTab.Title = 'Bandwidth';
            app.Bandwidth_UIAxes = uiaxes(app.BandwidthTab);
            title(app.Bandwidth_UIAxes, 'Magnitude Response with Bandwidth')
            xlabel(app.Bandwidth_UIAxes, 'Frequency (rad/s)')
            ylabel(app.Bandwidth_UIAxes, 'Magnitude (dB)')
            zlabel(app.Bandwidth_UIAxes, 'Z')
            app.Bandwidth_UIAxes.Visible = 'off';
            app.RobustnessTab = uitab(app.TabGroup_2);
            app.RobustnessTab.Title = 'Robustness Analysis';
            app.RobustnessTabGroup = uitabgroup(app.RobustnessTab);
            app.SensitivityTab = uitab(app.RobustnessTabGroup);
            app.SensitivityTab.Title = 'Sensitivity Analysis';
            app.SensitivityUIAxes = uiaxes(app.SensitivityTab);
            title(app.SensitivityUIAxes, 'Parameter Sensitivity Analysis')
            xlabel(app.SensitivityUIAxes, 'Parameter Variation (%)')
            ylabel(app.SensitivityUIAxes, 'Performance Metric')
            zlabel(app.SensitivityUIAxes, 'Z')
            app.SelectParameterLabel = uilabel(app.SensitivityTab);
            app.SelectParameterLabel.Text = 'Select Parameter:';
            app.SensitivityParamDropdown = uidropdown(app.SensitivityTab);
            app.SensitivityParamDropdown.Items = {'Kp (Controller Gain)', 'Ki (Integral Gain)', 'Kd (Derivative Gain)', 'Plant Parameter'};
            app.SensitivityParamDropdown.Value = 'Kp (Controller Gain)';
            app.VariationRangeLabel = uilabel(app.SensitivityTab);
            app.VariationRangeLabel.Text = 'Variation Range (%)';
            app.SensitivityRangeEdit = uieditfield(app.SensitivityTab, 'numeric');
            app.SensitivityRangeEdit.Value = 20;
            app.PerformanceMetricLabel = uilabel(app.SensitivityTab);
            app.PerformanceMetricLabel.Text = 'Performance Metric:';
            app.SensitivityMetricDropdown = uidropdown(app.SensitivityTab);
            app.SensitivityMetricDropdown.Items = {'Settling Time', 'Overshoot', 'Rise Time', 'Bandwidth', 'Phase Margin', 'Gain Margin'};
            app.SensitivityMetricDropdown.Value = 'Settling Time';
            app.RunSensitivityButton = uibutton(app.SensitivityTab, 'push');
            app.RunSensitivityButton.ButtonPushedFcn = createCallbackFcn(app, @RunSensitivityButtonPushed, true);
            app.RunSensitivityButton.Text = 'Run Sensitivity Analysis';
            app.SensitivityHTML = uihtml(app.SensitivityTab);
            app.SensitivityHTML.HTMLSource = '<html><body><div style="font-family: Arial; font-size: 12px; padding: 10px;">Sensitivity analysis results will appear here.</div></body></html>';
            app.NumberofPointsLabel = uilabel(app.SensitivityTab);
            app.NumberofPointsLabel.Text = 'Number of Points:';
            app.SensitivityPointsEdit = uieditfield(app.SensitivityTab, 'numeric');
            app.SensitivityPointsEdit.Value = 10;
            app.MonteCarloTab = uitab(app.RobustnessTabGroup);
            app.MonteCarloTab.Title = 'Monte Carlo Simulation';
            app.MonteCarloHistUIAxes = uiaxes(app.MonteCarloTab);
            title(app.MonteCarloHistUIAxes, 'Performance Metric Histogram')
            xlabel(app.MonteCarloHistUIAxes, 'Value')
            ylabel(app.MonteCarloHistUIAxes, 'Frequency')
            zlabel(app.MonteCarloHistUIAxes, 'Z')
            app.MonteCarloResponseUIAxes = uiaxes(app.MonteCarloTab);
            title(app.MonteCarloResponseUIAxes, 'System Responses')
            xlabel(app.MonteCarloResponseUIAxes, 'Time (s)')
            ylabel(app.MonteCarloResponseUIAxes, 'Output')
            zlabel(app.MonteCarloResponseUIAxes, 'Z')
            app.ControllerGainKpLabel = uilabel(app.MonteCarloTab);
            app.ControllerGainKpLabel.Text = 'Controller Gain (Kp):';
            app.DistributionLabel = uilabel(app.MonteCarloTab);
            app.DistributionLabel.Text = 'Distribution:';
            app.KpDistDropdown = uidropdown(app.MonteCarloTab);
            app.KpDistDropdown.Items = {'Normal', 'Uniform', 'Fixed'};
            app.KpDistDropdown.ValueChangedFcn = createCallbackFcn(app, @KpDistDropdownValueChanged, true);
            app.KpDistDropdown.Value = 'Normal';
            app.MeanMinLabel = uilabel(app.MonteCarloTab);
            app.MeanMinLabel.Text = 'Mean/Min:';
            app.KpParam1Edit = uieditfield(app.MonteCarloTab, 'numeric');
            app.KpParam1Edit.Value = 1;
            app.StdMaxLabel = uilabel(app.MonteCarloTab);
            app.StdMaxLabel.Text = 'Std/Max:';
            app.KpParam2Edit = uieditfield(app.MonteCarloTab, 'numeric');
            app.KpParam2Edit.Value = 0.1;
            app.IntegralGainKiLabel = uilabel(app.MonteCarloTab);
            app.IntegralGainKiLabel.Text = 'Integral Gain (Ki):';
            app.DistributionLabel_2 = uilabel(app.MonteCarloTab);
            app.DistributionLabel_2.Text = 'Distribution:';
            app.MeanMinLabel_2 = uilabel(app.MonteCarloTab);
            app.MeanMinLabel_2.Text = 'Mean/Min:';
            app.KiDistDropdown = uidropdown(app.MonteCarloTab);
            app.KiDistDropdown.Items = {'Normal', 'Uniform', 'Fixed'};
            app.KiDistDropdown.ValueChangedFcn = createCallbackFcn(app, @KiDistDropdownValueChanged, true);
            app.KiDistDropdown.Value = 'Normal';
            app.KiParam1Edit = uieditfield(app.MonteCarloTab, 'numeric');
            app.KiParam1Edit.Value = 0.5;
            app.StdMaxLabel_2 = uilabel(app.MonteCarloTab);
            app.StdMaxLabel_2.Text = 'Std/Max:';
            app.KiParam2Edit = uieditfield(app.MonteCarloTab, 'numeric');
            app.KiParam2Edit.Value = 0.05;
            app.DerivativeGainKdLabel = uilabel(app.MonteCarloTab);
            app.DerivativeGainKdLabel.Text = 'Derivative Gain (Kd):';
            app.DistributionLabel_3 = uilabel(app.MonteCarloTab);
            app.DistributionLabel_3.Text = 'Distribution:';
            app.MeanMinLabel_3 = uilabel(app.MonteCarloTab);
            app.MeanMinLabel_3.Text = 'Mean/Min:';
            app.KdDistDropdown = uidropdown(app.MonteCarloTab);
            app.KdDistDropdown.Items = {'Normal', 'Uniform', 'Fixed'};
            app.KdDistDropdown.ValueChangedFcn = createCallbackFcn(app, @KdDistDropdownValueChanged, true);
            app.KdDistDropdown.Value = 'Normal';
            app.KdParam1Edit = uieditfield(app.MonteCarloTab, 'numeric');
            app.KdParam1Edit.Value = 0.1;
            app.StdMaxLabel_3 = uilabel(app.MonteCarloTab);
            app.StdMaxLabel_3.Text = 'Std/Max:';
            app.KdParam2Edit = uieditfield(app.MonteCarloTab, 'numeric');
            app.KdParam2Edit.Value = 0.01;
            app.NumberofSimulationsLabel = uilabel(app.MonteCarloTab);
            app.NumberofSimulationsLabel.Text = 'Number of Simulations:';
            app.NumSimulationsEdit = uieditfield(app.MonteCarloTab, 'numeric');
            app.NumSimulationsEdit.Value = 100;
            app.PerformanceMetricLabel_2 = uilabel(app.MonteCarloTab);
            app.PerformanceMetricLabel_2.Text = 'Performance Metric:';
            app.MonteCarloMetricDropdown = uidropdown(app.MonteCarloTab);
            app.MonteCarloMetricDropdown.Items = {'Settling Time', 'Overshoot', 'Rise Time', 'Correlation Matrix'};
            app.MonteCarloMetricDropdown.Value = 'Settling Time';
            app.RunMonteCarloButton = uibutton(app.MonteCarloTab, 'push');
            app.RunMonteCarloButton.ButtonPushedFcn = createCallbackFcn(app, @RunMonteCarloButtonPushed, true);
            app.RunMonteCarloButton.Text = 'Run Monte Carlo Simulation';
            app.MonteCarloHTML = uihtml(app.MonteCarloTab);
            app.MonteCarloHTML.HTMLSource = '<html><body><div style="font-family: Arial; font-size: 12px; padding: 10px;">Monte Carlo simulation results will appear here.</div></body></html>';
            app.MuAnalysisTab = uitab(app.RobustnessTabGroup);
            app.MuAnalysisTab.Title = 'μ-Analysis';
            app.MuAnalysisUIAxes = uiaxes(app.MuAnalysisTab);
            title(app.MuAnalysisUIAxes, 'μ-Analysis Results')
            xlabel(app.MuAnalysisUIAxes, 'Frequency (rad/s)')
            ylabel(app.MuAnalysisUIAxes, 'μ-Value')
            zlabel(app.MuAnalysisUIAxes, 'Z')
            app.GainUncertaintyLabel = uilabel(app.MuAnalysisTab);
            app.GainUncertaintyLabel.Text = 'Gain Uncertainty (%):';
            app.GainUncertaintyEdit = uieditfield(app.MuAnalysisTab, 'numeric');
            app.GainUncertaintyEdit.Value = 20;
            app.TimeConstantUncLabel = uilabel(app.MuAnalysisTab);
            app.TimeConstantUncLabel.Text = 'Time Constant Unc. (%):';
            app.TimeConstUncertaintyEdit = uieditfield(app.MuAnalysisTab, 'numeric');
            app.TimeConstUncertaintyEdit.Value = 30;
            app.DelayUncertaintyLabel = uilabel(app.MuAnalysisTab);
            app.DelayUncertaintyLabel.Text = 'Delay Uncertainty (%):';
            app.DelayUncertaintyEdit = uieditfield(app.MuAnalysisTab, 'numeric');
            app.DelayUncertaintyEdit.Value = 10;
            app.FrequencyRangeLabel = uilabel(app.MuAnalysisTab);
            app.FrequencyRangeLabel.Text = 'Frequency Range:';
            app.FreqMinEdit = uieditfield(app.MuAnalysisTab, 'numeric');
            app.FreqMinEdit.Value = 0.01;
            app.toLabel = uilabel(app.MuAnalysisTab);
            app.toLabel.Text = 'to';
            app.FreqMaxEdit = uieditfield(app.MuAnalysisTab, 'numeric');
            app.FreqMaxEdit.Value = 100;
            app.RunMuAnalysisButton = uibutton(app.MuAnalysisTab, 'push');
            app.RunMuAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunMuAnalysisButtonPushed, true);
            app.RunMuAnalysisButton.Text = 'Run μ-Analysis';
            app.MuAnalysisHTML = uihtml(app.MuAnalysisTab);
            app.MuAnalysisHTML.HTMLSource = '<html><body><div style="font-family: Arial; font-size: 12px; padding: 10px;">μ-analysis results will appear here.</div></body></html>';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_automatische_Analyse_eines_Regelkreises_Kopie

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end